classdef DataViewer_evntManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        TriggerdetectionparametersPanel  matlab.ui.container.Panel
        mininterstimsSpinnerLabel       matlab.ui.control.Label
        mininterstimsSpinner            matlab.ui.control.Spinner
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        TriggertypeDropDownLabel        matlab.ui.control.Label
        TriggertypeDropDown             matlab.ui.control.DropDown
        ConditionlistPanel              matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        FileDropDownLabel               matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        GridLayout3                     matlab.ui.container.GridLayout
        DetectButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SelectchannelsPanel             matlab.ui.container.Panel
        SelectcolumnsPanel              matlab.ui.container.Panel
        TriggerinfoPanel                matlab.ui.container.Panel
        RecordinglengthsEditFieldLabel  matlab.ui.control.Label
        RecordinglengthsEditField       matlab.ui.control.NumericEditField
        TriggersdetectedLabel           matlab.ui.control.Label
        TriggersdetectedEditField       matlab.ui.control.NumericEditField
        NumberofconditionsLabel         matlab.ui.control.Label
        NumberofconditionsEditField     matlab.ui.control.NumericEditField
        DetectionthresholdVEditFieldLabel  matlab.ui.control.Label
        DetectionthresholdVEditField    matlab.ui.control.NumericEditField
        AvgIntertriallengthsEditFieldLabel  matlab.ui.control.Label
        AvgIntertriallengthsEditField   matlab.ui.control.NumericEditField
        AvgtriallengthsEditFieldLabel   matlab.ui.control.Label
        AvgtriallengthsEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        saveFolder % Folder where to save the "events.mat" file
        evObj % EventsManager class instance.
        fH  % plot figure handle array.
    end
    
    methods (Access = private)
        
        function showStats(app)
            % Updates fields of "Trigger info" panel.
            app.RecordinglengthsEditField.Value = double(size(app.evObj.AnalogIN,1)/app.evObj.sr);
            app.DetectionthresholdVEditField.Value = double(app.evObj.trigThr);
            app.TriggersdetectedEditField.Value = double(sum(app.evObj.state));
            app.NumberofconditionsEditField.Value = length(app.evObj.eventNameList);
            
            deltaT = [diff(app.evObj.timestamps); nan];
            trialLen = mean(deltaT(app.evObj.state == 1), 'omitnan');
            interTrialLen = mean(deltaT(app.evObj.state == 0), 'omitnan');
            if isnan(interTrialLen)
                interTrialLen = 0;
            end
            app.AvgtriallengthsEditField.Value = double(trialLen);            
            app.AvgIntertriallengthsEditField.Value = double(interTrialLen);    
            % Re-enable elements:
            arrayfun(@(x) set(x, 'Enable','on'), app.TriggerinfoPanel.Children);            
        end
        
        function showPlots(app)
            % Displays the plots of the selected channels.
            
            % Delete existing plots
            if ~isempty(app.fH)
                close(app.fH);
            end
            % Update trigger Channels from the selected channels on the
            % list:
            
            %%%% TBD %%%%%
            
            % Create plots 
            app.fH = app.evObj.plotAnalogIN(app.evObj.trigChanName); % Note to self: replace trigChanName with selected trigger names in chanlist.
            % Put plot figures next to the main app.            
            cascadeArr = 10*0:length(app.fH)-1;
            for ii = 1:length(app.fH)
                app.fH(ii).Position(1) = sum([app.UIFigure.Position([1 3]),cascadeArr(ii),5]);
                app.fH(ii).Position(2) = app.UIFigure.OuterPosition(4) + app.UIFigure.OuterPosition(2) - app.fH(ii).Position(4)-50 - cascadeArr(ii);
            end
                       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rawFolder, saveFolder)
            % 
            app.UIFigure.Visible = 'off';
            % Hide the two columns in the middle:            
            app.GridLayout.ColumnWidth([2,3]) = {0,0};
            app.UIFigure.Position(3) = app.UIFigure.Position(3) - 320; %Resize figure;
            movegui(app.UIFigure,'northwest');
            app.UIFigure.Visible = 'on';
            app.saveFolder = saveFolder;
            % Instantiate EventsManager object:            
            app.evObj = EventsManager(rawFolder);
            % If there are no AnalogIN data in the raw folder, raise error
            % message:
            if isempty(app.evObj.AnalogIN)
                uialert(app.UIFigure, ['Failed to find analog signals in folder "' rawFolder '". This app will close.'], 'Error!',"CloseFcn",@(src,evnt) app.UIFigureCloseRequest)
            else
                % If internal stims were already detected, update channel list
                % and plot triggers.
                if ~isempty(app.evObj.eventID)
%                     app.showChanList;
                    app.showStats;                    
                    app.showPlots;                    
                end
            end            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.fH)
                close(app.fH);
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Events Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TriggerdetectionparametersPanel = uipanel(app.GridLayout2);
            app.TriggerdetectionparametersPanel.Tooltip = {''};
            app.TriggerdetectionparametersPanel.Title = 'Trigger detection parameters';
            app.mininterstimsSpinnerLabel = uilabel(app.TriggerdetectionparametersPanel);
            app.mininterstimsSpinnerLabel.Text = 'min. interstim (s)';
            app.mininterstimsSpinner = uispinner(app.TriggerdetectionparametersPanel);
            app.mininterstimsSpinner.Step = 0.2;
            app.mininterstimsSpinner.Limits = [0.5 Inf];
            app.mininterstimsSpinner.Tooltip = {'Minimal time between stimuli in seconds (used to identify burst stimuli).'};
            app.mininterstimsSpinner.Value = 2;
            app.ThresholdEditFieldLabel = uilabel(app.TriggerdetectionparametersPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.TriggerdetectionparametersPanel, 'text');
            app.ThresholdEditField.Tooltip = {'Trigger detection threshold ("auto" or a numeric value in Volts).'};
            app.ThresholdEditField.Value = 'auto';
            app.TriggertypeDropDownLabel = uilabel(app.TriggerdetectionparametersPanel);
            app.TriggertypeDropDownLabel.Enable = 'off';
            app.TriggertypeDropDownLabel.Text = 'Trigger type';
            app.TriggertypeDropDown = uidropdown(app.TriggerdetectionparametersPanel);
            app.TriggertypeDropDown.Items = {'EdgeSet', 'EdgeToggle'};
            app.TriggertypeDropDown.Enable = 'off';
            app.TriggertypeDropDown.Tooltip = {'Type of trigger. EdgeSet: rising and falling edges of a pulse mark the start and end of an event. '; 'EdgeToggle: two consecutive pulses mark the start and end of an event.'};
            app.TriggertypeDropDown.Value = 'EdgeSet';
            app.ConditionlistPanel = uipanel(app.GridLayout2);
            app.ConditionlistPanel.Title = 'Condition list';
            app.TypeDropDownLabel = uilabel(app.ConditionlistPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ConditionlistPanel);
            app.TypeDropDown.Items = {'none', 'CSV', 'Vpixx'};
            app.TypeDropDown.Value = 'none';
            app.FileDropDownLabel = uilabel(app.ConditionlistPanel);
            app.FileDropDownLabel.Enable = 'off';
            app.FileDropDownLabel.Text = 'File:';
            app.FileDropDown = uidropdown(app.ConditionlistPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.Enable = 'off';
            app.FileDropDown.Value = {};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.DetectButton = uibutton(app.GridLayout3, 'push');
            app.DetectButton.Tooltip = {'Detect and plot events.'};
            app.DetectButton.Text = 'Detect';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.Tooltip = {'Reset parameters to default'};
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Save info to "events.mat" file.'};
            app.SaveButton.Text = 'Save';
            app.SelectchannelsPanel = uipanel(app.GridLayout);
            app.SelectchannelsPanel.Tooltip = {'Select one or more channels containing the triggers. If two or more channels are chosen'; ' each channel will be considered as a condition'; ' unless a condition file is set.'};
            app.SelectchannelsPanel.Title = 'Select channel(s) ';
            app.SelectcolumnsPanel = uipanel(app.GridLayout);
            app.SelectcolumnsPanel.Tooltip = {'Select one or more columns from the .CSV file. For multiple selection'; ' each row will be concatenated to a single condition.'};
            app.SelectcolumnsPanel.Title = 'Select column(s)';
            app.TriggerinfoPanel = uipanel(app.GridLayout);
            app.TriggerinfoPanel.Title = 'Trigger info';
            app.RecordinglengthsEditFieldLabel = uilabel(app.TriggerinfoPanel);
            app.RecordinglengthsEditFieldLabel.Enable = 'off';
            app.RecordinglengthsEditFieldLabel.Text = 'Recording length (s):';
            app.RecordinglengthsEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.RecordinglengthsEditField.Limits = [0 Inf];
            app.RecordinglengthsEditField.ValueDisplayFormat = '%0.2f';
            app.RecordinglengthsEditField.Editable = 'off';
            app.RecordinglengthsEditField.Enable = 'off';
            app.TriggersdetectedLabel = uilabel(app.TriggerinfoPanel);
            app.TriggersdetectedLabel.Enable = 'off';
            app.TriggersdetectedLabel.Text = 'Triggers detected:';
            app.TriggersdetectedEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.TriggersdetectedEditField.Limits = [0 Inf];
            app.TriggersdetectedEditField.Editable = 'off';
            app.TriggersdetectedEditField.Enable = 'off';
            app.NumberofconditionsLabel = uilabel(app.TriggerinfoPanel);
            app.NumberofconditionsLabel.Enable = 'off';
            app.NumberofconditionsLabel.Text = 'Number of conditions:';
            app.NumberofconditionsEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.NumberofconditionsEditField.Limits = [0 Inf];
            app.NumberofconditionsEditField.Editable = 'off';
            app.NumberofconditionsEditField.Enable = 'off';
            app.DetectionthresholdVEditFieldLabel = uilabel(app.TriggerinfoPanel);
            app.DetectionthresholdVEditFieldLabel.Enable = 'off';
            app.DetectionthresholdVEditFieldLabel.Text = 'Detection threshold (V):';
            app.DetectionthresholdVEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.DetectionthresholdVEditField.Limits = [0 Inf];
            app.DetectionthresholdVEditField.ValueDisplayFormat = '%0.2f';
            app.DetectionthresholdVEditField.Editable = 'off';
            app.DetectionthresholdVEditField.Enable = 'off';
            app.AvgIntertriallengthsEditFieldLabel = uilabel(app.TriggerinfoPanel);
            app.AvgIntertriallengthsEditFieldLabel.Enable = 'off';
            app.AvgIntertriallengthsEditFieldLabel.Text = 'Avg. Inter-trial length (s):';
            app.AvgIntertriallengthsEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.AvgIntertriallengthsEditField.Limits = [0 Inf];
            app.AvgIntertriallengthsEditField.ValueDisplayFormat = '%0.2f';
            app.AvgIntertriallengthsEditField.Editable = 'off';
            app.AvgIntertriallengthsEditField.Enable = 'off';
            app.AvgtriallengthsEditFieldLabel = uilabel(app.TriggerinfoPanel);
            app.AvgtriallengthsEditFieldLabel.Enable = 'off';
            app.AvgtriallengthsEditFieldLabel.Text = 'Avg. trial length (s):';
            app.AvgtriallengthsEditField = uieditfield(app.TriggerinfoPanel, 'numeric');
            app.AvgtriallengthsEditField.Limits = [0 Inf];
            app.AvgtriallengthsEditField.ValueDisplayFormat = '%0.2f';
            app.AvgtriallengthsEditField.Editable = 'off';
            app.AvgtriallengthsEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_evntManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end