classdef funcImag_viz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FunctionalImagingvisualizationLabel  matlab.ui.control.Label
        SelectdataDropDownLabel  matlab.ui.control.Label
        SelectdataDropDown       matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
        Slider                   matlab.ui.control.Slider
        ROIoptionsPanel          matlab.ui.container.Panel
        SaveplotsButton          matlab.ui.control.Button
        PlotoptionsPanel         matlab.ui.container.Panel
        colormapDropDownLabel    matlab.ui.control.Label
        colormapDropDown         matlab.ui.control.DropDown
        minEditFieldLabel        matlab.ui.control.Label
        minEditField             matlab.ui.control.NumericEditField
        minEditField_2Label      matlab.ui.control.Label
        minEditField_2           matlab.ui.control.NumericEditField
        showcolorbarCheckBox     matlab.ui.control.CheckBox
        ClippingvaluesLabel      matlab.ui.control.Label
        maxEditFieldLabel_2      matlab.ui.control.Label
        PromptLabel              matlab.ui.control.Label
        EventsoptionsPanel       matlab.ui.container.Panel
        Button                   matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        my_cmap % current colormap
        atlas % Allen Brain Atlas structure
        inputObj % input object
        inputFile % input File
        currFr % current Frame.
        expType % experiment type one of the following: {'SeedPixCorr', 'ImgTimeSeries', 'ImgTimeSeriesByEvent'}
        mDat % mmemmap file containing metaData from inputFile.
    end
    
    methods (Access = private)
        
        function b_isOK = checkInput(app)
            % CHECKINPUT verifies if inputObj and inputFile obey the
            % necessary conditions.
            % Output: b_isOK, boolean to indicate if inputs obey all
            % conditions.
            b_isOK = false;
            if numel(app.inputObj) ~=1
                uiwait(errordlg('Only one object is accepted!', 'Error!', 'modal'))
                return
            end
            if iscell(app.inputObj)
                app.inputObj = app.inputObj{:};
            end
            file = fullfile(app.inputObj.SaveFolder, app.inputFile);
            if ~isfile(file)
                uiwait(errordlg(['Input file ' app.inputFile ' not found in ' app.inputObj.SaveFolder], 'Error!', 'modal'))
                return
            end
            app.mDat = mapDatFile(file);
            fields = fieldnames(app.mDat.Data);
            
            % Determine what type of experiment is represented by the Data:


            if ~all(ismember(fields, {'CM', 'P'}))
                uiwait(errordlg('Wrong data File! Choose a Seed-Pixel Correlation .DAT file.', 'Error!', 'modal'))
                return
            end
            app.Label.Text = 'Opening file...'; drawnow
            app.Data = flipud(rot90(app.mDat.Data.CM));
            % Draw first Frame
            app.CurrentFrame = squeeze(app.Data(:,:,1));
            FrSz = size(app.CurrentFrame);
            app.UIAxes.XLim = [1 FrSz(1)]; app.UIAxes.YLim = [1 FrSz(2)];
            imagesc(app.UIAxes,app.CurrentFrame, app.clims); colormap(app.UIAxes, app.my_cmap); colorbar(app.UIAxes, 'westoutside')
            app.start = true;
            app.Label.Text = ['File : ' file]; drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputObj, inputFile)
            root = getenv('Umitoolbox');
            file = fullfile(root, 'GUI\DataViz\mouse_ctx_borders.mat');
            a = load(file);
            app.atlas = a.atlas;
            app.my_cmap = app.colormapDropDown.Value;
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.Title.String = app.MapButtonGroup.SelectedObject.Text;
            % Initialize parameters
            app.inputObj = inputObj;
            app.inputFile = inputFile;
            b_isOk = app.checkInput;
            if ~b_isOk
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FunctionalImagingvisualizationLabel = uilabel(app.UIFigure);
            app.FunctionalImagingvisualizationLabel.Text = 'Functional Imaging visualization';
            app.SelectdataDropDownLabel = uilabel(app.UIFigure);
            app.SelectdataDropDownLabel.Text = 'Select data';
            app.SelectdataDropDown = uidropdown(app.UIFigure);
            app.SelectdataDropDown.Items = {'data'};
            app.SelectdataDropDown.Value = 'data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Slider = uislider(app.UIFigure);
            app.ROIoptionsPanel = uipanel(app.UIFigure);
            app.ROIoptionsPanel.Title = 'ROI options';
            app.SaveplotsButton = uibutton(app.UIFigure, 'push');
            app.SaveplotsButton.Text = 'Save plots';
            app.PlotoptionsPanel = uipanel(app.UIFigure);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.colormapDropDownLabel = uilabel(app.PlotoptionsPanel);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.PlotoptionsPanel);
            app.colormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.colormapDropDown.Value = 'parula';
            app.minEditFieldLabel = uilabel(app.PlotoptionsPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.minEditField_2Label = uilabel(app.PlotoptionsPanel);
            app.minEditField_2Label.Text = 'min';
            app.minEditField_2 = uieditfield(app.PlotoptionsPanel, 'numeric');
            app.showcolorbarCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.showcolorbarCheckBox.Text = 'show colorbar';
            app.ClippingvaluesLabel = uilabel(app.PlotoptionsPanel);
            app.ClippingvaluesLabel.Text = 'Clipping values:';
            app.maxEditFieldLabel_2 = uilabel(app.PlotoptionsPanel);
            app.maxEditFieldLabel_2.Text = 'max';
            app.TextArea = uitextarea(app.UIFigure);
            app.PromptLabel = uilabel(app.UIFigure);
            app.PromptLabel.Text = 'Prompt:';
            app.EventsoptionsPanel = uipanel(app.UIFigure);
            app.EventsoptionsPanel.Title = 'Events options';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'icon_playButton.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = funcImag_viz(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end