classdef MergeRecs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MergeRecordingtoolUIFigure      matlab.ui.Figure
        Tree                            matlab.ui.container.Tree
        ChooseacquisitionsListBoxLabel  matlab.ui.control.Label
        ChooseacquisitionsListBox       matlab.ui.control.ListBox
        ChooseModalityListBoxLabel      matlab.ui.control.Label
        ChooseModalityListBox           matlab.ui.control.ListBox
        ChooseFilesListBoxLabel         matlab.ui.control.Label
        ChooseFilesListBox              matlab.ui.control.ListBox
        SelectButton                    matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        SetorderListBoxLabel            matlab.ui.control.Label
        SetorderListBox                 matlab.ui.control.ListBox
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        MergetypeButtonGroup            matlab.ui.container.ButtonGroup
        MovieButton                     matlab.ui.control.RadioButton
        TrialButton                     matlab.ui.control.RadioButton
        EventsPanel                     matlab.ui.container.Panel
        StimIDEditField                 matlab.ui.control.EditField
        SetstimIDButton                 matlab.ui.control.Button
        UsecustomstimIDCheckBox         matlab.ui.control.CheckBox
        NewAcquistionNameEditFieldLabel  matlab.ui.control.Label
        NewAcquistionNameEditField      matlab.ui.control.EditField
        MergeDataButton                 matlab.ui.control.Button
        MergedfilenameListBoxLabel      matlab.ui.control.Label
        MergedfilenameListBox           matlab.ui.control.ListBox
        ChangenameButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Handle to umIToolbox app
        nodeLevels % list of level indice of Nodes in TREE.
        nodeList % List of selected nodes
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.Panel.Visible = 'off';
            app.MergeRecordingtoolUIFigure.Visible = 'off';
            movegui(app.MergeRecordingtoolUIFigure, "center");
            app.MainApp = MainApp;
            % Copy UITree from main app:
            copyobj(app.MainApp.Tree.Children, app.Tree, 'legacy')
            expand(app.Tree);
            % Make figure visible:
            app.MergeRecordingtoolUIFigure.Visible = 'on';
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.Tree.Enable = 'off';drawnow
            if isempty(app.Tree.SelectedNodes)
                app.ChooseFilesListBox.Items = {};
                app.ChooseModalityListBox.Items ={};
                app.ChooseacquisitionsListBox.Items = {};
                app.Tree.Enable = 'on';
                return
            end
            Nodes = [];
            for i = 1:numel(app.Tree.SelectedNodes)
                if isa(app.Tree.SelectedNodes(i).Parent, 'matlab.ui.container.Tree')
                    Nodes = [Nodes;app.Tree.SelectedNodes(i).Children];
                else
                    Nodes = [Nodes;app.Tree.SelectedNodes(i)];
                end
            end
            app.nodeList = [];
            
            for i = 1:numel(Nodes)
                node = Nodes(i);
                try
                    app.nodeList = [app.nodeList;node;node.Children;[node.Children.Children]'];
                catch
                    try
                        app.nodeList = [app.nodeList;node;node.Children];
                    catch
                        app.nodeList = [app.nodeList;node];
                    end
                end
                
            end
            app.nodeList= unique(app.nodeList, 'stable');
            % Get level of each node:
            app.nodeLevels = ones(size(app.nodeList));
            for i= 1:length(app.nodeList)
                node = app.nodeList(i);
                idx = true;
                while idx
                    myParent = node.Parent;
                    if isa(myParent.Parent, 'matlab.ui.container.Tree')
                        idx = false;
                    else
                        app.nodeLevels(i) = app.nodeLevels(i) + 1;
                        node = node.Parent;
                    end
                end
            end
            % Select branches:
            app.Tree.SelectedNodes = unique(app.nodeList);
            % Erase modality and File List boxes:
            app.ChooseFilesListBox.Items = {};
            app.ChooseModalityListBox.Items ={};
            % Update acquisition list with all acquisition names that exist
            % in all selected subjects:
            indxM = find(app.nodeLevels == 3);
            myParents = [];
            for i = 1:length(indxM)
                myParents = [myParents; [string(app.nodeList(indxM(i)).Parent.Parent.Text) string(app.nodeList(indxM(i)).Parent.Text)]];
            end
            subjs = unique(myParents(:,1));
            acqs = unique(myParents(:,2));
            idxAll = false(size(subjs));
            for i = 1:numel(acqs)
                idxA = myParents(:,2) == acqs(i);
                if all(ismember(subjs, myParents(idxA,1)))
                    idxAll(i) = true;
                end
            end
            str = convertStringsToChars(acqs(idxAll));
            if ~iscell(str)
                str = {str};
            end
            app.ChooseacquisitionsListBox.Items = str;
            app.ChooseacquisitionsListBox.Value = {};
            % Expand nodes and re-enable UITREE:
            arrayfun(@expand, app.Tree.SelectedNodes)
            app.Tree.Enable = 'on';drawnow
        end

        % Value changed function: ChooseacquisitionsListBox
        function ChooseacquisitionsListBoxValueChanged(app, event)
            acqList = app.ChooseacquisitionsListBox.Value;            
            modStr = [];
            for i = 1:length(acqList)
                indxA = find(strcmp(acqList{i}, {app.nodeList.Text})' & app.nodeLevels == 2);                
                for k = 1:length(indxA)
                    modStr= [modStr;string({app.nodeList(ismember(app.nodeList, app.nodeList(indxA(k)).Children)).Text})];
                end                                              
            end
            modStr = convertStringsToChars(modStr);
            if ~iscell(modStr)
                modStr = {modStr};
            end
            app.ChooseModalityListBox.Items = unique(modStr);
            app.ChooseModalityListBox.Value = {};                       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MergeRecordingtoolUIFigure = uifigure('Visible', 'off');
            app.MergeRecordingtoolUIFigure.Name = 'MergeRecording tool';
            app.Tree = uitree(app.MergeRecordingtoolUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ChooseacquisitionsListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseacquisitionsListBoxLabel.Text = 'Choose acquisitions';
            app.ChooseacquisitionsListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseacquisitionsListBox.Items = {};
            app.ChooseacquisitionsListBox.Multiselect = 'on';
            app.ChooseacquisitionsListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseacquisitionsListBoxValueChanged, true);
            app.ChooseacquisitionsListBox.Value = {};
            app.ChooseModalityListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseModalityListBoxLabel.Text = 'Choose Modality';
            app.ChooseModalityListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseModalityListBox.Items = {};
            app.ChooseModalityListBox.Value = {};
            app.ChooseFilesListBoxLabel = uilabel(app.MergeRecordingtoolUIFigure);
            app.ChooseFilesListBoxLabel.Text = 'Choose File(s)';
            app.ChooseFilesListBox = uilistbox(app.MergeRecordingtoolUIFigure);
            app.ChooseFilesListBox.Items = {};
            app.ChooseFilesListBox.Multiselect = 'on';
            app.ChooseFilesListBox.Value = {};
            app.SelectButton = uibutton(app.MergeRecordingtoolUIFigure, 'state');
            app.SelectButton.Text = 'Select';
            app.Panel = uipanel(app.MergeRecordingtoolUIFigure);
            app.SetorderListBoxLabel = uilabel(app.Panel);
            app.SetorderListBoxLabel.Text = 'Set order';
            app.SetorderListBox = uilistbox(app.Panel);
            app.SetorderListBox.Items = {};
            app.SetorderListBox.Value = {};
            app.Button = uibutton(app.Panel, 'push');
            app.Button.Icon = 'icon_arrow_up.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.Icon = 'icon_arrow_dwn.png';
            app.Button_2.Text = '';
            app.MergetypeButtonGroup = uibuttongroup(app.Panel);
            app.MergetypeButtonGroup.Title = 'Merge type';
            app.MovieButton = uiradiobutton(app.MergetypeButtonGroup);
            app.MovieButton.Text = 'Movie';
            app.MovieButton.Value = true;
            app.TrialButton = uiradiobutton(app.MergetypeButtonGroup);
            app.TrialButton.Text = 'Trial';
            app.EventsPanel = uipanel(app.Panel);
            app.EventsPanel.Title = 'Events';
            app.StimIDEditField = uieditfield(app.EventsPanel, 'text');
            app.StimIDEditField.Editable = 'off';
            app.StimIDEditField.Enable = 'off';
            app.SetstimIDButton = uibutton(app.EventsPanel, 'push');
            app.SetstimIDButton.Enable = 'off';
            app.SetstimIDButton.Text = {'Set '; 'stim. ID'};
            app.UsecustomstimIDCheckBox = uicheckbox(app.EventsPanel);
            app.UsecustomstimIDCheckBox.Text = ' Use custom stim ID?';
            app.NewAcquistionNameEditFieldLabel = uilabel(app.Panel);
            app.NewAcquistionNameEditFieldLabel.Text = 'New Acquistion Name';
            app.NewAcquistionNameEditField = uieditfield(app.Panel, 'text');
            app.NewAcquistionNameEditField.Value = 'NewAcq';
            app.MergeDataButton = uibutton(app.Panel, 'push');
            app.MergeDataButton.Text = 'Merge Data';
            app.MergedfilenameListBoxLabel = uilabel(app.Panel);
            app.MergedfilenameListBoxLabel.Text = 'Merged file name';
            app.MergedfilenameListBox = uilistbox(app.Panel);
            app.MergedfilenameListBox.Items = {};
            app.MergedfilenameListBox.Multiselect = 'on';
            app.MergedfilenameListBox.Value = {};
            app.ChangenameButton = uibutton(app.Panel, 'push');
            app.ChangenameButton.Text = 'Change  name';
            % Show the figure after all components are created
            app.MergeRecordingtoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MergeRecs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MergeRecordingtoolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MergeRecordingtoolUIFigure)
        end
    end
end