classdef PipelineManagerTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        DiagnosticsPanel           matlab.ui.container.Panel
        GridDiagnostics            matlab.ui.container.GridLayout
        StatusLabel                matlab.ui.control.Label
        DiagnosticsTextArea        matlab.ui.control.TextArea
        ExecutionOrderPanel        matlab.ui.container.Panel
        GridExecutionOrder         matlab.ui.container.GridLayout
        ExecGraphHTML              matlab.ui.control.HTML
        SelectedstepdetailsPanel   matlab.ui.container.Panel
        GridSelectedStepDetails    matlab.ui.container.GridLayout
        FunctionNameField          matlab.ui.control.EditField
        ParametersTextArea         matlab.ui.control.TextArea
        ParametersLabel            matlab.ui.control.Label
        StepSummaryTextArea        matlab.ui.control.TextArea
        StepSummaryLabel           matlab.ui.control.Label
        StepTagField               matlab.ui.control.EditField
        StepTagLabel               matlab.ui.control.Label
        StepNameLabel              matlab.ui.control.Label
        PipelineGraphPanel         matlab.ui.container.Panel
        GridPipelineGraph          matlab.ui.container.GridLayout
        GraphHTML                  matlab.ui.control.HTML
        RefreshButton              matlab.ui.control.Button
        ResetViewButton            matlab.ui.control.Button
        AutoLayoutButton           matlab.ui.control.Button
        AvailableFunctionsPanel    matlab.ui.container.Panel
        GridAvailableFunctions     matlab.ui.container.GridLayout
        AddSelectedFunctionButton  matlab.ui.control.Button
        FunctionTree               matlab.ui.container.Tree
        Node                       matlab.ui.container.TreeNode
        Node2                      matlab.ui.container.TreeNode
        Node3                      matlab.ui.container.TreeNode
        Node4                      matlab.ui.container.TreeNode
        SearchFunctionEditField    matlab.ui.control.EditField
        GridLayoutTop              matlab.ui.container.GridLayout
        ValidateButton             matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        NewButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        pm = []
        graphHtmlFile char = ''
        execHtmlFile char = ''
        selectedNodeID double = NaN
        selectedStepTag char = ''
        selectedFunctionName char = ''
        bGraphHtmlReady logical = false
        bExecHtmlReady logical = false
    end

    methods (Access = private)

        function initializeUiState(app)
            %INITIALIZEUISTATE Reset passive UI content and local state.

            app.UIFigure.Name = 'Pipeline Manager Tool';

            app.selectedNodeID = NaN;
            app.selectedStepTag = '';
            app.selectedFunctionName = '';
            app.bGraphHtmlReady = false;
            app.bExecHtmlReady = false;

            app.StepTagField.Value = '';
            app.FunctionNameField.Value = '';
            app.StepSummaryTextArea.Value = {'<no step selected>'};
            app.ParametersTextArea.Value = {'<no step selected>'};
            app.DiagnosticsTextArea.Value = {''};

            app.AddSelectedFunctionButton.Enable = 'off';
            app.StepNameLabel.Text = 'Function';

            app.setStatus('Ready');

            try
                delete(app.FunctionTree.Children);
            catch
            end
        end

        function tf = promptCreatePipelineManager(app)
            %PROMPTCREATEPIPELINEMANAGER Create a new PipelineManager from a SaveFolder.

            tf = false;

            saveFolder = uigetdir(pwd, 'Select the SaveFolder for the new pipeline');
            if isequal(saveFolder, 0)
                app.appendDiagnostic('New pipeline creation cancelled by user.');
                app.setStatus('New pipeline cancelled.');
                return
            end

            try
                app.pm = PipelineManager(saveFolder);
                app.selectedNodeID = NaN;
                app.selectedStepTag = '';
                app.selectedFunctionName = '';

                app.populateFunctionTree();
                app.refreshAllViews();

                app.appendDiagnostic(sprintf('Created new PipelineManager using SaveFolder: %s', saveFolder));
                app.setStatus('New pipeline ready.');
                tf = true;

            catch ME
                app.appendDiagnostic(sprintf('Failed to create PipelineManager: %s', ME.message));
                app.setStatus('Failed to create PipelineManager.');
            end
        end

        function ensureHtmlFilesExist(app)
            %ENSUREHTMLFILESEXIST Create the local HTML files used by the uihtml controls.

            htmlRoot = fullfile(tempdir, 'PipelineManagerTool_html');
            if ~isfolder(htmlRoot)
                mkdir(htmlRoot);
            end

            app.graphHtmlFile = fullfile(htmlRoot, 'pipeline_manager_graph.html');
            app.execHtmlFile  = fullfile(htmlRoot, 'pipeline_manager_execution.html');

            app.writeTextFile(app.graphHtmlFile, app.getGraphHtmlLines());
            app.writeTextFile(app.execHtmlFile,  app.getExecHtmlLines());

            app.GraphHTML.HTMLSource = app.graphHtmlFile;
            app.ExecGraphHTML.HTMLSource = app.execHtmlFile;
        end

        function writeTextFile(~, filePath, lineList)
            %WRITETEXTFILE Write a cell array of lines to a text file.

            fid = fopen(filePath, 'w');
            if fid == -1
                error('PipelineManagerTool:writeTextFile:OpenFailed', ...
                    'Could not open "%s" for writing.', filePath);
            end

            cleaner = onCleanup(@() fclose(fid)); %#ok<NASGU>
            fprintf(fid, '%s\n', lineList{:});
        end

        function lineList = getGraphHtmlLines(~)
            %GETGRAPHHTMLLINES Return the HTML source for the DAG graph view.

            lineList = { ...
                '<!DOCTYPE html>', ...
                '<html>', ...
                '<head>', ...
                '  <meta charset="utf-8">', ...
                '  <title>Pipeline Graph</title>', ...
                '  <style>', ...
                '    html, body { margin: 0; padding: 0; width: 100%; height: 100%; overflow: auto; background: #f4f5f7; font-family: Arial, Helvetica, sans-serif; }', ...
                '    #root { width: 100%; height: 100%; }', ...
                '    #canvas { position: relative; min-width: 1000px; min-height: 700px; }', ...
                '    #edges { position: absolute; left: 0; top: 0; pointer-events: none; overflow: visible; }', ...
                '    #nodeLayer { position: absolute; inset: 0; }', ...
                '    #hint { position: absolute; top: 18px; left: 18px; color: #7a7a7a; font-size: 13px; }', ...
                '    .node { position: absolute; width: 220px; min-height: 120px; box-sizing: border-box; border: 1px solid #c8ccd3; border-radius: 12px; background: #ffffff; box-shadow: 0 2px 8px rgba(0,0,0,0.08); padding: 10px 10px 8px 10px; cursor: pointer; }', ...
                '    .node.folder { background: #f7fbff; }', ...
                '    .node.selected { border: 2px solid #2f78ff; box-shadow: 0 0 0 2px rgba(47,120,255,0.15); }', ...
                '    .title { font-size: 13px; font-weight: 700; white-space: nowrap; overflow: hidden; text-overflow: ellipsis; }', ...
                '    .subtitle { font-size: 12px; color: #5f6368; margin-top: 2px; white-space: nowrap; overflow: hidden; text-overflow: ellipsis; }', ...
                '    .badges { margin-top: 6px; display: flex; flex-wrap: wrap; gap: 6px; }', ...
                '    .badge { padding: 2px 6px; border-radius: 999px; background: #eef2f7; color: #3b3f45; font-size: 10px; }', ...
                '    .section { margin-top: 8px; }', ...
                '    .sectionLabel { font-size: 10px; font-weight: 700; color: #6b7280; text-transform: uppercase; letter-spacing: 0.04em; }', ...
                '    .sectionRow { margin-top: 3px; font-size: 11px; color: #313131; white-space: nowrap; overflow: hidden; text-overflow: ellipsis; }', ...
                '    .edgePath { stroke: #8c99a5; stroke-width: 2; fill: none; }', ...
                '    .edgeLabel { font-size: 10px; fill: #48505a; }', ...
                '    #menu { position: fixed; z-index: 1000; min-width: 170px; background: #ffffff; border: 1px solid #cfd5dc; border-radius: 10px; box-shadow: 0 6px 20px rgba(0,0,0,0.18); display: none; overflow: hidden; }', ...
                '    .menuItem { padding: 10px 12px; font-size: 12px; cursor: pointer; }', ...
                '    .menuItem:hover { background: #eef4ff; }', ...
                '  </style>', ...
                '</head>', ...
                '<body>', ...
                '  <div id="root">', ...
                '    <div id="canvas">', ...
                '      <svg id="edges"></svg>', ...
                '      <div id="nodeLayer"></div>', ...
                '      <div id="hint">No pipeline loaded.</div>', ...
                '    </div>', ...
                '    <div id="menu">', ...
                '      <div class="menuItem" onclick="menuAction(''removeStep'')">Remove step</div>', ...
                '      <div class="menuItem" onclick="menuAction(''reconnectStep'')">Reconnect inputs</div>', ...
                '      <div class="menuItem" onclick="menuAction(''editParameters'')">Edit parameters</div>', ...
                '    </div>', ...
                '  </div>', ...
                '', ...
                '  <script>', ...
                '    let htmlComponent = null;', ...
                '    let model = { nodes: [], edges: [], selectedNodeID: null };', ...
                '    let contextNode = null;', ...
                '', ...
                '    function setup(component) {', ...
                '      htmlComponent = component;', ...
                '      htmlComponent.addEventListener("DataChanged", function() { applyData(); });', ...
                '      document.addEventListener("click", function() { hideMenu(); });', ...
                '      applyData();', ...
                '      htmlComponent.sendEventToMATLAB("htmlReady", { component: "graph" });', ...
                '    }', ...
                '', ...
                '    function normalizeToArray(value) {', ...
                '      if (Array.isArray(value)) {', ...
                '        return value;', ...
                '      }', ...
                '      if (value === undefined || value === null) {', ...
                '        return [];', ...
                '      }', ...
                '      if (typeof value === "object") {', ...
                '        return [value];', ...
                '      }', ...
                '      return [];', ...
                '    }', ...
                '', ...
                '    function applyData() {', ...
                '      const d = (htmlComponent && htmlComponent.Data) ? htmlComponent.Data : {};', ...
                '      model.nodes = normalizeToArray(d.nodes);', ...
                '      model.edges = normalizeToArray(d.edges);', ...
                '      if (Object.prototype.hasOwnProperty.call(d, "selectedNodeID")) {', ...
                '        model.selectedNodeID = d.selectedNodeID;', ...
                '      }', ...
                '      if (d.command === "resetView") {', ...
                '        document.documentElement.scrollTop = 0;', ...
                '        document.documentElement.scrollLeft = 0;', ...
                '      }', ...
                '      render();', ...
                '      if (htmlComponent) {', ...
                '        htmlComponent.sendEventToMATLAB("graphRendered", { nodeCount: model.nodes.length, edgeCount: model.edges.length });', ...
                '      }', ...
                '    }', ...
                '', ...
                '    function render() {', ...
                '      const nodeLayer = document.getElementById("nodeLayer");', ...
                '      const svg = document.getElementById("edges");', ...
                '      const hint = document.getElementById("hint");', ...
                '      nodeLayer.innerHTML = "";', ...
                '      svg.innerHTML = "";', ...
                '', ...
                '      if (!model.nodes.length) {', ...
                '        hint.style.display = "block";', ...
                '        return;', ...
                '      }', ...
                '', ...
                '      hint.style.display = "none";', ...
                '', ...
                '      const nodeMap = {};', ...
                '      let maxX = 1000;', ...
                '      let maxY = 700;', ...
                '', ...
                '      model.nodes.forEach(function(n) {', ...
                '        nodeMap[n.id] = n;', ...
                '        maxX = Math.max(maxX, (n.x || 0) + (n.width || 220) + 80);', ...
                '        maxY = Math.max(maxY, (n.y || 0) + (n.height || 140) + 80);', ...
                '', ...
                '        const el = document.createElement("div");', ...
                '        el.className = "node" + ((n.id === model.selectedNodeID) ? " selected" : "") + ((n.kind === "folder") ? " folder" : "");', ...
                '        el.style.left = (n.x || 0) + "px";', ...
                '        el.style.top = (n.y || 0) + "px";', ...
                '        el.style.width = (n.width || 220) + "px";', ...
                '        el.style.minHeight = (n.height || 140) + "px";', ...
                '', ...
                '        let badges = "";', ...
                '        if (n.topoIndex > 0) { badges += `<span class="badge">Topo ${n.topoIndex}</span>`; }', ...
                '        if (n.execIndex > 0) { badges += `<span class="badge">Exec ${n.execIndex}</span>`; }', ...
                '        if (n.hasSavedOutputs) { badges += `<span class="badge">Saved output</span>`; }', ...
                '', ...
                '        el.innerHTML = `', ...
                '          <div class="title">${esc(n.stepTag || "")}</div>', ...
                '          <div class="subtitle">${esc(n.funcName || "")}</div>', ...
                '          <div class="badges">${badges}</div>', ...
                '          <div class="section">', ...
                '            <div class="sectionLabel">Inputs</div>', ...
                '            ${makeRows(n.inputs)}', ...
                '          </div>', ...
                '          <div class="section">', ...
                '            <div class="sectionLabel">Outputs</div>', ...
                '            ${makeRows(n.outputs)}', ...
                '          </div>`;', ...
                '', ...
                '        el.addEventListener("click", function(ev) {', ...
                '          ev.stopPropagation();', ...
                '          hideMenu();', ...
                '          sendSelected(n);', ...
                '        });', ...
                '', ...
                '        el.addEventListener("contextmenu", function(ev) {', ...
                '          ev.preventDefault();', ...
                '          ev.stopPropagation();', ...
                '          contextNode = n;', ...
                '          sendSelected(n);', ...
                '          showMenu(ev.clientX, ev.clientY);', ...
                '        });', ...
                '', ...
                '        nodeLayer.appendChild(el);', ...
                '      });', ...
                '', ...
                '      const canvas = document.getElementById("canvas");', ...
                '      canvas.style.minWidth = maxX + "px";', ...
                '      canvas.style.minHeight = maxY + "px";', ...
                '      svg.setAttribute("width", maxX);', ...
                '      svg.setAttribute("height", maxY);', ...
                '      svg.style.width = maxX + "px";', ...
                '      svg.style.height = maxY + "px";', ...
                '', ...
                '      model.edges.forEach(function(e) {', ...
                '        const s = nodeMap[e.sourceNodeID];', ...
                '        const t = nodeMap[e.targetNodeID];', ...
                '        if (!s || !t) { return; }', ...
                '', ...
                '        const x1 = (s.x || 0) + (s.width || 220);', ...
                '        const y1 = (s.y || 0) + Math.max(48, (s.height || 140) / 2);', ...
                '        const x2 = (t.x || 0);', ...
                '        const y2 = (t.y || 0) + Math.max(48, (t.height || 140) / 2);', ...
                '        const midX = (x1 + x2) / 2;', ...
                '', ...
                '        const path = document.createElementNS("http://www.w3.org/2000/svg", "path");', ...
                '        path.setAttribute("class", "edgePath");', ...
                '        path.setAttribute("d", `M ${x1} ${y1} C ${midX} ${y1}, ${midX} ${y2}, ${x2} ${y2}`);', ...
                '        svg.appendChild(path);', ...
                '', ...
                '        const label = document.createElementNS("http://www.w3.org/2000/svg", "text");', ...
                '        label.setAttribute("class", "edgeLabel");', ...
                '        label.setAttribute("x", midX);', ...
                '        label.setAttribute("y", ((y1 + y2) / 2) - 4);', ...
                '        label.setAttribute("text-anchor", "middle");', ...
                '        label.textContent = `${e.sourceLabel || ""} → ${e.targetLabel || ""}`;', ...
                '        svg.appendChild(label);', ...
                '      });', ...
                '    }', ...
                '', ...
                '    function makeRows(items) {', ...
                '      const safeItems = normalizeToArray(items);', ...
                '      if (!safeItems.length) {', ...
                '        return `<div class="sectionRow">&lt;none&gt;</div>`;', ...
                '      }', ...
                '', ...
                '      let rows = safeItems.slice(0, 3).map(function(item) {', ...
                '        return `<div class="sectionRow">${esc(item)}</div>`;', ...
                '      }).join("");', ...
                '', ...
                '      if (safeItems.length > 3) {', ...
                '        rows += `<div class="sectionRow">... ${safeItems.length - 3} more</div>`;', ...
                '      }', ...
                '', ...
                '      return rows;', ...
                '    }', ...
                '', ...
                '    function sendSelected(node) {', ...
                '      model.selectedNodeID = node.id;', ...
                '      render();', ...
                '      if (htmlComponent) {', ...
                '        htmlComponent.sendEventToMATLAB("nodeSelected", { nodeID: node.id, stepTag: node.stepTag || "", funcName: node.funcName || "" });', ...
                '      }', ...
                '    }', ...
                '', ...
                '    function showMenu(x, y) {', ...
                '      const menu = document.getElementById("menu");', ...
                '      menu.style.left = x + "px";', ...
                '      menu.style.top = y + "px";', ...
                '      menu.style.display = "block";', ...
                '    }', ...
                '', ...
                '    function hideMenu() {', ...
                '      const menu = document.getElementById("menu");', ...
                '      menu.style.display = "none";', ...
                '    }', ...
                '', ...
                '    function menuAction(action) {', ...
                '      hideMenu();', ...
                '      if (!contextNode || !htmlComponent) { return; }', ...
                '      htmlComponent.sendEventToMATLAB("contextAction", { action: action, nodeID: contextNode.id, stepTag: contextNode.stepTag || "" });', ...
                '    }', ...
                '', ...
                '    function esc(value) {', ...
                '      return String((value === undefined || value === null) ? "" : value)', ...
                '        .replace(/&/g, "&amp;")', ...
                '        .replace(/</g, "&lt;")', ...
                '        .replace(/>/g, "&gt;")', ...
                '        .replace(/"/g, "&quot;")', ...
                '        .replace(/''/g, "&#39;");', ...
                '    }', ...
                '  </script>', ...
                '</body>', ...
                '</html>' ...
                };
        end

        function lineList = getExecHtmlLines(~)
            %GETEXECHTMLLINES Return the HTML source for the execution-order view.

            lineList = { ...
                '<!DOCTYPE html>', ...
                '<html>', ...
                '<head>', ...
                '  <meta charset="utf-8">', ...
                '  <title>Execution Order</title>', ...
                '  <style>', ...
                '    html, body { margin: 0; padding: 0; width: 100%; height: 100%; overflow: auto; background: #f4f5f7; font-family: Arial, Helvetica, sans-serif; }', ...
                '    #root { padding: 10px; box-sizing: border-box; }', ...
                '    #note { color: #6b7280; font-size: 12px; margin-bottom: 10px; }', ...
                '    .step { background: #ffffff; border: 1px solid #d2d6dd; border-radius: 10px; padding: 10px; margin-bottom: 8px; cursor: pointer; box-shadow: 0 1px 4px rgba(0,0,0,0.06); }', ...
                '    .step.selected { border: 2px solid #2f78ff; box-shadow: 0 0 0 2px rgba(47,120,255,0.12); }', ...
                '    .stepHeader { display: flex; justify-content: space-between; align-items: center; gap: 8px; }', ...
                '    .stepTitle { font-size: 13px; font-weight: 700; white-space: nowrap; overflow: hidden; text-overflow: ellipsis; }', ...
                '    .stepTag { color: #5f6368; font-size: 12px; margin-top: 2px; }', ...
                '    .orderBadge { padding: 2px 6px; border-radius: 999px; background: #eef2f7; font-size: 10px; }', ...
                '    #hint { color: #7a7a7a; font-size: 13px; }', ...
                '  </style>', ...
                '</head>', ...
                '<body>', ...
                '  <div id="root">', ...
                '    <div id="note"></div>', ...
                '    <div id="list"></div>', ...
                '    <div id="hint">No execution data available.</div>', ...
                '  </div>', ...
                '', ...
                '  <script>', ...
                '    let htmlComponent = null;', ...
                '    let model = { steps: [], selectedNodeID: null, note: "" };', ...
                '', ...
                '    function setup(component) {', ...
                '      htmlComponent = component;', ...
                '      htmlComponent.addEventListener("DataChanged", function() { applyData(); });', ...
                '      applyData();', ...
                '      htmlComponent.sendEventToMATLAB("htmlReady", { component: "execution" });', ...
                '    }', ...
                '', ...
                '    function normalizeToArray(value) {', ...
                '      if (Array.isArray(value)) {', ...
                '        return value;', ...
                '      }', ...
                '      if (value === undefined || value === null) {', ...
                '        return [];', ...
                '      }', ...
                '      if (typeof value === "object") {', ...
                '        return [value];', ...
                '      }', ...
                '      return [];', ...
                '    }', ...
                '', ...
                '    function applyData() {', ...
                '      const d = (htmlComponent && htmlComponent.Data) ? htmlComponent.Data : {};', ...
                '      model.steps = normalizeToArray(d.steps);', ...
                '      model.selectedNodeID = Object.prototype.hasOwnProperty.call(d, "selectedNodeID") ? d.selectedNodeID : null;', ...
                '      model.note = d.note || "";', ...
                '      render();', ...
                '    }', ...
                '', ...
                '    function render() {', ...
                '      const note = document.getElementById("note");', ...
                '      const list = document.getElementById("list");', ...
                '      const hint = document.getElementById("hint");', ...
                '      note.textContent = model.note || "";', ...
                '      list.innerHTML = "";', ...
                '', ...
                '      if (!model.steps.length) {', ...
                '        hint.style.display = "block";', ...
                '        return;', ...
                '      }', ...
                '', ...
                '      hint.style.display = "none";', ...
                '', ...
                '      model.steps.forEach(function(step) {', ...
                '        const card = document.createElement("div");', ...
                '        card.className = "step" + ((step.id === model.selectedNodeID) ? " selected" : "");', ...
                '        card.innerHTML = `', ...
                '          <div class="stepHeader">', ...
                '            <div>', ...
                '              <div class="stepTitle">${esc(step.funcName || "")}</div>', ...
                '              <div class="stepTag">${esc(step.stepTag || "")}</div>', ...
                '            </div>', ...
                '            <div class="orderBadge">#${step.orderIndex || ""}</div>', ...
                '          </div>`;', ...
                '', ...
                '        card.addEventListener("click", function() {', ...
                '          if (htmlComponent) {', ...
                '            htmlComponent.sendEventToMATLAB("stepSelected", { nodeID: step.id, stepTag: step.stepTag || "", funcName: step.funcName || "" });', ...
                '          }', ...
                '        });', ...
                '', ...
                '        list.appendChild(card);', ...
                '      });', ...
                '    }', ...
                '', ...
                '    function esc(value) {', ...
                '      return String((value === undefined || value === null) ? "" : value)', ...
                '        .replace(/&/g, "&amp;")', ...
                '        .replace(/</g, "&lt;")', ...
                '        .replace(/>/g, "&gt;")', ...
                '        .replace(/"/g, "&quot;")', ...
                '        .replace(/''/g, "&#39;");', ...
                '    }', ...
                '  </script>', ...
                '</body>', ...
                '</html>' ...
                };
        end

        function populateFunctionTree(app)
            %POPULATEFUNCTIONTREE Populate the available-function tree.

            app.AddSelectedFunctionButton.Enable = 'off';
            app.selectedFunctionName = '';

            try
                delete(app.FunctionTree.Children);
            catch
            end

            if isempty(app.pm) || ~isprop(app.pm, 'funcList') || isempty(app.pm.funcList)
                placeholderNode = uitreenode(app.FunctionTree);
                placeholderNode.Text = 'Create or attach a PipelineManager first';
                placeholderNode.NodeData = struct('kind', 'placeholder');
                return
            end

            funcs = app.pm.funcList;

            filterText = strtrim(char(string(app.SearchFunctionEditField.Value)));
            if ~isempty(filterText)
                keepMask = false(numel(funcs), 1);
                filterLower = lower(filterText);

                for iFunc = 1:numel(funcs)
                    fName = lower(char(string(funcs(iFunc).name)));
                    fFolder = '';
                    if isfield(funcs(iFunc), 'folder') && ~isempty(funcs(iFunc).folder)
                        fFolder = lower(char(string(funcs(iFunc).folder)));
                    end
                    keepMask(iFunc) = contains(fName, filterLower) || contains(fFolder, filterLower);
                end

                funcs = funcs(keepMask);
            end

            if isempty(funcs)
                placeholderNode = uitreenode(app.FunctionTree);
                placeholderNode.Text = 'No matching functions';
                placeholderNode.NodeData = struct('kind', 'placeholder');
                return
            end

            folderList = strings(numel(funcs), 1);
            for iFunc = 1:numel(funcs)
                if isfield(funcs(iFunc), 'folder') && ~isempty(funcs(iFunc).folder)
                    folderList(iFunc) = string(funcs(iFunc).folder);
                else
                    folderList(iFunc) = "Uncategorized";
                end
            end

            commonRoot = app.findCommonFolderPrefix(folderList);
            nodeMap = containers.Map('KeyType', 'char', 'ValueType', 'any');

            for iFunc = 1:numel(funcs)
                folderStr = char(folderList(iFunc));
                relFolder = app.makeRelativeFolder(folderStr, commonRoot);
                folderParts = app.splitFolderParts(relFolder);

                if isempty(folderParts)
                    folderParts = {'Uncategorized'};
                end

                parentHandle = app.FunctionTree;
                currentKey = '';

                for iPart = 1:numel(folderParts)
                    thisPart = folderParts{iPart};

                    if isempty(currentKey)
                        currentKey = thisPart;
                    else
                        currentKey = [currentKey '|' thisPart];
                    end

                    if ~isKey(nodeMap, currentKey)
                        catNode = uitreenode(parentHandle);
                        catNode.Text = thisPart;
                        catNode.NodeData = struct('kind', 'category', 'pathKey', currentKey);
                        nodeMap(currentKey) = catNode;
                    end

                    parentHandle = nodeMap(currentKey);
                end

                funcNode = uitreenode(parentHandle);
                funcNode.Text = char(string(funcs(iFunc).name));
                funcNode.NodeData = struct( ...
                    'kind', 'function', ...
                    'name', char(string(funcs(iFunc).name)), ...
                    'folder', folderStr);
            end

            rootNodes = app.FunctionTree.Children;
            for iRoot = 1:numel(rootNodes)
                try
                    expand(rootNodes(iRoot));
                catch
                end
            end
        end

        function commonRoot = findCommonFolderPrefix(~, folderList)
            %FINDCOMMONFOLDERPREFIX Return the common folder prefix across paths.

            commonRoot = '';

            if isempty(folderList)
                return
            end

            partsCell = cell(numel(folderList), 1);
            minLen = inf;

            for i = 1:numel(folderList)
                parts = regexp(char(folderList(i)), ['[\\' filesep '/]+'], 'split');
                parts = parts(~cellfun(@isempty, parts));
                partsCell{i} = parts;
                minLen = min(minLen, numel(parts));
            end

            commonParts = {};

            for iPart = 1:minLen
                token = partsCell{1}{iPart};
                isShared = true;

                for iList = 2:numel(partsCell)
                    if ~strcmpi(partsCell{iList}{iPart}, token)
                        isShared = false;
                        break
                    end
                end

                if ~isShared
                    break
                end

                commonParts{end+1} = token; %#ok<AGROW>
            end

            if isempty(commonParts)
                commonRoot = '';
            else
                commonRoot = strjoin(commonParts, filesep);
            end
        end

        function relFolder = makeRelativeFolder(~, folderStr, commonRoot)
            %MAKERELATIVEFOLDER Strip the common root from one folder string.

            relFolder = folderStr;

            if isempty(commonRoot)
                return
            end

            prefixWithSep = [commonRoot filesep];

            if startsWith(folderStr, prefixWithSep)
                relFolder = extractAfter(string(folderStr), strlength(prefixWithSep));
                relFolder = char(relFolder);
            elseif strcmpi(folderStr, commonRoot)
                relFolder = '';
            end
        end

        function parts = splitFolderParts(~, folderStr)
            %SPLITFOLDERPARTS Split a folder string into non-empty path parts.

            if isempty(folderStr)
                parts = {};
                return
            end

            parts = regexp(folderStr, ['[\\' filesep '/]+'], 'split');
            parts = parts(~cellfun(@isempty, parts));
        end

        function refreshAllViews(app)
            %REFRESHALLVIEWS Refresh graph, execution view, and selected-step details.

            app.refreshGraphView();
            app.refreshExecutionView();
            app.refreshSelectedStepPanel();
        end

        function refreshGraphView(app)
            if ~app.bGraphHtmlReady
                return
            end
            payload = app.buildGraphPayload();
            payload.command = 'refresh';
            app.GraphHTML.Data = payload;
        end

        function refreshExecutionView(app)
            if ~app.bExecHtmlReady
                return
            end
            payload = app.buildExecutionPayload();
            app.ExecGraphHTML.Data = payload;
        end
        
        function payload = buildGraphPayload(app)
            %BUILDGRAPHPAYLOAD Build the payload rendered by the graph HTML view.

            payload = struct();
            payload.nodes = struct('id', {}, 'stepTag', {}, 'funcName', {}, 'kind', {}, ...
                'x', {}, 'y', {}, 'width', {}, 'height', {}, 'topoIndex', {}, ...
                'execIndex', {}, 'hasSavedOutputs', {}, 'inputs', {}, 'outputs', {});
            payload.edges = struct('sourceNodeID', {}, 'targetNodeID', {}, ...
                'sourceLabel', {}, 'targetLabel', {});
            payload.selectedNodeID = app.selectedNodeID;

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            nodesLocal = app.pm.nodes;
            connectionsLocal = app.pm.connections;
            topoOrder = app.computeTopoOrderFromGraph(nodesLocal, connectionsLocal);
            levelMap = app.computeNodeLevels(nodesLocal, connectionsLocal, topoOrder);

            topoPosMap = containers.Map('KeyType', 'double', 'ValueType', 'double');
            execPosMap = containers.Map('KeyType', 'double', 'ValueType', 'double');
            rowCounterMap = containers.Map('KeyType', 'char', 'ValueType', 'double');

            for iTopo = 1:numel(topoOrder)
                topoPosMap(topoOrder(iTopo)) = iTopo;
                execPosMap(topoOrder(iTopo)) = iTopo; % Current GUI fallback
            end

            for iTopo = 1:numel(topoOrder)
                nodeID = topoOrder(iTopo);
                nodeIdx = find([nodesLocal.id] == nodeID, 1, 'first');
                nodeLocal = nodesLocal(nodeIdx);

                levelVal = levelMap(nodeID);
                levelKey = sprintf('L%d', levelVal);

                if ~isKey(rowCounterMap, levelKey)
                    rowCounterMap(levelKey) = 0;
                end

                rowVal = rowCounterMap(levelKey) + 1;
                rowCounterMap(levelKey) = rowVal;

                x = 40 + (levelVal - 1) * 270;
                y = 40 + (rowVal - 1) * 180;
                widthVal = 220;
                heightVal = 140;

                funcName = app.getNodeFunctionName(nodeLocal);
                hasSavedOutputs = false;
                outputItems = strings(0, 1);

                if isfield(nodeLocal, 'info') && isfield(nodeLocal.info, 'outputs') && ~isempty(nodeLocal.info.outputs)
                    for iOut = 1:numel(nodeLocal.info.outputs)
                        outDef = nodeLocal.info.outputs(iOut);
                        outName = char(string(outDef.name));
                        outType = app.typeListToText(outDef.type);

                        outMode = 'data';
                        if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                            outMode = char(string(outDef.outputMode));
                        end

                        saveSuffix = '';
                        if isfield(outDef, 'saveFileName') && ~isempty(outDef.saveFileName)
                            hasSavedOutputs = true;
                            saveSuffix = sprintf(' [save: %s]', char(string(outDef.saveFileName)));
                        end

                        outputItems(end+1, 1) = sprintf('%s (%s, %s)%s', outName, outType, outMode, saveSuffix); %#ok<AGROW>
                    end
                end

                inputItems = strings(0, 1);
                if strcmpi(nodeLocal.kind, 'folder')
                    inputItems = strings(0, 1);
                elseif isfield(nodeLocal, 'info') && isfield(nodeLocal.info, 'inputs') && ~isempty(nodeLocal.info.inputs)
                    for iIn = 1:numel(nodeLocal.info.inputs)
                        inDef = nodeLocal.info.inputs(iIn);

                        if ~isfield(inDef, 'isData') || ~inDef.isData
                            continue
                        end

                        if isempty(connectionsLocal)
                            connIdx = [];
                        else
                            connIdx = find([connectionsLocal.targetNodeID] == nodeLocal.id & ...
                                strcmpi({connectionsLocal.targetInputName}, char(string(inDef.name))), 1, 'first');
                        end
                        if isempty(connIdx)
                            inputItems(end+1, 1) = sprintf('%s <= <missing>', char(string(inDef.name))); %#ok<AGROW>
                        else
                            connLocal = connectionsLocal(connIdx);
                            srcIdx = find([nodesLocal.id] == connLocal.sourceNodeID, 1, 'first');
                            srcNode = nodesLocal(srcIdx);

                            srcRef = char(string(connLocal.sourceOutputName));
                            if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                                srcRef = char(string(connLocal.selectedFile));
                            end

                            inputItems(end+1, 1) = sprintf('%s <= %s:%s', ...
                                char(string(inDef.name)), ...
                                char(string(srcNode.name)), ...
                                srcRef); %#ok<AGROW>
                        end
                    end
                end

                payload.nodes(end+1) = struct( ... %#ok<AGROW>
                    'id', nodeLocal.id, ...
                    'stepTag', char(string(nodeLocal.name)), ...
                    'funcName', funcName, ...
                    'kind', char(string(nodeLocal.kind)), ...
                    'x', x, ...
                    'y', y, ...
                    'width', widthVal, ...
                    'height', heightVal, ...
                    'topoIndex', topoPosMap(nodeLocal.id), ...
                    'execIndex', execPosMap(nodeLocal.id), ...
                    'hasSavedOutputs', hasSavedOutputs, ...
                    'inputs', {cellstr(inputItems)}, ...
                    'outputs', {cellstr(outputItems)});
            end

            for iConn = 1:numel(connectionsLocal)
                connLocal = connectionsLocal(iConn);

                srcLabel = char(string(connLocal.sourceOutputName));
                if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                    srcLabel = char(string(connLocal.selectedFile));
                end

                payload.edges(end+1) = struct( ... %#ok<AGROW>
                    'sourceNodeID', connLocal.sourceNodeID, ...
                    'targetNodeID', connLocal.targetNodeID, ...
                    'sourceLabel', srcLabel, ...
                    'targetLabel', char(string(connLocal.targetInputName)));
            end
        end

        function payload = buildExecutionPayload(app)
            %BUILDEXECUTIONPAYLOAD Build the payload rendered by the execution HTML view.

            payload = struct();
            payload.steps = struct('id', {}, 'stepTag', {}, 'funcName', {}, 'orderIndex', {});
            payload.selectedNodeID = app.selectedNodeID;
            payload.note = 'Execution preview currently follows topological order.';

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            nodesLocal = app.pm.nodes;
            topoOrder = app.computeTopoOrderFromGraph(nodesLocal, app.pm.connections);

            for iStep = 1:numel(topoOrder)
                nodeIdx = find([nodesLocal.id] == topoOrder(iStep), 1, 'first');
                nodeLocal = nodesLocal(nodeIdx);

                payload.steps(end+1) = struct( ... %#ok<AGROW>
                    'id', nodeLocal.id, ...
                    'stepTag', char(string(nodeLocal.name)), ...
                    'funcName', app.getNodeFunctionName(nodeLocal), ...
                    'orderIndex', iStep);
            end
        end

        function topoOrder = computeTopoOrderFromGraph(~, nodesLocal, connectionsLocal)
            %COMPUTETOPOORDERFROMGRAPH Compute a topological order from nodes/connections.

            if isempty(nodesLocal)
                topoOrder = [];
                return
            end

            nodeIDs = [nodesLocal.id];

            if isempty(connectionsLocal)
                topoOrder = nodeIDs;
                return
            end

            inDegree = zeros(1, numel(nodeIDs));

            for iConn = 1:numel(connectionsLocal)
                tgtIdx = find(nodeIDs == connectionsLocal(iConn).targetNodeID, 1, 'first');
                if ~isempty(tgtIdx)
                    inDegree(tgtIdx) = inDegree(tgtIdx) + 1;
                end
            end

            queue = nodeIDs(inDegree == 0);
            topoOrder = [];

            while ~isempty(queue)
                currentID = queue(1);
                queue(1) = [];
                topoOrder(end+1) = currentID; %#ok<AGROW>

                outMask = [connectionsLocal.sourceNodeID] == currentID;
                outgoing = connectionsLocal(outMask);

                for iOut = 1:numel(outgoing)
                    tgtIdx = find(nodeIDs == outgoing(iOut).targetNodeID, 1, 'first');
                    if isempty(tgtIdx)
                        continue
                    end

                    inDegree(tgtIdx) = inDegree(tgtIdx) - 1;

                    if inDegree(tgtIdx) == 0
                        queue(end+1) = nodeIDs(tgtIdx); %#ok<AGROW>
                    end
                end
            end

            if numel(topoOrder) ~= numel(nodeIDs)
                topoOrder = nodeIDs;
            end
        end

        function levelMap = computeNodeLevels(~, nodesLocal, connectionsLocal, topoOrder)
            %COMPUTENODELEVELS Compute a simple layered layout index for each node.

            levelMap = containers.Map('KeyType', 'double', 'ValueType', 'double');

            if isempty(nodesLocal)
                return
            end

            % No connections -> every node is a root-level node
            if isempty(connectionsLocal)
                for iTopo = 1:numel(topoOrder)
                    levelMap(topoOrder(iTopo)) = 1;
                end
                return
            end

            for iTopo = 1:numel(topoOrder)
                nodeID = topoOrder(iTopo);

                inMask = [connectionsLocal.targetNodeID] == nodeID;
                incoming = connectionsLocal(inMask);

                if isempty(incoming)
                    levelMap(nodeID) = 1;
                    continue
                end

                parentLevels = zeros(1, numel(incoming));
                for iIn = 1:numel(incoming)
                    srcID = incoming(iIn).sourceNodeID;
                    if isKey(levelMap, srcID)
                        parentLevels(iIn) = levelMap(srcID);
                    else
                        parentLevels(iIn) = 1;
                    end
                end

                levelMap(nodeID) = max(parentLevels) + 1;
            end
        end

        function leafIDs = computeLeafNodeIDs(~, nodesLocal, connectionsLocal)
            %COMPUTELEAFNODEIDS Return node IDs with no outgoing edges.

            if isempty(nodesLocal)
                leafIDs = [];
                return
            end

            nodeIDs = [nodesLocal.id];
            if isempty(connectionsLocal)
                leafIDs = nodeIDs;
                return
            end

            srcIDs = [connectionsLocal.sourceNodeID];
            leafIDs = nodeIDs(~ismember(nodeIDs, srcIDs));
        end

        function refreshSelectedStepPanel(app)
            %REFRESHSELECTEDSTEPPANEL Refresh the selected-step details panel.

            if isempty(app.pm) || isempty(app.pm.nodes) || isnan(app.selectedNodeID)
                app.clearSelectedStepPanel();
                return
            end

            nodeIdx = find([app.pm.nodes.id] == app.selectedNodeID, 1, 'first');
            if isempty(nodeIdx)
                app.clearSelectedStepPanel();
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            app.StepTagField.Value = char(string(nodeLocal.name));
            app.FunctionNameField.Value = app.getNodeFunctionName(nodeLocal);
            app.StepSummaryTextArea.Value = app.buildStepSummaryText(nodeLocal);
            app.ParametersTextArea.Value = app.buildParameterText(nodeLocal);
        end

        function clearSelectedStepPanel(app)
            %CLEARSELECTEDSTEPPANEL Clear the right-hand details panel.

            app.StepTagField.Value = '';
            app.FunctionNameField.Value = '';
            app.StepSummaryTextArea.Value = {'<no step selected>'};
            app.ParametersTextArea.Value = {'<no step selected>'};
        end

        function outLines = buildStepSummaryText(app, nodeLocal)
            %BUILDSTEPSUMMARYTEXT Build the Step Summary text for one selected node.

            outLines = strings(0, 1);

            if isempty(app.pm)
                outLines = "<no step selected>";
                outLines = cellstr(outLines);
                return
            end

            topoOrder = app.computeTopoOrderFromGraph(app.pm.nodes, app.pm.connections);
            topoPos = find(topoOrder == nodeLocal.id, 1, 'first');
            execPos = topoPos; % Current GUI fallback

            outLines(end+1, 1) = "Topological order: " + string(app.valueOrPlaceholder(topoPos)); %#ok<AGROW>
            outLines(end+1, 1) = "Execution order: " + string(app.valueOrPlaceholder(execPos)) + " (GUI fallback)"; %#ok<AGROW>
            outLines(end+1, 1) = ""; %#ok<AGROW>

            outLines(end+1, 1) = "Inputs:"; %#ok<AGROW>
            dataInputLines = app.getDataInputLines(nodeLocal);
            outLines = [outLines; string(dataInputLines(:))]; %#ok<AGROW>
            outLines(end+1, 1) = ""; %#ok<AGROW>

            outLines(end+1, 1) = "Outputs:"; %#ok<AGROW>
            outputLines = app.getOutputLines(nodeLocal);
            outLines = [outLines; string(outputLines(:))]; %#ok<AGROW>

            saveLines = app.getSavedOutputLines(nodeLocal);
            if ~isempty(saveLines)
                outLines(end+1, 1) = ""; %#ok<AGROW>
                outLines(end+1, 1) = "Saved files:"; %#ok<AGROW>
                outLines = [outLines; string(saveLines(:))]; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getDataInputLines(app, nodeLocal)
            %GETDATAINPUTLINES Build the input lines shown in the selected-step summary.

            outLines = strings(0, 1);

            if strcmpi(nodeLocal.kind, 'folder')
                outLines(end+1, 1) = "<file source node>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'inputs') || isempty(nodeLocal.info.inputs)
                outLines(end+1, 1) = "<no inputs>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iIn = 1:numel(nodeLocal.info.inputs)
                inDef = nodeLocal.info.inputs(iIn);

                if ~isfield(inDef, 'isData') || ~inDef.isData
                    continue
                end

                connIdx = find([app.pm.connections.targetNodeID] == nodeLocal.id & ...
                    strcmpi({app.pm.connections.targetInputName}, char(string(inDef.name))), 1, 'first');

                if isempty(connIdx)
                    outLines(end+1, 1) = "- " + string(inDef.name) + " <= <missing>"; %#ok<AGROW>
                    continue
                end

                connLocal = app.pm.connections(connIdx);
                srcIdx = find([app.pm.nodes.id] == connLocal.sourceNodeID, 1, 'first');
                srcNode = app.pm.nodes(srcIdx);

                srcRef = char(string(connLocal.sourceOutputName));
                if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                    srcRef = char(string(connLocal.selectedFile));
                end

                outLines(end+1, 1) = "- " + string(inDef.name) + " <= " + string(srcNode.name) + ":" + string(srcRef); %#ok<AGROW>
            end

            if isempty(outLines)
                outLines(end+1, 1) = "<no data inputs>"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getOutputLines(app, nodeLocal)
            %GETOUTPUTLINES Build the output lines shown in the selected-step summary.

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                outLines(end+1, 1) = "<no outputs>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iOut = 1:numel(nodeLocal.info.outputs)
                outDef = nodeLocal.info.outputs(iOut);
                outName = char(string(outDef.name));
                outType = app.typeListToText(outDef.type);

                outMode = 'data';
                if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                    outMode = char(string(outDef.outputMode));
                end

                outLines(end+1, 1) = "- " + string(outName) + " (" + string(outType) + ", " + string(outMode) + ")"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getSavedOutputLines(~, nodeLocal)
            %GETSAVEDOUTPUTLINES Return output save-target lines for one node.

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                outLines = cellstr(outLines);
                return
            end

            for iOut = 1:numel(nodeLocal.info.outputs)
                outDef = nodeLocal.info.outputs(iOut);

                if isfield(outDef, 'saveFileName') && ~isempty(outDef.saveFileName)
                    outLines(end+1, 1) = "- " + string(outDef.name) + " -> " + string(outDef.saveFileName); %#ok<AGROW>
                end
            end

            outLines = cellstr(outLines);
        end

        function outLines = buildParameterText(app, nodeLocal)
            %BUILDPARAMETERTEXT Build the Parameters text for one selected node.

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'parameters') || isempty(nodeLocal.info.parameters)
                outLines(end+1, 1) = "<no parameters>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iParam = 1:numel(nodeLocal.info.parameters)
                p = nodeLocal.info.parameters(iParam);

                if ~isfield(p, 'name') || isempty(p.name)
                    continue
                end

                pValue = [];
                if isfield(p, 'value')
                    pValue = p.value;
                elseif isfield(p, 'default')
                    pValue = p.default;
                end

                outLines(end+1, 1) = string(p.name) + ": " + string(app.formatValueForDisplay(pValue)); %#ok<AGROW>
            end

            if isempty(outLines)
                outLines(end+1, 1) = "<no parameters>"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function txt = formatValueForDisplay(~, valueIn)
            %FORMATVALUEFORDISPLAY Convert a MATLAB value to short readable text.

            if isempty(valueIn)
                txt = '[]';
                return
            end

            if ischar(valueIn) || (isstring(valueIn) && isscalar(valueIn))
                txt = char(string(valueIn));
                return
            end

            if isnumeric(valueIn) || islogical(valueIn)
                if isscalar(valueIn) || (isvector(valueIn) && numel(valueIn) <= 6)
                    txt = mat2str(valueIn);
                else
                    txt = sprintf('[%s %s]', strjoin(string(size(valueIn)), 'x'), class(valueIn));
                end
                return
            end

            if iscell(valueIn)
                txt = sprintf('[%s cell]', strjoin(string(size(valueIn)), 'x'));
                return
            end

            if isstruct(valueIn)
                txt = sprintf('[%s struct]', strjoin(string(size(valueIn)), 'x'));
                return
            end

            if isa(valueIn, 'function_handle')
                txt = ['@' func2str(valueIn)];
                return
            end

            txt = class(valueIn);
        end

        function txt = getNodeFunctionName(~, nodeLocal)
            %GETNODEFUNCTIONNAME Return the callable function name for one node.

            txt = char(string(nodeLocal.name));

            if isfield(nodeLocal, 'info') && isfield(nodeLocal.info, 'name') && ~isempty(nodeLocal.info.name)
                txt = char(string(nodeLocal.info.name));
            end
        end

        function txt = typeListToText(~, typeIn)
            %TYPELISTTOTEXT Convert type metadata to one display string.

            if isempty(typeIn)
                txt = 'Unknown';
                return
            end

            if ischar(typeIn) || (isstring(typeIn) && isscalar(typeIn))
                txt = char(string(typeIn));
                return
            end

            txt = strjoin(string(typeIn), ' / ');
            txt = char(txt);
        end

        function outVal = valueOrPlaceholder(~, val)
            %VALUEORPLACEHOLDER Return a string-friendly value or placeholder.

            if isempty(val)
                outVal = '<n/a>';
            else
                outVal = val;
            end
        end

        function appendDiagnostic(app, msg)
            %APPENDDIAGNOSTIC Append one timestamped line to the diagnostics box.

            stamp = char(datetime('now', 'Format', 'HH:mm:ss'));
            newLine = sprintf('[%s] %s', stamp, msg);

            currentVal = string(app.DiagnosticsTextArea.Value);
            if isempty(currentVal) || (numel(currentVal) == 1 && strlength(currentVal(1)) == 0)
                app.DiagnosticsTextArea.Value = {newLine};
            else
                app.DiagnosticsTextArea.Value = cellstr([currentVal; string(newLine)]);
            end
        end

        function setStatus(app, msg)
            %SETSTATUS Update the bottom status label.

            app.StatusLabel.Text = ['Status: ' char(string(msg))];
        end

        function validateAndReportPipeline(app)
            %VALIDATEANDREPORTPIPELINE Run pipeline validation and log the result.

            if isempty(app.pm)
                app.appendDiagnostic('No PipelineManager is attached.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            try
                [tf, report] = app.pm.validateGraph('throwOnError', false);

                if tf
                    app.appendDiagnostic('Pipeline validation passed.');
                    app.setStatus('Pipeline is valid.');
                else
                    app.appendDiagnostic(sprintf('Pipeline validation failed with %d error(s).', numel(report.errors)));
                    for iErr = 1:numel(report.errors)
                        app.appendDiagnostic(report.errors{iErr});
                    end
                    for iWarn = 1:numel(report.warnings)
                        app.appendDiagnostic(['Warning: ' report.warnings{iWarn}]);
                    end
                    app.setStatus('Pipeline is invalid.');
                end

            catch ME
                app.appendDiagnostic(sprintf('Validation failed: %s', ME.message));
                app.setStatus('Validation failed.');
            end
        end

        function funcName = getSelectedFunctionName(app)
            %GETSELECTEDFUNCTIONNAME Return the currently selected function-tree leaf name.

            funcName = '';

            selectedNodes = app.FunctionTree.SelectedNodes;
            if isempty(selectedNodes)
                return
            end

            selectedNode = selectedNodes(1);
            nodeData = selectedNode.NodeData;

            if isempty(nodeData) || ~isstruct(nodeData) || ~isfield(nodeData, 'kind')
                return
            end

            if strcmpi(nodeData.kind, 'function') && isfield(nodeData, 'name')
                funcName = char(string(nodeData.name));
            end
        end

        function nodeLocal = getSelectedGraphNode(app)
            %GETSELECTEDGRAPHNODE Return the currently selected graph node struct or [].

            nodeLocal = [];

            if isempty(app.pm) || isempty(app.pm.nodes) || isnan(app.selectedNodeID)
                return
            end

            idx = find([app.pm.nodes.id] == app.selectedNodeID, 1, 'first');
            if isempty(idx)
                return
            end

            nodeLocal = app.pm.nodes(idx);
        end

        function tryAddSelectedFunction(app)
            %TRYADDSELECTEDFUNCTION Add the selected function when the add is unambiguous.

            if isempty(app.pm)
                app.appendDiagnostic('No PipelineManager is attached.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            funcName = app.getSelectedFunctionName();
            if isempty(funcName)
                app.appendDiagnostic('No function is selected.');
                app.setStatus('No function selected.');
                return
            end

            try
                info = app.pm.getPipelineInfo(funcName);

                nDataInputs = 0;
                if isfield(info, 'inputs') && ~isempty(info.inputs)
                    nDataInputs = sum(arrayfun(@(x) isfield(x, 'isData') && x.isData, info.inputs));
                end

                explicitInput = [];

                if nDataInputs == 1 && ~isempty(app.pm.nodes) && ~isnan(app.selectedNodeID)
                    leafIDs = app.computeLeafNodeIDs(app.pm.nodes, app.pm.connections);

                    if any(leafIDs == app.selectedNodeID)
                        selectedNode = app.getSelectedGraphNode();
                        if ~isempty(selectedNode)
                            explicitInput = char(string(selectedNode.name));
                        end
                    end
                end

                if nDataInputs > 1
                    app.appendDiagnostic(sprintf(['Function "%s" requires multiple data inputs. ' ...
                        'Use the reconnect workflow after adding supporting nodes.'], funcName));
                    app.setStatus('Ambiguous add not supported in v1.');
                    return
                end

                if nDataInputs == 1 && isempty(explicitInput)
                    leafIDs = app.computeLeafNodeIDs(app.pm.nodes, app.pm.connections);
                    if numel(leafIDs) > 1
                        app.appendDiagnostic(sprintf(['Multiple branches are available for "%s". ' ...
                            'Select one leaf node in the graph first, then add the step again.'], funcName));
                        app.setStatus('Select a leaf node first.');
                        return
                    end
                end

                if isempty(explicitInput)
                    stepTag = app.pm.addStep(funcName);
                else
                    stepTag = app.pm.addStep(funcName, 'input', explicitInput);
                end

                if isempty(stepTag)
                    app.appendDiagnostic(sprintf('Add-step operation for "%s" was cancelled.', funcName));
                    app.setStatus('Add-step cancelled.');
                    return
                end

                newIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
                if ~isempty(newIdx)
                    app.selectedNodeID = app.pm.nodes(newIdx).id;
                    app.selectedStepTag = char(string(app.pm.nodes(newIdx).name));
                end

                app.refreshAllViews();
                app.appendDiagnostic(sprintf('Added function "%s" as step "%s".', funcName, stepTag));
                app.setStatus('Step added.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to add function "%s": %s', funcName, ME.message));
                app.setStatus('Failed to add step.');
            end
        end

        function reconnectStepInteractive(app, stepTag)
            %RECONNECTSTEPINTERACTIVE Guided reconnect dialog for one selected step.

            if isempty(app.pm)
                app.appendDiagnostic('Reconnect requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            nodeIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
            if isempty(nodeIdx)
                app.appendDiagnostic(sprintf('Reconnect failed. Step "%s" was not found.', stepTag));
                app.setStatus('Reconnect failed.');
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            if strcmpi(nodeLocal.kind, 'folder')
                app.appendDiagnostic('Folder nodes cannot be reconnected.');
                app.setStatus('Reconnect not allowed.');
                return
            end

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'inputs') || isempty(nodeLocal.info.inputs)
                app.appendDiagnostic(sprintf('Step "%s" has no inputs to reconnect.', stepTag));
                app.setStatus('No inputs to reconnect.');
                return
            end

            dataInputs = nodeLocal.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, nodeLocal.info.inputs));

            if isempty(dataInputs)
                app.appendDiagnostic(sprintf('Step "%s" has no data inputs to reconnect.', stepTag));
                app.setStatus('No data inputs to reconnect.');
                return
            end

            inputRefs = cell(1, numel(dataInputs));

            for iIn = 1:numel(dataInputs)
                [displayList, refList] = app.getReconnectChoices(nodeLocal, dataInputs(iIn));

                if isempty(refList)
                    app.appendDiagnostic(sprintf('No compatible sources were found for input "%s".', char(string(dataInputs(iIn).name))));
                    app.setStatus('Reconnect aborted.');
                    return
                end

                [selIdx, tf] = listdlg( ...
                    'PromptString', sprintf('Select source for input "%s":', char(string(dataInputs(iIn).name))), ...
                    'SelectionMode', 'single', ...
                    'ListString', displayList, ...
                    'ListSize', [600 300]);

                if ~tf || isempty(selIdx)
                    app.appendDiagnostic('Reconnect cancelled by user.');
                    app.setStatus('Reconnect cancelled.');
                    return
                end

                inputRefs{iIn} = refList{selIdx};
            end

            try
                app.pm.reconnectStep(stepTag, 'input', inputRefs);
                app.refreshAllViews();
                app.appendDiagnostic(sprintf('Reconnected step "%s".', stepTag));
                app.setStatus('Step reconnected.');

            catch ME
                app.appendDiagnostic(sprintf('Reconnect failed for "%s": %s', stepTag, ME.message));
                app.setStatus('Reconnect failed.');
            end
        end

        function [displayList, refList] = getReconnectChoices(app, targetNode, inputDef)
            %GETRECONNECTCHOICES Build explicit reconnect options for one target input.

            displayList = {};
            refList = {};

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            dstTypes = {};
            if isfield(inputDef, 'type') && ~isempty(inputDef.type)
                if ischar(inputDef.type) || (isstring(inputDef.type) && isscalar(inputDef.type))
                    dstTypes = {char(string(inputDef.type))};
                else
                    dstTypes = cellstr(string(inputDef.type(:)));
                end
            end

            for iNode = 1:numel(app.pm.nodes)
                srcNode = app.pm.nodes(iNode);

                if srcNode.id == targetNode.id
                    continue
                end

                if ~isfield(srcNode, 'info') || ~isfield(srcNode.info, 'outputs') || isempty(srcNode.info.outputs)
                    continue
                end

                for iOut = 1:numel(srcNode.info.outputs)
                    outDef = srcNode.info.outputs(iOut);

                    if ~isfield(outDef, 'isData') || ~outDef.isData
                        continue
                    end

                    srcTypes = {};
                    if isfield(outDef, 'type') && ~isempty(outDef.type)
                        if ischar(outDef.type) || (isstring(outDef.type) && isscalar(outDef.type))
                            srcTypes = {char(string(outDef.type))};
                        else
                            srcTypes = cellstr(string(outDef.type(:)));
                        end
                    end

                    if ~app.areTypesCompatible(srcTypes, dstTypes)
                        continue
                    end

                    outName = char(string(outDef.name));
                    outMode = 'data';
                    if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                        outMode = char(string(outDef.outputMode));
                    end

                    typeText = app.typeListToText(srcTypes);

                    if strcmpi(outMode, 'file')
                        declaredFiles = {};
                        if isfield(outDef, 'defOutfilename') && ~isempty(outDef.defOutfilename)
                            if ischar(outDef.defOutfilename) || (isstring(outDef.defOutfilename) && isscalar(outDef.defOutfilename))
                                declaredFiles = {char(string(outDef.defOutfilename))};
                            elseif iscell(outDef.defOutfilename)
                                declaredFiles = cellfun(@(x) char(string(x)), outDef.defOutfilename, 'UniformOutput', false);
                            end
                        end

                        declaredFiles = declaredFiles(~cellfun(@isempty, declaredFiles));

                        if isempty(declaredFiles)
                            displayList{end+1} = sprintf('%s:%s [%s, file]', srcNode.name, outName, typeText); %#ok<AGROW>
                            refList{end+1} = sprintf('%s:%s', srcNode.name, outName); %#ok<AGROW>
                        else
                            for iFile = 1:numel(declaredFiles)
                                displayList{end+1} = sprintf('%s:%s [%s, file]', srcNode.name, declaredFiles{iFile}, typeText); %#ok<AGROW>
                                refList{end+1} = sprintf('%s:%s', srcNode.name, declaredFiles{iFile}); %#ok<AGROW>
                            end
                        end

                    else
                        displayList{end+1} = sprintf('%s:%s [%s]', srcNode.name, outName, typeText); %#ok<AGROW>
                        refList{end+1} = sprintf('%s:%s', srcNode.name, outName); %#ok<AGROW>
                    end
                end
            end

            if isempty(displayList)
                displayList = {};
                refList = {};
            end
        end

        function tf = areTypesCompatible(~, srcTypes, dstTypes)
            %ARETYPESCOMPATIBLE Return true when two type lists are compatible.

            if isempty(srcTypes) || isempty(dstTypes)
                tf = true;
                return
            end

            srcTypes = cellstr(string(srcTypes(:)));
            dstTypes = cellstr(string(dstTypes(:)));

            srcHasWildcard = any(strcmp(srcTypes, 'UnknownDataType'));
            dstHasWildcard = any(strcmp(dstTypes, 'UnknownDataType'));

            tf = srcHasWildcard || dstHasWildcard || ~isempty(intersect(srcTypes, dstTypes));
        end
    end

    methods (Access = public)

        function attachPipelineManager(app, pm)
            %ATTACHPIPELINEMANAGER Attach an existing PipelineManager instance.

            if nargin < 2 || isempty(pm) || ~isa(pm, 'PipelineManager')
                error('PipelineManagerTool:attachPipelineManager:InvalidInput', ...
                    'attachPipelineManager expects a valid PipelineManager instance.');
            end

            app.pm = pm;
            app.selectedNodeID = NaN;
            app.selectedStepTag = '';
            app.selectedFunctionName = '';

            app.populateFunctionTree();
            app.refreshAllViews();

            app.appendDiagnostic(sprintf('PipelineManager attached. Current step count: %d.', numel(app.pm.nodes)));
            app.setStatus('PipelineManager attached.');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeUiState();

            try
                app.ensureHtmlFilesExist();
                app.appendDiagnostic(sprintf('Loaded graph HTML: %s', app.graphHtmlFile));
                app.appendDiagnostic(sprintf('Loaded execution HTML: %s', app.execHtmlFile));
            catch ME
                app.appendDiagnostic(sprintf('Failed to initialize HTML components: %s', ME.message));
                app.setStatus('Failed to initialize HTML components.');
            end

            app.populateFunctionTree();
            app.refreshAllViews();

            app.appendDiagnostic('App initialized.');
            app.setStatus('App initialized.');

        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            if isempty(app.pm)
                app.promptCreatePipelineManager();
                return
            end

            choice = uiconfirm(app.UIFigure, ...
                'Clear the current pipeline and start a new one?', ...
                'New Pipeline', ...
                'Options', {'Clear Pipeline', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if ~strcmp(choice, 'Clear Pipeline')
                app.appendDiagnostic('New pipeline action cancelled.');
                app.setStatus('New pipeline cancelled.');
                return
            end

            try
                app.pm.clearPipeline();
                app.selectedNodeID = NaN;
                app.selectedStepTag = '';
                app.refreshAllViews();

                app.appendDiagnostic('Current pipeline cleared.');
                app.setStatus('New empty pipeline ready.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to clear pipeline: %s', ME.message));
                app.setStatus('Failed to clear pipeline.');
            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.pm)
                tf = app.promptCreatePipelineManager();
                if ~tf
                    return
                end
            end

            [fileName, folderName] = uigetfile({'*.pipe', 'Pipeline files (*.pipe)'}, 'Load pipeline');
            if isequal(fileName, 0)
                app.appendDiagnostic('Load pipeline cancelled by user.');
                app.setStatus('Load cancelled.');
                return
            end

            pipeFile = fullfile(folderName, fileName);

            try
                app.pm.loadPipe(pipeFile);

                app.selectedNodeID = NaN;
                app.selectedStepTag = '';

                app.populateFunctionTree();
                app.refreshAllViews();

                app.appendDiagnostic(sprintf('Loaded pipeline: %s', pipeFile));
                app.setStatus('Pipeline loaded.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to load pipeline: %s', ME.message));
                app.setStatus('Failed to load pipeline.');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            if isempty(app.pm)
                app.appendDiagnostic('Save requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            [fileName, folderName] = uiputfile({'*.pipe', 'Pipeline files (*.pipe)'}, ...
                'Save pipeline as', fullfile(pwd, 'MyPipeline.pipe'));

            if isequal(fileName, 0)
                app.appendDiagnostic('Save pipeline cancelled by user.');
                app.setStatus('Save cancelled.');
                return
            end

            try
                app.pm.savePipe(fullfile(folderName, fileName));
                app.appendDiagnostic(sprintf('Saved pipeline to %s', fullfile(folderName, fileName)));
                app.setStatus('Pipeline saved.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to save pipeline: %s', ME.message));
                app.setStatus('Failed to save pipeline.');
            end

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)

            app.validateAndReportPipeline();
            app.refreshGraphView();

        end

        % Value changed function: SearchFunctionEditField
        function SearchFunctionEditFieldValueChanged(app, event)
            app.populateFunctionTree();

            if isempty(strtrim(char(string(app.SearchFunctionEditField.Value))))
                app.appendDiagnostic('Function search cleared.');
            else
                app.appendDiagnostic(sprintf('Function search filter updated: %s', char(string(app.SearchFunctionEditField.Value))));
            end

            app.setStatus('Function tree updated.');


        end

        % Button pushed function: AddSelectedFunctionButton
        function AddSelectedFunctionButtonPushed(app, event)
            app.tryAddSelectedFunction();
        end

        % Button pushed function: AutoLayoutButton
        function AutoLayoutButtonPushed(app, event)
            app.refreshGraphView();
            app.appendDiagnostic('Auto-layout applied.');
            app.setStatus('Graph auto-layout applied.');

        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            payload = app.buildGraphPayload();
            payload.command = 'resetView';
            app.GraphHTML.Data = payload;

            app.appendDiagnostic('Graph view reset.');
            app.setStatus('Graph view reset.');

        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshAllViews();
            app.appendDiagnostic('Views refreshed.');
            app.setStatus('Views refreshed.');

        end

        % Data changed function: GraphHTML
        function GraphHTMLDataChanged(app, event)
            % This callback is currently not used.
            % Graph interactions are handled through HTMLEventReceived.


        end

        % Event received function: GraphHTML
        function GraphHTMLEventReceived(app, event)

            eventName = lower(char(string(event.HTMLEventName)));
            eventData = event.HTMLEventData;

            switch eventName

                case 'htmlready'
                    if isstruct(eventData) && isfield(eventData, 'component') && strcmpi(char(string(eventData.component)), 'graph')
                        app.bGraphHtmlReady = true;
                        app.refreshGraphView();
                        app.appendDiagnostic('HTML graph is ready.');
                        app.setStatus('Graph view ready.');
                    end

                case 'graphrendered'
                    % No action needed.

                case 'nodeselected'
                    if isstruct(eventData) && isfield(eventData, 'nodeID')
                        app.selectedNodeID = double(eventData.nodeID);

                        if isfield(eventData, 'stepTag')
                            app.selectedStepTag = char(string(eventData.stepTag));
                        else
                            app.selectedStepTag = '';
                        end

                        app.refreshSelectedStepPanel();
                        app.refreshGraphView();
                        app.refreshExecutionView();

                        if ~isempty(app.selectedStepTag)
                            app.setStatus(['Selected step: ' app.selectedStepTag]);
                        else
                            app.setStatus('Step selected.');
                        end
                    end

                case 'contextaction'
                    if ~isstruct(eventData) || ~isfield(eventData, 'action')
                        return
                    end

                    action = lower(char(string(eventData.action)));
                    stepTag = '';

                    if isfield(eventData, 'stepTag')
                        stepTag = char(string(eventData.stepTag));
                    end

                    switch action

                        case 'removestep'
                            if isempty(stepTag) || isempty(app.pm)
                                return
                            end

                            choice = uiconfirm(app.UIFigure, ...
                                sprintf('Remove step "%s"?', stepTag), ...
                                'Remove Step', ...
                                'Options', {'Remove', 'Cancel'}, ...
                                'DefaultOption', 2, ...
                                'CancelOption', 2);

                            if ~strcmp(choice, 'Remove')
                                app.appendDiagnostic('Remove-step action cancelled.');
                                app.setStatus('Remove-step cancelled.');
                                return
                            end

                            try
                                app.pm.rmStep(stepTag);

                                if strcmpi(app.selectedStepTag, stepTag)
                                    app.selectedNodeID = NaN;
                                    app.selectedStepTag = '';
                                end

                                app.refreshAllViews();
                                app.appendDiagnostic(sprintf('Removed step "%s".', stepTag));
                                app.setStatus('Step removed.');

                            catch ME
                                app.appendDiagnostic(sprintf('Failed to remove step "%s": %s', stepTag, ME.message));
                                app.setStatus('Failed to remove step.');
                            end

                        case 'editparameters'
                            if isempty(stepTag) || isempty(app.pm)
                                return
                            end

                            try
                                app.pm.setParameters(stepTag);
                                app.refreshAllViews();
                                app.appendDiagnostic(sprintf('Parameter dialog completed for "%s".', stepTag));
                                app.setStatus('Parameters updated.');

                            catch ME
                                app.appendDiagnostic(sprintf('Failed to edit parameters for "%s": %s', stepTag, ME.message));
                                app.setStatus('Failed to edit parameters.');
                            end

                        case 'reconnectstep'
                            if isempty(stepTag)
                                return
                            end
                            app.reconnectStepInteractive(stepTag);

                        otherwise
                            app.appendDiagnostic(sprintf('Unknown graph context action: %s', action));
                            app.setStatus('Unknown graph action.');
                    end
            end


        end

        % Data changed function: ExecGraphHTML
        function ExecGraphHTMLDataChanged(app, event)
            % This callback is currently not used.
            % Execution-view interactions are handled through HTMLEventReceived.


        end

        % Event received function: ExecGraphHTML
        function ExecGraphHTMLEventReceived(app, event)

            eventName = lower(char(string(event.HTMLEventName)));
            eventData = event.HTMLEventData;

            switch eventName

                case 'htmlready'
                    if isstruct(eventData) && isfield(eventData, 'component') && strcmpi(char(string(eventData.component)), 'execution')
                        app.bExecHtmlReady = true;
                        app.refreshExecutionView();
                        app.appendDiagnostic('Execution-order HTML view is ready.');
                        app.setStatus('Execution view ready.');
                    end

                case 'stepselected'
                    if isstruct(eventData) && isfield(eventData, 'nodeID')
                        app.selectedNodeID = double(eventData.nodeID);

                        if isfield(eventData, 'stepTag')
                            app.selectedStepTag = char(string(eventData.stepTag));
                        else
                            app.selectedStepTag = '';
                        end

                        app.refreshSelectedStepPanel();
                        app.refreshGraphView();
                        app.refreshExecutionView();

                        if ~isempty(app.selectedStepTag)
                            app.setStatus(['Selected step: ' app.selectedStepTag]);
                        else
                            app.setStatus('Execution step selected.');
                        end
                    end
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Selection changed function: FunctionTree
        function FunctionTreeSelectionChanged(app, event)

            selectedNodes = app.FunctionTree.SelectedNodes;

            if isempty(selectedNodes)
                app.selectedFunctionName = '';
                app.AddSelectedFunctionButton.Enable = 'off';
                return
            end

            selectedNode = selectedNodes(1);
            nodeData = selectedNode.NodeData;

            if isempty(nodeData) || ~isstruct(nodeData) || ~isfield(nodeData, 'kind')
                app.selectedFunctionName = '';
                app.AddSelectedFunctionButton.Enable = 'off';
                return
            end

            if strcmpi(nodeData.kind, 'function') && isfield(nodeData, 'name')
                app.selectedFunctionName = char(string(nodeData.name));
                app.AddSelectedFunctionButton.Enable = 'on';
                app.appendDiagnostic(sprintf('Selected function: %s', app.selectedFunctionName));
                app.setStatus('Function selected.');
            else
                app.selectedFunctionName = '';
                app.AddSelectedFunctionButton.Enable = 'off';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutTop = uigridlayout(app.GridLayout);
            app.NewButton = uibutton(app.GridLayoutTop, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.LoadButton = uibutton(app.GridLayoutTop, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayoutTop, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ValidateButton = uibutton(app.GridLayoutTop, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.AvailableFunctionsPanel = uipanel(app.GridLayout);
            app.AvailableFunctionsPanel.Title = 'Available Functions';
            app.GridAvailableFunctions = uigridlayout(app.AvailableFunctionsPanel);
            app.SearchFunctionEditField = uieditfield(app.GridAvailableFunctions, 'text');
            app.SearchFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @SearchFunctionEditFieldValueChanged, true);
            app.SearchFunctionEditField.Placeholder = 'Search function ...';
            app.FunctionTree = uitree(app.GridAvailableFunctions);
            app.FunctionTree.SelectionChangedFcn = createCallbackFcn(app, @FunctionTreeSelectionChanged, true);
            app.Node = uitreenode(app.FunctionTree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.AddSelectedFunctionButton = uibutton(app.GridAvailableFunctions, 'push');
            app.AddSelectedFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectedFunctionButtonPushed, true);
            app.AddSelectedFunctionButton.Enable = 'off';
            app.AddSelectedFunctionButton.Text = 'Add Selected Function';
            app.PipelineGraphPanel = uipanel(app.GridLayout);
            app.PipelineGraphPanel.Title = 'Pipeline Graph ';
            app.GridPipelineGraph = uigridlayout(app.PipelineGraphPanel);
            app.AutoLayoutButton = uibutton(app.GridPipelineGraph, 'push');
            app.AutoLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @AutoLayoutButtonPushed, true);
            app.AutoLayoutButton.Text = 'Auto Layout';
            app.ResetViewButton = uibutton(app.GridPipelineGraph, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.RefreshButton = uibutton(app.GridPipelineGraph, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.GraphHTML = uihtml(app.GridPipelineGraph);
            app.GraphHTML.DataChangedFcn = createCallbackFcn(app, @GraphHTMLDataChanged, true);
            app.GraphHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @GraphHTMLEventReceived, true);
            app.SelectedstepdetailsPanel = uipanel(app.GridLayout);
            app.SelectedstepdetailsPanel.Title = 'Selected step details';
            app.GridSelectedStepDetails = uigridlayout(app.SelectedstepdetailsPanel);
            app.StepNameLabel = uilabel(app.GridSelectedStepDetails);
            app.StepNameLabel.Text = 'Step';
            app.StepTagLabel = uilabel(app.GridSelectedStepDetails);
            app.StepTagLabel.Text = 'Step Tag';
            app.StepTagField = uieditfield(app.GridSelectedStepDetails, 'text');
            app.StepTagField.Editable = 'off';
            app.StepSummaryLabel = uilabel(app.GridSelectedStepDetails);
            app.StepSummaryLabel.Text = 'Step Summary';
            app.StepSummaryTextArea = uitextarea(app.GridSelectedStepDetails);
            app.StepSummaryTextArea.Editable = 'off';
            app.ParametersLabel = uilabel(app.GridSelectedStepDetails);
            app.ParametersLabel.Text = 'Parameters';
            app.ParametersTextArea = uitextarea(app.GridSelectedStepDetails);
            app.ParametersTextArea.Editable = 'off';
            app.FunctionNameField = uieditfield(app.GridSelectedStepDetails, 'text');
            app.FunctionNameField.Editable = 'off';
            app.ExecutionOrderPanel = uipanel(app.GridLayout);
            app.ExecutionOrderPanel.Title = 'Execution Order';
            app.GridExecutionOrder = uigridlayout(app.ExecutionOrderPanel);
            app.ExecGraphHTML = uihtml(app.GridExecutionOrder);
            app.ExecGraphHTML.DataChangedFcn = createCallbackFcn(app, @ExecGraphHTMLDataChanged, true);
            app.ExecGraphHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ExecGraphHTMLEventReceived, true);
            app.DiagnosticsPanel = uipanel(app.GridLayout);
            app.DiagnosticsPanel.Title = 'Diagnostics';
            app.GridDiagnostics = uigridlayout(app.DiagnosticsPanel);
            app.DiagnosticsTextArea = uitextarea(app.GridDiagnostics);
            app.DiagnosticsTextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.GridDiagnostics);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineManagerTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end