classdef Data_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFIgure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        GridLayout2               matlab.ui.container.GridLayout
        OptionsPanel              matlab.ui.container.Panel
        XaxisButtonGroup          matlab.ui.container.ButtonGroup
        GroupButton               matlab.ui.control.ToggleButton
        SubjectButton             matlab.ui.control.ToggleButton
        AcquisitionButton         matlab.ui.control.ToggleButton
        EventButton               matlab.ui.control.ToggleButton
        ROIButton                 matlab.ui.control.ToggleButton
        GroupsButtonGroup         matlab.ui.container.ButtonGroup
        GroupButton_2             matlab.ui.control.ToggleButton
        SubjectButton_2           matlab.ui.control.ToggleButton
        AcquisitionButton_2       matlab.ui.control.ToggleButton
        EventButton_2             matlab.ui.control.ToggleButton
        ROIButton_2               matlab.ui.control.ToggleButton
        GroupcolorsDropDownLabel  matlab.ui.control.Label
        GroupcolorsDropDown       matlab.ui.control.DropDown
        ConfirmButton_2           matlab.ui.control.Button
        DataperplotButtonGroup    matlab.ui.container.ButtonGroup
        GroupButton_3             matlab.ui.control.ToggleButton
        SubjectButton_3           matlab.ui.control.ToggleButton
        AcquisitionButton_3       matlab.ui.control.ToggleButton
        EventButton_3             matlab.ui.control.ToggleButton
        ROIButton_3               matlab.ui.control.ToggleButton
        GridLayout3               matlab.ui.container.GridLayout
        ApplyButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataArr % Description
        dataOut % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
        function loadPrefs(app)
            % LOADPREFS loads the options saved in a .mat file inside umIT's
            % folder.
            disp('Loading preferences...')
            
        end
        
        function pivotData(app)
            % PIVOTDATA rearranges the data in "dataArr" and saves the output
            % in "dataOut".
            disp('Pivoting data')
            out = struct('gIndx', [],'sIndx', [], 'aIndx', [], 'rIndx',[],'eIndx',[],'data',[]);
            dimNames = {app.XaxisButtonGroup.SelectedObject.Text, app.GroupsButtonGroup.SelectedObject.Text, ...
                app.DataperplotButtonGroup.SelectedObject.Text};
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataArr, b_useGUI)
            app.UIFIgure.Visible = 'off';            
            app.dataArr = dataArr;            
            app.loadPrefs;            
            app.dataOut;    
            if ~b_useGUI               
                app.UIFIgureCloseRequest;
            else                
                movegui(app.UIFIgure, 'center');
                app.UIFIgure.Visible = 'on';            
            end            
        end

        % Selection changed function: DataperplotButtonGroup, 
        % GroupsButtonGroup, XaxisButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            currentGroup = event.Source;
            selectedButton = event.Source.SelectedObject;            
            otherGroups = setdiff([app.XaxisButtonGroup, app.GroupsButtonGroup, app.DataperplotButtonGroup], currentGroup);
            otherSelect = arrayfun(@(x) x.SelectedObject.Text, otherGroups, 'UniformOutput',false);
            if ismember(selectedButton.Text, otherSelect)
                event.Source.SelectedObject = event.OldValue;
            end
        end

        % Close request function: UIFIgure
        function UIFIgureCloseRequest(app, event)
            
            assignin("caller", 'out', app.dataOut);
            if strcmpi(app.UIFIgure.Visible, 'off')
                delete(app)                    
            else
                %%%% ASK TO SAVE CHANGES IF DIFFERENT... %%%
                delete(app);
            end
        end

        % Button pushed function: ConfirmButton_2
        function ConfirmButton_2Pushed(app, event)
            app.pivotData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFIgure = uifigure('Visible', 'off');
            app.UIFIgure.Name = 'Data selector';
            app.UIFIgure.CloseRequestFcn = createCallbackFcn(app, @UIFIgureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFIgure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Group Name'; 'Color'};
            app.UITable.RowName = {};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OptionsPanel = uipanel(app.GridLayout2);
            app.OptionsPanel.Title = 'Options';
            app.XaxisButtonGroup = uibuttongroup(app.OptionsPanel);
            app.XaxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.XaxisButtonGroup.Title = 'X axis';
            app.GroupButton = uitogglebutton(app.XaxisButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjectButton = uitogglebutton(app.XaxisButtonGroup);
            app.SubjectButton.Text = 'Subject';
            app.AcquisitionButton = uitogglebutton(app.XaxisButtonGroup);
            app.AcquisitionButton.Text = 'Acquisition';
            app.AcquisitionButton.Value = true;
            app.EventButton = uitogglebutton(app.XaxisButtonGroup);
            app.EventButton.Text = 'Event';
            app.ROIButton = uitogglebutton(app.XaxisButtonGroup);
            app.ROIButton.Text = 'ROI';
            app.GroupsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.GroupsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GroupsButtonGroup.Title = 'Group(s)';
            app.GroupButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.GroupButton_2.Text = 'Group';
            app.GroupButton_2.Value = true;
            app.SubjectButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.SubjectButton_2.Text = 'Subject';
            app.AcquisitionButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.AcquisitionButton_2.Text = 'Acquisition';
            app.EventButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.EventButton_2.Text = 'Event';
            app.ROIButton_2 = uitogglebutton(app.GroupsButtonGroup);
            app.ROIButton_2.Text = 'ROI';
            app.GroupcolorsDropDownLabel = uilabel(app.OptionsPanel);
            app.GroupcolorsDropDownLabel.Text = 'Group colors';
            app.GroupcolorsDropDown = uidropdown(app.OptionsPanel);
            app.GroupcolorsDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.GroupcolorsDropDown.Value = 'parula';
            app.ConfirmButton_2 = uibutton(app.OptionsPanel, 'push');
            app.ConfirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_2Pushed, true);
            app.ConfirmButton_2.Text = 'Confirm';
            app.DataperplotButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DataperplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DataperplotButtonGroup.Title = 'Data per plot';
            app.GroupButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.GroupButton_3.Text = 'Group';
            app.SubjectButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.SubjectButton_3.Text = 'Subject';
            app.AcquisitionButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.AcquisitionButton_3.Text = 'Acquisition';
            app.EventButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.EventButton_3.Text = 'Event';
            app.ROIButton_3 = uitogglebutton(app.DataperplotButtonGroup);
            app.ROIButton_3.Text = 'ROI';
            app.ROIButton_3.Value = true;
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFIgure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFIgure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFIgure)
        end
    end
end