classdef PlotLongData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SaveExportMenu                matlab.ui.container.Menu
        SavefiguresMenu               matlab.ui.container.Menu
        ExportfiguresMenu             matlab.ui.container.Menu
        ChangeplottypeMenu            matlab.ui.container.Menu
        LinePlotMenu                  matlab.ui.container.Menu
        BoxPlotMenu                   matlab.ui.container.Menu
        ROIinmainfigureDropDownLabel  matlab.ui.control.Label
        ROIinmainfigureDropDown       matlab.ui.control.DropDown
        AxissettingsPanel             matlab.ui.container.Panel
        XlabelEditFieldLabel          matlab.ui.control.Label
        XlabelEditField               matlab.ui.control.EditField
        YlabelEditFieldLabel          matlab.ui.control.Label
        YlabelEditField               matlab.ui.control.EditField
        YscaleButtonGroup             matlab.ui.container.ButtonGroup
        AutoButton                    matlab.ui.control.RadioButton
        UniformButton                 matlab.ui.control.RadioButton
        LinearCheckBox                matlab.ui.control.CheckBox
        LogCheckBox                   matlab.ui.control.CheckBox
        FixedButton                   matlab.ui.control.RadioButton
        minEditFieldLabel             matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        maxEditFieldLabel             matlab.ui.control.Label
        maxEditField                  matlab.ui.control.NumericEditField
        ErrorbarstyleButtonGroup      matlab.ui.container.ButtonGroup
        StandarddeviationSDButton     matlab.ui.control.RadioButton
        StandardErrorofthemeanSEMButton  matlab.ui.control.RadioButton
        ConfidenceintervalCIButton    matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        statsObj % StatManager object containing ROI data.
        mainFig % Handle to the main figure.
        summaryFigs % Handle to Summary figures.
        mainAx % Handle to axis in main figure;
        scatData % Structure containing the metaData of each item from the scatter plots in the summary figures.
        mainAxMetaData % MetaData for each scatter point in the main figure;
        dcm_obj % DataCursor mode object for datatips in Main Figure.
        cmp % current mouse position. This property is updated by the WindowButtonMotionFcn of the main fig.
        errMeasure % Measurement of error to be represented in the error bars (i.e. 'SD', 'SEM', '95%CI').
        hlink  % linkprop object handle.
    end
    
    methods (Access = private)
        
        function createSummaryFigs(app)
            % This method creates figures with line + scatter plots. A maximum of 8 plots
            % is allowed per figure.
            
            % Create first figure:
            app.summaryFigs = figure('Name', ['Summary figure #' num2str(1)], 'NumberTitle', 'off',...
                'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing);
            movegui(app.summaryFigs(1), 'southeast');
            cnt = 1; % Axes counter.
            % Create a plot for each observation:
            % Loop over the number of groups:
            gList = unique(app.statsObj.stats_data(:,1));
            colList = cool(numel(gList));
            app.scatData = struct('handles',[],'metaData',{});
            for ii = 1:numel(app.statsObj.obs_list)
                % Create axis:
                s(ii) = subplot(4,2,cnt,"replace",'Parent',app.summaryFigs(end), 'Tag', app.statsObj.obs_list{ii});
                hold(s(ii),"on")
                %                 scatHandles = [];
                %                 metaData_list = struct();
                cnt_scat = 1;
                for j = 1:numel(gList)
                    [acqIndx,data, metaData] = app.getData(gList{j}, app.statsObj.obs_list{ii});
                    if isempty(acqIndx)
                        continue
                    end
                    % Create errorbar plot (default):
                    % Calculate average and STD of data by acqIndx and create errorbar plot:
                    avg = arrayfun(@(x) mean(data(acqIndx == x), 'omitnan'),unique(acqIndx));
                    stDev = arrayfun(@(x) std(data(acqIndx == x),0,"all", 'omitnan'),unique(acqIndx));                   
                    errorbar(s(ii),unique(acqIndx),avg,stDev, 'Tag', ['errG' num2str(j)], 'Color', colList(j,:));
                    
                    % Create scatter plot:
                    scat = scatter(s(ii),acqIndx,data,'filled');
                    set(scat, 'MarkerEdgeColor', colList(j,:), 'MarkerFaceColor', [.7 .7 .7], 'Tag', ['scatG' num2str(j)]);
                    %
                    app.scatData(ii).handles(cnt_scat) = scat;
                    app.scatData(ii).metaData{cnt_scat} = metaData;
                    cnt_scat = cnt_scat + 1;
                end
                                
                title(s(ii),app.statsObj.obs_list{ii}, 'Interpreter',"none");
                hold(s(ii),"off")
                cnt = cnt+1;
                if cnt > 8
                    cnt = 1;
                    if ii<numel(app.statsObj.obs_list)
                        app.summaryFigs = vertcat(app.summaryFigs,...
                            figure('Name', ['Summary figure #' num2str(length(app.summaryFigs)+1)], 'NumberTitle', 'off',...
                            'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing));
                        movegui(app.summaryFigs(end), 'southeast');
                    end
                end
            end
            % Link axes properties and set some of them:
            app.hlink = linkprop(s,{'XLim', 'XTick', 'Color', 'YLimMode', 'YScale'});
            
            s(1).XLim = [s(1).XLim(1)-1 s(1).XLim(2)+1];
            s(1).XTick = unique(acqIndx);
            s(1).Color = [.95 .95 .95];
            
            
            % Show figures:
            arrayfun(@(x) set(x, 'Visible', 'on'), app.summaryFigs);
        end
        
        function [x,y,mD] = getData(app,groupID,obsID)
            % This function retrieves the data and  acquisition index
            % from the "stats_data" property of app.statsObj for all
            % recordings from a given group ("groupID") for a given
            % observation ("obsID").
            % Inputs:
            %   groupID (char): name of experimental group;
            %   obsID (char): name of observation.
            % Outputs:
            %   x (num. array): list of the acquisition indexes for
            %   each data point.
            %   y (num. array): data.
            %   mD(cell array): metaData of "data".
            x=[];y=[];mD=[];
            
            idxG = strcmp(groupID,app.statsObj.stats_data(:,1));
            indxObs = cellfun(@(x) find(strcmp(obsID,x)), app.statsObj.stats_data(idxG,...
                strcmp(app.statsObj.stats_data_headers,'observationID')),'UniformOutput',false);
            if all(cellfun(@isempty, indxObs))
                return
            end
            mD = app.statsObj.stats_data(idxG,ismember(app.statsObj.stats_data_headers,...
                {'groupID', 'SubjectID', 'AcquisitionID', 'ModalityID', 'RecStartDateTime', 'data', 'AcquisitionIndx'}));
            % Drop rows with missing observation:
            b_isEmpty = cellfun(@isempty,indxObs);
            mD(b_isEmpty,:) = [];
            indxObs(b_isEmpty)= [];
            % Get Acquisition index and data point:
            y = single.empty(0,size(mD,1));
            x=y;
            for k = 1:size(mD,1)
                x(k) = mD{k,end};
                y(k) = mD{k,end-1}{indxObs{k}};
            end
            % Drop last column of data:
            mD(:,end-1:end) = [];
            % Sort data and metadata using ascending order of the
            % acquisition indices:
            [x,idx] = sort(x);
            y = y(idx);
            mD = mD(idx,:);
            % Add ObsID to metaData:
            mD = horzcat(mD, repmat({obsID},size(mD,1),1));
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%%%%%
        function DoNothing(~,~,~)
            % EMPTY!%
        end
        
        function txt = showDataTip(app,~,evnt)
            % Callback to show object's information in datatip.
            
            % If the user clicke on a scatter plot dot:
            if isa(evnt.Target, 'matlab.graphics.chart.primitive.Scatter')
                indx = str2double(erase(evnt.Target.Tag, 'scatG'));
                info = app.mainAxMetaData.metaData{indx}(evnt.Position(1),:);
                info = [info(1:end-1), {evnt.Position(2)}];
                %                txt = sprintf(' \\color[rgb]{%0.3f %0.3f %0.3f}\\bf\n',...
                %                    evnt.Target.CData(1), evnt.Target.CData(2), evnt.Target.CData(3));
                %                txt =[txt,sprintf('GroupID: %s\nSubjectID: %s\nAcq.ID: %s\nMod.ID: %s\nRec.DateTime: %s\nValue: %5.3f',...
                %                    info{:})];
                txt = sprintf('Point:\nGroupID: %s\nSubjectID: %s\nAcq.ID: %s\nMod.ID: %s\nRec.DateTime: %s\nValue: %0.3f',...
                    info{:});
            elseif isa(evnt.Target, 'matlab.graphics.chart.primitive.ErrorBar')
                txt = sprintf('Group Line\nX: %d\nY: %0.3f\nError: %0.3f',...
                    evnt.Position(1), evnt.Position(2), evnt.Target.YPositiveDelta(evnt.Position(1)));
            else
                txt = sprintf('Single Line\nX: %d\nY: %0.3f', evnt.Position(1), evnt.Position(2));
            end
            
        end
        function getMousePos(app,~,~)
            % This callback continuously tracks the mouse position inside the main axis.
            cmp = get(app.mainAx, 'CurrentPoint');
            app.cmp = cmp(1,1:2);
        end
        function enterFcn(app,src,evnt)
            % This callback overlays a line plot to connect the scatter plot data from
            %  single subjects.
            
            set(app.mainFig, 'Pointer', 'crosshair')
            %             % Find scatter plot dot that is closest to the pointer:
            %             dat = arrayfun(@(x) sqrt(abs(x.XData - app.cmp(1)).^2 + abs(x.YData - app.cmp(2)).^2), app.mainAxMetaData.handles, 'UniformOutput',false);
            %             for i = 1:numel(dat)
            %                 indx_pt(i) = find(dat{i} == min(dat{i}));
            %                 dist_dat(i) = dat{i}(indx_pt(i));
            %             end
            %             indx_g = find(dist_dat == min(dist_dat));
            %             indx_pt = indx_pt(indx_g);
            %             disp(indx_pt)
            %             % Plot line over scatterplot:
            %             app.mainAxMetaData.handles(indx_g);
            %             idx_subj = strcmp(app.mainAxMetaData.metaData(indx_g).out(:,2), app.mainAxMetaData.metaData(indx_g).out{indx_pt,2});
            %             hold(app.mainAx, 'on')
            %             plot(app.mainAx,app.mainAxMetaData.handles(indx_g).XData(idx_subj),app.mainAxMetaData.handles(indx_g).YData(idx_subj),...
            %                 'k-','LineWidth',2, 'Tag', 'tmpPlot');
            %             hold(app.mainAx, 'off');%
            
        end
        
        function exitFcn(app, src,evnt)
            set(app.mainFig, 'Pointer', 'arrow')
            %             h = findobj(app.mainAx, 'Tag', 'tmpPlot');
            %             delete(h);
        end
        
        
        function clickMainAx(app, src, ~)
            % Custom callback that shows/hides individual line plots when
            % the User clicks over a scatter plot dot.
            
            if strcmp(get(src, 'Pointer'), 'arrow')
                return
            end
            
            click = get(src, 'SelectionType');
            
            if strcmp(click, 'alt')
                h = findobj(app.mainAx, 'Tag', 'tmpPlot');
                delete(h);
                return
            end
            % Find scatter plot dot that is closest to the pointer:
            % First, find closest X position from current click coordinates:
            x_click = round(app.cmp(1));
            if x_click> max(app.mainAxMetaData.handles(1).XData)
                x_click = max(app.mainAxMetaData.handles(1).XData);
            elseif x_click<min(app.mainAxMetaData.handles(1).XData)
                x_click = min(app.mainAxMetaData.handles(1).XData);
            end
            dat = arrayfun(@(x) sqrt(abs(app.cmp(1)-x.XData(x.XData == x_click)).^2 + abs(app.cmp(2)-x.YData(x.XData == x_click)).^2),...
                app.mainAxMetaData.handles, 'UniformOutput',false);
            val = zeros(size(dat));
            indx_pt = val;
            for i = 1:length(dat)
                [val(i),indx_pt(i)] = min(dat{i});
            end
            [~,indx_g] = min(val);
            % Plot line over scatterplot:
            
            idx_subj = strcmp({app.mainAxMetaData.metaData{indx_g}{:,2}}, app.mainAxMetaData.metaData{indx_g}{indx_pt,2});
            hold(app.mainAx, 'on')
            plot(app.mainAx,app.mainAxMetaData.handles(indx_g).XData(idx_subj),app.mainAxMetaData.handles(indx_g).YData(idx_subj),...
                'k-','LineWidth',2, 'Tag', 'tmpPlot');
            hold(app.mainAx, 'off');
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%
        function b_in_bounds = getBounds(app, cp)
            % GETBOUNDS verifies if the current position (cp) of the mouse
            % cursor is inside the boundaries
            % of the main axis.
            
            % Input:
            % cp (1x2 num. array): X,Y mouse cursor position.
            % Output:
            % b_in_bounds (bool) : true, if cursor is inside the image,
            % false, if is not.
            
            % get axis limits:
            x_lims = get(app.mainAx, 'XLim');
            y_lims = get(app.mainAx, 'YLim');
            
            % Check if cursor is inside the axis limits:
            b_in_bounds = (cp(1) >= x_lims(1)) && ...
                (cp(1) <= x_lims(2)) && ...
                (cp(2) >= y_lims(1)) && ...
                (cp(2) <= y_lims(2));
        end
        
        
        function savePlots(app, frmt, path)
            % This function saves or exports individual plots from summary
            % figures.


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            
            %
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'northwest');
            
            app.statsObj = statsObj;
            % Populate dropdown list:
            app.ROIinmainfigureDropDown.Items = app.statsObj.obs_list;
            % Create main figure:
            app.mainFig = figure('Name', ['Main - ' app.ROIinmainfigureDropDown.Value], 'NumberTitle','off',...
                'Position', [0 0 880 420],'Visible', 'off', 'WindowButtonDownFcn', @app.clickMainAx,...
                'WindowButtonMotionFcn',@app.getMousePos, 'CloseRequestFcn', @app.DoNothing);
            app.mainAx = axes('Parent', app.mainFig, 'Position',[.1 .12 .8 .8], 'Units',"normalized");
            % Create summary figures:
            app.createSummaryFigs;
            % Show Figures and app:
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            % Set some axis properties of mainAx:
            app.mainAx.Title.Interpreter = 'none';
            app.mainAx.XLabel.String = 'Acquisition index';
            app.mainAx.YLabel.String = 'Value';
            %
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
            % Add mainAx to hlink object:
            app.hlink.addtarget(app.mainAx)
            %
            errBarName = regexp(app.ErrorbarstyleButtonGroup.SelectedObject.Text, '(?<=\()\w*\)', 'match', 'once');
            gNames = cellfun(@(x) [x{1,1} ' (\pm' errBarName], app.scatData(1).metaData, 'UniformOutput', false);
            legend(app.mainAx, app.scatData(1).handles,gNames,'AutoUpdate',"off", 'Location',"bestoutside");
            % Set Data cursor info:
            app.dcm_obj = datacursormode(app.mainFig);
            set(app.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showDataTip, 'Interpreter', 'none');
            % Check for Statistics toolbox:
            a = ver;
            if any(strcmp('Statistics and Machine Learning Toolbox', {a.Name}))
                app.ConfidenceintervalCIButton.Enable = 'on';
            end
            % Make it visible:
            app.mainFig.Visible = 'on';
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.mainFig)
            delete(app.summaryFigs)
            delete(app)
        end

        % Value changed function: ROIinmainfigureDropDown
        function ShowPlotInMainFig(app, event)
            % This callback makes a copy of selected ROI plot from the
            % summaryFigs into the main fig axis
            idx_plot = strcmp(app.ROIinmainfigureDropDown.Value, arrayfun(@(x) x.metaData{1}{1,end},app.scatData,'UniformOutput',false));
            cla(app.mainAx);
            copyobj(app.scatData(idx_plot).handles(1).Parent.Children,app.mainAx);

            app.mainAx.Title.String = app.scatData(idx_plot).metaData{1}{1,end};
            app.mainAxMetaData = app.scatData(idx_plot);
            app.mainAx.YLim = app.scatData(idx_plot).handles(1).Parent.YLim;
            % Add PointerBehaviour callbacks to main axis:
            pointerBehaviour.enterFcn = @app.enterFcn;
            pointerBehaviour.traverseFcn = [];
            pointerBehaviour.exitFcn = @app.exitFcn;
            iptSetPointerBehavior(app.mainAx, pointerBehaviour);
            iptPointerManager(app.mainFig, 'enable');
            % Update legend:
            errBarName = regexp(app.ErrorbarstyleButtonGroup.SelectedObject.Text, '(?<=\()\w*\)', 'match', 'once');            
            gNames = cellfun(@(x) [x{1,1} ' (\pm' errBarName], app.scatData(idx_plot).metaData, 'UniformOutput', false);
            legend(app.mainAx, app.scatData(idx_plot).handles,gNames,'AutoUpdate',"off", 'Location',"bestoutside")
        end

        % Selection changed function: ErrorbarstyleButtonGroup
        function ErrorbarstyleButtonGroupSelectionChanged(app, event)
            % This callback updates the error bars of all plots as well as
            % the legend in the main figure for the type of error measure
            % selected in the GUI.
            
            indx = find(arrayfun(@(x) isequaln(app.ErrorbarstyleButtonGroup.SelectedObject, x), app.ErrorbarstyleButtonGroup.Buttons));
            for i = 1:length(app.scatData)
                for j = 1:length(app.scatData(i).handles)
                    h = findobj(app.scatData(i).handles(1).Parent, 'Tag',strrep(app.scatData(i).handles(j).Tag, 'scat', 'err'));
                    stDev = arrayfun(@(x) std(app.scatData(i).handles(j).YData(app.scatData(i).handles(j).XData == x),0,"all", 'omitnan'),...
                        unique(app.scatData(i).handles(j).XData));                    
                    switch indx
                        case 1
                            % SD
                            set(h, 'YPositiveDelta', stDev, 'YNegativeDelta', stDev);
                            
                        case 2
                            %SEM
                            set(h, 'YPositiveDelta', stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1)),...
                                'YNegativeDelta', stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1)));
                        case 3
                            %95%CI
                            set(h, 'YPositiveDelta',  (tinv(1-0.025,sum(app.scatData(i).handles(j).XData == 1)-1)).*(stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1))),...
                                'YNegativeDelta',  (tinv(1-0.025,sum(app.scatData(i).handles(j).XData == 1)-1)).*(stDev./sqrt(sum(app.scatData(i).handles(j).XData == 1))));
                    end
                end
               
            end
            
            % Update Main figure as well:
            app.ShowPlotInMainFig;
        
        end

        % Value changed function: XlabelEditField
        function XlabelEditFieldValueChanged(app, event)
          
            app.mainAx.XLabel.String = app.XlabelEditField.Value;
        end

        % Value changed function: YlabelEditField
        function YlabelEditFieldValueChanged(app, event)
            
            app.mainAx.YLabel.String = app.YlabelEditField.Value;           
        end

        % Selection changed function: YscaleButtonGroup
        function YscaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.YscaleButtonGroup.SelectedObject;
            indx = find(arrayfun(@(x) isequaln(x,selectedButton), app.YscaleButtonGroup.Buttons));
            app.minEditField.Enable = 'off';
            app.maxEditField.Enable = 'off';
            switch indx
                case 1
                    app.hlink.removeprop('YLim');
                    set(app.mainAx, 'YLimMode','auto')
                case 2
                    set(app.mainAx, 'YLimMode','auto')
                    y_lims = arrayfun(@(x) x.handles(1).Parent.YLim, app.scatData, 'UniformOutput',false); y_lims = y_lims';
                    y_lims = vertcat(y_lims{:});
                    y_lims = [min(y_lims(:,1)), max(y_lims(:,2))];
                    app.hlink.addprop('YLim');
                    set(app.mainAx, 'YLim',y_lims,'YLimMode','manual');
                case 3
                    app.minEditField.Enable = 'on';
                    app.maxEditField.Enable = 'on';
            end
            % Set min and max YLims values from main ax:
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
        end

        % Value changed function: maxEditField, minEditField
        function min_max_EditFieldValueChanged(app, event)
            app.minEditField.Value;
            app.hlink.addprop('YLim');
            set(app.mainAx, 'YLim', [app.minEditField.Value, app.maxEditField.Value], 'YLimMode','manual');
        end

        % Value changed function: LinearCheckBox
        function LinearCheckBoxValueChanged(app, event)
            if app.LinearCheckBox.Value
                app.LogCheckBox.Value = false;
                set(app.mainAx, 'YScale', 'linear')
            else 
                app.LinearCheckBox.Value = true;
            end
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            if app.LogCheckBox.Value
                app.LinearCheckBox.Value = false;
                set(app.mainAx, 'YScale', 'log')
            else 
                app.LogCheckBox.Value = true;
            end
        end

        % Menu selected function: ExportfiguresMenu, SavefiguresMenu
        function Save_Export_Figs(app, event)
            % This callback either saves as .fig or exports individual plots as
            % bitmap/vector images.
            
            % Create format if the User chose to save as .fig:
            if startsWith(event.Source.Text, 'save', "IgnoreCase",true)               
                [file,path] = uiputfile('*.fig', 'Save plots as...', 'LongDatPlots');
            else
                fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)';...
                '*.pdf', 'Portable Document Format  (*.pdf)';...
                '*.eps', 'Encapsulated PostScript  (*.eps)'};
                [file, path, indx] = uiputfile(fig_filter, 'Export Image(s)', 'LongDatPlots.png');
                print_dict = {'-dpng', '-djpeg', '-dpdf', '-depsc'};
            end
            if file == 0
              disp('Operation cancelled by User')
              return
            end
                        
            [~,prefix,fmt] = fileparts(file);
            errBarName = regexp(app.ErrorbarstyleButtonGroup.SelectedObject.Text, '(?<=\()\w*\)', 'match', 'once');            
            f = figure('Position',[0 0 880 420], 'Visible', 'off');
            movegui(f, 'center');f.Visible = 'on';
            h = waitbar(0,'Saving figures...'); movegui(h,'northeast');
            for i = 1:length(app.scatData)                
                ax = axes(f, 'Units',"normalized" , 'Position',[.1 .12 .8 .8]);
                copyobj(app.scatData(i).handles(1).Parent.Children, ax)
                set(ax, 'Color', app.scatData(i).handles(1).Parent.Color, 'XLim', app.scatData(i).handles(1).Parent.XLim, ...
                    'YLim', app.scatData(i).handles(1).Parent.YLim, 'YScale', app.scatData(i).handles(1).Parent.YScale);
                ax.XLabel.String = app.mainAx.XLabel.String;
                ax.YLabel.String = app.mainAx.YLabel.String;                
                gNames = cellfun(@(x) [x{1,1} ' (\pm' errBarName], app.scatData(i).metaData, 'UniformOutput', false);
                legend(ax, app.scatData(i).handles,gNames,'AutoUpdate',"off", 'Location',"best");
                title(ax, app.scatData(i).metaData{1}{end,end}, 'Interpreter', 'none')
                if startsWith(event.Source.Text, 'save', "IgnoreCase",true)                    
                    savefig(f, fullfile(path,[prefix '_' app.scatData(i).metaData{1}{end,end} fmt]))
                else
                    if strcmp(fmt, '.pdf')
                        print([prefix '_' app.scatData(i).metaData{1}{end,end} fmt] , f, print_dict{indx}, '-painters','-r300', '-bestfit');
                    else
                        print([prefix '_' app.scatData(i).metaData{1}{end,end} fmt] , f, print_dict{indx}, '-painters','-r300');
                    end
                end
                clf(f);                 
                waitbar(i/length(app.scatData), h);
            end
            close(h)
            close(f)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Longitudinal data Plotter';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveExportMenu = uimenu(app.UIFigure);
            app.SaveExportMenu.Text = 'Save/Export';
            app.SavefiguresMenu = uimenu(app.SaveExportMenu);
            app.SavefiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.SavefiguresMenu.Text = 'Save figures';
            app.ExportfiguresMenu = uimenu(app.SaveExportMenu);
            app.ExportfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.ExportfiguresMenu.Text = 'Export figures';
            app.ChangeplottypeMenu = uimenu(app.UIFigure);
            app.ChangeplottypeMenu.Text = 'Change plot type';
            app.LinePlotMenu = uimenu(app.ChangeplottypeMenu);
            app.LinePlotMenu.Text = 'Line Plot';
            app.BoxPlotMenu = uimenu(app.ChangeplottypeMenu);
            app.BoxPlotMenu.Enable = 'off';
            app.BoxPlotMenu.Text = 'Box Plot';
            app.ROIinmainfigureDropDownLabel = uilabel(app.UIFigure);
            app.ROIinmainfigureDropDownLabel.Text = 'ROI in main figure';
            app.ROIinmainfigureDropDown = uidropdown(app.UIFigure);
            app.ROIinmainfigureDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowPlotInMainFig, true);
            app.AxissettingsPanel = uipanel(app.UIFigure);
            app.AxissettingsPanel.Title = 'Axis settings';
            app.XlabelEditFieldLabel = uilabel(app.AxissettingsPanel);
            app.XlabelEditFieldLabel.Text = 'X label';
            app.XlabelEditField = uieditfield(app.AxissettingsPanel, 'text');
            app.XlabelEditField.ValueChangedFcn = createCallbackFcn(app, @XlabelEditFieldValueChanged, true);
            app.XlabelEditField.Value = 'Acquisition index';
            app.YlabelEditFieldLabel = uilabel(app.AxissettingsPanel);
            app.YlabelEditFieldLabel.Text = 'Y label';
            app.YlabelEditField = uieditfield(app.AxissettingsPanel, 'text');
            app.YlabelEditField.ValueChangedFcn = createCallbackFcn(app, @YlabelEditFieldValueChanged, true);
            app.YlabelEditField.Value = 'Value';
            app.YscaleButtonGroup = uibuttongroup(app.AxissettingsPanel);
            app.YscaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YscaleButtonGroupSelectionChanged, true);
            app.YscaleButtonGroup.Title = 'Y scale';
            app.AutoButton = uiradiobutton(app.YscaleButtonGroup);
            app.AutoButton.Tooltip = {'Sets the best Y limits for each plot independently.'};
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.UniformButton = uiradiobutton(app.YscaleButtonGroup);
            app.UniformButton.Tooltip = {'Sets all Y limits for all plots using the largest range found across plots.'};
            app.UniformButton.Text = 'Uniform';
            app.LinearCheckBox = uicheckbox(app.YscaleButtonGroup);
            app.LinearCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearCheckBoxValueChanged, true);
            app.LinearCheckBox.Text = 'Linear';
            app.LinearCheckBox.Value = true;
            app.LogCheckBox = uicheckbox(app.YscaleButtonGroup);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.FixedButton = uiradiobutton(app.YscaleButtonGroup);
            app.FixedButton.Tooltip = {'Set Y limits manually.'};
            app.FixedButton.Text = 'Fixed';
            app.minEditFieldLabel = uilabel(app.YscaleButtonGroup);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.YscaleButtonGroup, 'numeric');
            app.minEditField.ValueDisplayFormat = '%11.3f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @min_max_EditFieldValueChanged, true);
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.YscaleButtonGroup);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.YscaleButtonGroup, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%11.3f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @min_max_EditFieldValueChanged, true);
            app.maxEditField.Enable = 'off';
            app.ErrorbarstyleButtonGroup = uibuttongroup(app.AxissettingsPanel);
            app.ErrorbarstyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ErrorbarstyleButtonGroupSelectionChanged, true);
            app.ErrorbarstyleButtonGroup.Title = 'Error bar style';
            app.StandarddeviationSDButton = uiradiobutton(app.ErrorbarstyleButtonGroup);
            app.StandarddeviationSDButton.Text = 'Standard deviation (SD)';
            app.StandarddeviationSDButton.Value = true;
            app.StandardErrorofthemeanSEMButton = uiradiobutton(app.ErrorbarstyleButtonGroup);
            app.StandardErrorofthemeanSEMButton.Text = 'Standard Error of the mean (SEM)';
            app.ConfidenceintervalCIButton = uiradiobutton(app.ErrorbarstyleButtonGroup);
            app.ConfidenceintervalCIButton.Enable = 'off';
            app.ConfidenceintervalCIButton.Tooltip = {'available with Statistics and Machine Learning Toolbox.'};
            app.ConfidenceintervalCIButton.Text = '95% Confidence interval (CI)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotLongData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end