classdef umIToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjectMenu                    matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        UtilitiesMenu                  matlab.ui.container.Menu
        ProjectMenu_2                  matlab.ui.container.Menu
        LookfornewdataMenu             matlab.ui.container.Menu
        UpdateprojectMenu              matlab.ui.container.Menu
        ViewLogBookMenu                matlab.ui.container.Menu
        ResetLogBookMenu_2             matlab.ui.container.Menu
        RestoregarbageListMenu         matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        DashboardTab                   matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        ProjectStatusPanel             matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        TableTitle                     matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        PropertiesofselecteditemLabel  matlab.ui.control.Label
        ListoffilesListBoxLabel        matlab.ui.control.Label
        ListoffilesListBox             matlab.ui.control.ListBox
        DeleteselectedfilesButton      matlab.ui.control.Button
        PipelinecontrolpanelTab        matlab.ui.container.Tab
        UITable_2                      matlab.ui.control.Table
        PipelineSummaryLabel           matlab.ui.control.Label
        PipelinecontrolpanelPanel      matlab.ui.container.Panel
        LaunchPipelineConfigButton     matlab.ui.control.Button
        RunPipelineButton              matlab.ui.control.Button
        IgnoreLoggedTasksCheckBox      matlab.ui.control.CheckBox
        SaveDatabeforeerrorCheckBox    matlab.ui.control.CheckBox
        PipelinestatusPanel            matlab.ui.container.Panel
        TextArea_2                     matlab.ui.control.TextArea
        VisualizationTab               matlab.ui.container.Tab
        Tree3                          matlab.ui.container.Tree
        SearchtoolsPanel_2             matlab.ui.container.Panel
        AdvancedSearchButton_2         matlab.ui.control.Button
        ResetSearchButton_2            matlab.ui.control.Button
        SelectanItemLabel              matlab.ui.control.Label
        AppsPanel                      matlab.ui.container.Panel
        RetinotopyButton               matlab.ui.control.Button
        SeedPixelCorrelationButton     matlab.ui.control.Button
        FunctionalmapsvisualizationLabel  matlab.ui.control.Label
        ROImanagerButton               matlab.ui.control.Button
        ManualImageregistrationButton  matlab.ui.control.Button
        ImageRegistrationtoolsLabel    matlab.ui.control.Label
        DataViewerButton               matlab.ui.control.Button
        CustomAppsVannisLabLabel       matlab.ui.control.Label
        SelectafileLabel               matlab.ui.control.Label
        SelectafileListBox             matlab.ui.control.ListBox
        AnalysisTab                    matlab.ui.container.Tab
        Tree4                          matlab.ui.container.Tree
        SelectItemsLabel               matlab.ui.control.Label
        SelectObsListBox_analysis      matlab.ui.control.ListBox
        SelectButton_analysis          matlab.ui.control.StateButton
        SelectfileListBoxLabel         matlab.ui.control.Label
        SelectFileListBox_analysis     matlab.ui.control.ListBox
        GroupingtoolPanel              matlab.ui.container.Panel
        CreateEditButton               matlab.ui.control.Button
        SavetofileButton               matlab.ui.control.Button
        OpenBtn_analysis               matlab.ui.control.Button
        SelectobservationsLabel        matlab.ui.control.Label
        ToolsPanel                     matlab.ui.container.Panel
        ExporttoCSVButton              matlab.ui.control.Button
        UtilitiesLabel                 matlab.ui.control.Label
        TableViewButton                matlab.ui.control.Button
        SavetoMatButton                matlab.ui.control.Button
        GenAverageRSimagesButton       matlab.ui.control.Button
        CustomFunctionsVannisLabLabel  matlab.ui.control.Label
        PlotCorrelationMatrixButton    matlab.ui.control.Button
        PlottingLabel                  matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        SearchtoolsPanel_3             matlab.ui.container.Panel
        AdvancedSearchButton_3         matlab.ui.control.Button
        ResetSearchButton_3            matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        SelectionLabel                 matlab.ui.control.Label
        EditButton                     matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        UndoSelectionButton            matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        ProtocolFile % Protocol File Path
        SearchBuilderApp; % Property containing SearchBuilder app.
        PipelineConfigApp % Property containing PipelineConfig app.
        FilterStructFromSearchBuilder % Saves the filtered structure from SearchBuilder app.
        currentTree % Tree from current tab
        selectedObjs % selected object in Tree3 (see function DISPLAYNODEFILES).
        matFileList % Used in Analysis tab. List of .MAT files from selected nodes and children.
        nodeList % Used in Analysis tab. List of selected Nodes (+ children)
        nodeObjList %Used in Analysis tab. List of objects associated with nodeList.
    end
    
    properties (Access = public)
        ProtocolObj % PROTOCOL Object
        PipelineObj % PIPELINEMANAGER Object
        StatsObj % StatsManager Object
        groupInfo = struct('gType', 'descriptive stats', 'nGroups', 1, 'gIdxList',...
            struct('Name', 'Base', 'idxList',[])) % Structure containing group info created by "data_grouping_tool.mlapp".
    end
    
    methods (Access = private)
        
        function check4newData(app)
            % Checks the Raw folder of PROTOCOL for new/suppressed Subjects
            % and Acquisitions.
            app.Label.Text = 'Checking for new Data in Raw Directory...'; drawnow
            pause(.1);
            newprotocol = app.ProtocolObj.ProtoFunc(app.ProtocolObj);
            newprotocol = app.ProtocolObj.removeGarbage(newprotocol);
            iNewSubj = ~ismember({newprotocol.ID}, {app.ProtocolObj.Array.ObjList.ID}); % New subjects in the newprotocol.
            iMissSubj = ~ismember({app.ProtocolObj.Array.ObjList.ID},{newprotocol.ID});% Subjects from original list that are no longer in the newprotocol.
            indNwArr = find(~iNewSubj);
            indprotocol = arrayfun(@(a) find(strcmp({newprotocol(indNwArr).ID}, a)),...
                {app.ProtocolObj.Array.ObjList.ID}, 'UniformOutput', false); indprotocol = [indprotocol{:}];
            iNewAcq = arrayfun(@(a,b) ~ismember({a.Array.ObjList.ID}, {b.Array.ObjList.ID}),...
                newprotocol(indprotocol), app.ProtocolObj.Array.ObjList(indNwArr), 'UniformOutput', false);
            iNewAcq = [iNewAcq{:}];
            iMissAcq = arrayfun(@(a,b) ~ismember({b.Array.ObjList.ID}, {a.Array.ObjList.ID}),...
                newprotocol(indprotocol), app.ProtocolObj.Array.ObjList(indNwArr), 'UniformOutput', false);
            iMissAcq = [iMissAcq{:}];
            if any([iNewSubj iMissSubj iNewAcq iMissAcq])
                ns = num2str(sum(iNewSubj));
                ms = num2str(sum(iMissSubj));
                na = num2str(sum(iNewAcq));
                ma = num2str(sum(iMissAcq));
                app.Label.Text = 'Changes detected in raw folder:';
                app.Label_2.Text = sprintf('%s \n%s \n%s \n%s \n%s',['New Subjects: ' ns], ['Missing Subjects: ' ms], ...
                    ['New Acquisitions: ' na], ['Missing Acquisitions: ' ma], 'Click on ''Project''>> ''Update Project''to apply changes!'); drawnow
            else
                app.Label.Text = 'All Raw Files read.';
                app.Label_2.Text = ''; drawnow
            end
            
        end
        
        function makeUItreeNodes(app, Tree)
            % MAKEUITREENODES creates UITree nodes of Protocol hierarchy.
            % Input:
            % Tree = UITREE object handle
            
            app.Label.Text = 'Please wait...'; drawnow
            Tree.Enable = 'off';
            delete(Tree.Children)
            if isempty(app.ProtocolObj.Array.ObjList)
                uialert(app.UIFigure, 'Your project file seems to have Zero elements.', 'Query Filter Result', "Icon","warning", 'Modal',true)
                return
            end
            root = uitreenode(Tree, 'Text', app.ProtocolObj.Name,'Icon', 'icon_WinFolder.png');
            subjList =app.ProtocolObj.Array.listProp('ID');
            for i = 1:numel(subjList)
                uitreenode(root, 'Text', subjList{i}, 'Icon', 'icon_WinFolder.png');
            end
            expand(Tree.Children.Children);
            Tree.Enable = 'on';
            app.Label.Text = 'Tree created'; drawnow
        end
        
        function displayNodeInfo(app, NodeObj)
            % DISPLAYNODEINFO displays the NODEOBJ's properties in
            % APP.TEXTAREA.
            % Input:
            % NodeObj = object handle associated with a selected node from
            % an UITREE.
            
            eval(['mc = ?' class(NodeObj) ';'])
            b_isPublic = cellfun(@(x) any(strcmp(x, 'public')), {mc.PropertyList.GetAccess});
            propList = {mc.PropertyList(b_isPublic).Name};
            b_showable= cellfun(@(x) (isnumeric(NodeObj.(x)) || ischar(NodeObj.(x))), propList);
            propList = propList(b_showable);
            propVals  = cellfun(@(x) num2str(NodeObj.(x)), propList, 'UniformOutput',false);
            classInfo = {'Type', class(NodeObj)};
            NodeObjProps = horzcat(propList', propVals');
            NodeObjProps = vertcat(classInfo, NodeObjProps);
            % Temporary solution for displaying Function Handle...
            if isa(NodeObj,'Protocol')
                NodeObjProps{4,2} = char(NodeObjProps{4,2});
            end
            %%%
            Data = [];
            % The if statement here controls for known property data types.
            % Has to be improved to be more flexible.
            for i = 1:size(NodeObjProps,1)
                tag = string([NodeObjProps{i,1} ':']);
                value = string(['    ' NodeObjProps{i,2}]);
                Data = vertcat(Data, tag, value);
            end
            
            app.TextArea.Value = Data;
            app.TextArea.Editable = "off";
            
            if isempty(NodeObj.LastLog)
                tableData = table(0,0,0,0,0);
            else
                tableData = NodeObj.LastLog;
            end
            app.UITable.Data = tableData;
            app.UITable.ColumnName = tableData.Properties.VariableNames;
            app.UITable.ColumnEditable = false;
            app.TableTitle.Text = 'Last operations in selected Item:';
        end
        
        function checkTree(app, Tree)
            % CHECKTREE verifies if the hierarchical tree has all
            % necessary elements for analysis. This means that at least one
            % Subject has one Acquisition that itself has one modality.
            % Input:
            % Tree = UITREE object handle.
            
            % Change icons of subjects with empty Acquisitions to ERROR:
            idxS = arrayfun(@(x) isempty(x.Array.ObjList), app.ProtocolObj.Array.ObjList); % Empty Subject
            indxS = find(idxS);
            % Look into the other Subjects for acquisitions with empty
            % modalities and change Icons to ERROR:
            otherS = find(~idxS);
            AcqIndxList = [];
            a = 1;
            for i = 1:length(otherS)
                indxA = find(arrayfun(@(x) isempty(x.Array.ObjList), app.ProtocolObj.Array.ObjList(otherS(i)).Array.ObjList));
                for j = 1:length(indxA)
                    AcqIndxList(a,:) = [otherS(i) indxA(j)];
                    a = a+1;
                end
            end
            strS = []; strA = [];
            faultyS_ID = '';
            faultyA_ID = '';
            if ~isempty(indxS)
                strS = 'Subject(s): ';
                faultyS_ID = arrayfun(@(x) app.ProtocolObj.Array.ObjList(x).ID, indxS, 'UniformOutput', false);
                for i = 1:length(faultyS_ID)
                    strS = {strS;faultyS_ID{i}};
                end
            elseif~isempty(AcqIndxList)
                strA = 'Acquisition(s):';
                for i = 1:size(AcqIndxList,1)
                    faultyA_ID {i,1} = app.ProtocolObj.Array.ObjList(AcqIndxList(i,1)).ID;
                    faultyA_ID {i,2} = app.ProtocolObj.Array.ObjList(AcqIndxList(i,1)).Array.ObjList(AcqIndxList(i,2)).ID;
                    strA = {strA; strjoin(faultyA_ID(i,:), '_')};
                end
            else
                return
            end
            str = vertcat(strS,strA);
            msg = [{'The following items are empty: '}, str(:)',{' Remove faulty items?'}];
            answer = uiconfirm(app.UIFigure, msg, 'Warning!', 'Options', {'Remove Items', 'Continue anyway'});
            switch answer
                case 'Remove Items'
                    for i = 1:numel(faultyS_ID)
                        idxS = app.ProtocolObj.Array.findElement('ID', faultyS_ID{i}, 'strcmp');
                        app.ProtocolObj.manualRemoveObj(idxS);
                    end
                    for i = 1:size(faultyA_ID,1)
                        idxS = app.ProtocolObj.Array.findElement('ID', faultyA_ID{i,1}, 'strcmp');
                        idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', faultyA_ID{i,2}, 'strcmp');
                        app.ProtocolObj.manualRemoveObj(idxS, idxA);
                    end
                    app.Label.Text = 'Updating list...';
                    app.ProtocolObj.updateList;
                    app.Label.Text = 'Recreating tree...';
                    app.makeUItreeNodes(Tree);
                case 'Continue anyway'
                    uialert(app.UIFigure, 'Attention! This may cause problems later during analysis!', 'Warning!', "Modal",true, "Icon","warning");
            end
            
        end
        
        function expandNode(app, node)
            % EXPANDNODE creates Children nodes of "node" as Parent.
            % This function allows a lazy-load of node Children to improve
            % the speed of the GUI.
            % Input:
            % node = handle to the selected UITREENODE.
            
            
            ID = node.Text;
            b_top = false; a = 0;
            str = 'typeClass = class(node.';
            str2 = 'Parent';
            while ~b_top
                eval([str str2 ');']);
                lvl = a;
                if strcmp(typeClass,'matlab.ui.container.Tree')
                    b_top= true;
                end
                str = [str str2 '.'];
                a = a+1;
            end
            switch lvl
                case 0
                    nodeObj = app.ProtocolObj;
                case 1
                    idx = app.ProtocolObj.Array.findElement('ID', ID, 'strcmp');
                    nodeObj = app.ProtocolObj.Array.ObjList(idx);
                case 2
                    idxS = app.ProtocolObj.Array.findElement('ID', node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', ID, 'strcmp');
                    nodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idx);
                case 3
                    idxS = app.ProtocolObj.Array.findElement('ID', node.Parent.Parent.Text, 'strcmp');
                    idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID',node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', ID, 'strcmp');
                    nodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.ObjList(idx);
            end
            %             if lvl == 0
            %                 return
            %             end
            b_hasObj = isprop(nodeObj, 'Array');
            
            if b_hasObj
                my_children = nodeObj.Array.listProp('ID');
                try
                    childNames = {node.Children.Text};
                catch
                    childNames = '';
                end
                if isempty(childNames)
                    for i = 1:length(my_children)
                        uitreenode(node, 'Text', my_children{i}, 'Icon','icon_WinFolder.png');
                        app.expandNode(node.Children(i))
                    end
                end
            end
        end
        
        function [NodeObj, lvl] = getNodeObj(app,Node)
            % GETNODEOBJ retrieves the object associated with the selected node
            % in a UITree.
            % Input:
            % node = handle to the selected UITREENODE.
            % Outputs:
            % NodeObj = handle of the Protocol object associated with the
            % UITREE node.
            % lvl = level of the node (0 = protocol; 1 = subject; 2 =
            % acquisition; 3 = modality).
            
            ID = Node.Text;
            b_top = false; a = 0;
            str = 'typeClass = class(Node.';
            str2 = 'Parent';
            while ~b_top
                eval([str str2 ');']);
                lvl = a;
                if strcmp(typeClass,'matlab.ui.container.Tree')
                    b_top= true;
                end
                str = [str str2 '.'];
                a = a+1;
            end
            switch lvl
                case 0
                    NodeObj = app.ProtocolObj;
                case 1
                    idx = app.ProtocolObj.Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idx);
                case 2
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idx);
                case 3
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Parent.Text, 'strcmp');
                    idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.ObjList(idx);
            end
        end
        
        function displayNodeFiles(app, myTree, myList)
            % DISPLAYNODEFILES shows the files listed in the Save Folder of
            % selected nodes.
            % Inputs:
            %   myTree : handle of a UITREE
            %   myList : content of a LISTBOX object.
            myList.Items = {'Loading...'}; drawnow
            selectedNodes = myTree.SelectedNodes;
            
            app.selectedObjs = arrayfun(@(x) app.getNodeObj(x), selectedNodes, 'UniformOutput', false);
            filelist = {};
            for i = 1:numel(app.selectedObjs)
                filelist = [filelist ; app.getFileList(app.selectedObjs{i}, 'all')];
            end
            
            if isempty(filelist)
                filelist = {'No files found!'};
            end
            myList.Items = unique(filelist);
            
            
        end
        
        function out = getFileList(~,obj, tag)
            % This function reads the files contained in the SaveFolder of the selected object.
            % and retrieves a cell array with the names of all valid files listed.
            % Input:
            %   obj : object from Protocol (Subject/Acquisition/Modality)
            %   tag (char): string to indicate which extension {'.dat',
            %   '.mat', 'all'};
            % Output:
            %   out (cell): cell array containing the names of valid files
            %   inside object's SaveFolder.
            
            % List all .DAT and .MAT files in the object's save folder:
            datList = dir(fullfile(obj.SaveFolder, '*.dat'));
            matList = dir(fullfile(obj.SaveFolder, '*.mat'));
            % Here, we consider that all .DAT files are valid, however we
            % can find .mat files that were not created in the pipeline. So
            % we will filter them based on the existence of the variable
            % "dataHistory" inside .MAT files:
            
            % Exclude .MAT files containing metaData of .DAT files and files that do NOT contain "dataHistory" variable:
            [~,datNames,~] = cellfun(@(x) fileparts(x), {datList.name}, 'UniformOutput',false);
            [~,matNames,~] = cellfun(@(x) fileparts(x), {matList.name}, 'UniformOutput',false);
            idx_dat = ismember(matNames, datNames);
            idx_valid = arrayfun(@(x) ismember('dataHistory',properties(matfile(fullfile(x.folder, x.name)))), matList);
            matList = matList(~idx_dat(:) & idx_valid(:));
            
            switch tag
                case '.dat'
                    FilesList = datList;
                case '.mat'
                    FilesList = datList;
                case 'all'
                    FilesList = vertcat(datList, matList);
                otherwise
                    error('Wrong flag name!')
            end
            
            if isempty(FilesList)
                out = {''};
            else
                out = {FilesList.name};
            end
        end
        function checkAndLoad_protocol(app)
            % CHECKANDLOAD_PROTOCOL loads the "protocol" object and verifies if it is
            % valid.
            % Disable warning while loading Protocol object. Sometimes the
            % loadobj method issues warnings about empty properties.
            % However, this does not affect the loading of the protocol
            % object to the workspace.
            warning('off','all')
            a = load(app.ProtocolFile);
            warning('on','all')
            if ~isfield(a, 'protocol')
                uialert(app.UIFigure, 'Wrong File!', 'Error!', "Icon","error", "Modal",true);
                app.ProtocolFile = [];
                app.Label.Text = '';
                app.Label_2.Text = '';
                return
            end
            protocol= a.protocol;
            %             addpath(genpath(protocol.MainDir));
            %             addpath(genpath(protocol.SaveDir));
            if isa(protocol, 'Protocol') & ~isempty(protocol.Name)
                app.ProtocolObj = protocol;
                app.Label.Text = sprintf('%s', 'Project file loaded!'); drawnow
                app.Label_2.Text = sprintf('%s', ['Raw Dir:' protocol.MainDir]);
                % Make Tree:
                app.makeUItreeNodes(app.Tree);                
                % Check data integrity:
                app.checkTree(app.Tree);
                app.Label.Text = 'All set!'; drawnow
            elseif ~isa(protocol, 'Protocol')
                app.Label.Text = sprintf('%s\n%s', ['The file ' filename ' does not contain a valid Project.'], 'Try again!');
            else
                app.Label.Text = sprintf('%s\n%s', 'Failed to load project File!', 'Check Matlab''s command Window!');
            end
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% STATS FUNCTIONS    %%%%%%%%%%%%%%%%%%%%%%%%%%%
        function fillTree4(app)
            % Function to Create nodes in Analysis Tab.
            % In contrast with the other trees, this function draws all
            % nodes. This may be time consuming for large amounts of data.
            % This function creates Subject and Acquisition nodes. Modality
            % nodes are not drawn.
            app.Tree4.Enable = 'off';
            delete(app.Tree4.Children)
            %%% Allow the creation of several roots!
            s = app.groupInfo.gIdxList;
            nRoots = length(s);
            for ii = 1:nRoots
                idxList = s(ii).idxList;
                root = uitreenode(app.Tree4, 'Text', s(ii).Name,'Icon', 'icon_WinFolder.png');
                subjIdx = unique(idxList(:,1));
                for i = 1:numel(subjIdx)
                    ID = app.ProtocolObj.Array.ObjList(subjIdx(i)).ID;
                    subjNode = uitreenode(root, 'Text', ID, 'Icon', 'icon_WinFolder.png');
                    idx = idxList(:,1) == subjIdx(i);
                    acqIdx = unique(idxList(idx,2), 'rows');
                    for j = 1:size(acqIdx,1)
                        acqID = app.ProtocolObj.Array.ObjList(subjIdx(i)).Array.ObjList(acqIdx(j)).ID;
                        uitreenode(subjNode, 'Text', acqID, 'Icon', 'icon_WinFolder.png');
                    end
                end
            end
            %             expand(app.Tree, 'all');
            app.Tree4.Enable = 'on';
            app.Tree4.Multiselect = 'on';
        end
        
        function [matList, nodeList, nodeObjList] = findStatsFiles(app)
            % FINDSTATSFILES looks for stats-ready .MAT files listed in the
            % FilePtr.json files from objects selected in app.Tree.
            % In addition to looking inside FilePtrs of selected objects, this function will look into
            % FilePtrs from all modalities inside Acquisition objects.
            % Output:
            % matList: A list of full paths of .MAT files that are common to
            % selected objects (grouped by hierarchy).
            % nodeList : Cell array of UITree node handles associated with each
            % .Mat file listed in MATLIST.
            % nodeObjList: Cell array of objects associated with nodeList
            % elements.
            
            nodeList = {};
            matList = {};
            nodeObjList = {};
            nodes = app.Tree4.SelectedNodes;
            % First, get a list of all File Pointers from Selected Nodes:
            for i = 1:numel(nodes)
                node = nodes(i);
                nodeObj = app.getNodeObj(node);
                list = getFileList(nodeObj, '.mat');
                if ~isempty(list)
                    nodeObjList = [nodeObjList, repmat({nodeObj}, 1,length(list))];
                    nodeList = [nodeList, repmat({node}, 1,length(list))];
                    matList = [matList, list];
                end
                if isa(nodeObj, 'Acquisition')
                    for j = 1:numel(nodeObj.Array.ObjList)
                        mod_list = getFileList(nodeObj.Array.ObjList(j), '.mat');
                        nodeList = [nodeList, repmat({node}, 1, length(mod_list))];
                        matList = [matList, mod_list];
                        if ~isempty(mod_list)
                            nodeObjList = [nodeObjList, repmat({nodeObj.Array.ObjList(j)}, 1,length(mod_list))];
                        end
                    end
                end
            end
            
        end
        
        function lvl = findNodeLevel(~,Node)
            a = false;
            lvl = 0;
            while ~a
                myParent = Node.Parent;
                if isa(myParent, 'matlab.ui.container.Tree')
                    a = true;
                else
                    Node = Node.Parent;
                    lvl = lvl + 1;
                end
            end
        end
        
        function groupList = getMyGroup(~,nodeList)
            % Retrieves the name of the group from each element
            % contained in nodeList.
            groupList = cell(1,length(nodeList));
            for i = 1:numel(nodeList)
                node = nodeList{i};
                b_isNode = true;
                while b_isNode
                    myParent = node.Parent;
                    if isa(myParent, 'matlab.ui.container.Tree')
                        b_isNode = false;
                        groupName = node.Text;
                    else
                        node = node.Parent;
                    end
                end
                groupList{i} = groupName;
            end
        end
    end
    methods (Access = public)
        function filterSearch(app, FilterStruct)
            % FILTERSEARCH applies a query filter on the protocol object.
            % This public function is used by the SearchBuilderApp.
            
            app.ProtocolObj.FilterStruct = FilterStruct;
            app.FilterStructFromSearchBuilder = FilterStruct;
            app.ProtocolObj.queryFilter;
            app.currentTree.Enable = "off"; drawnow
            idxList = app.ProtocolObj.Idx_Filtered;
            if isempty(idxList)
                msgbox('Your query returned zero results. Change query terms and try again!', ...
                    'Advanced Search','warn', "modal");
                app.currentTree.Enable ="on";
                return
            end
            app.TextArea_2.Value = 'Filtering object Tree...'; drawnow
            app.TextArea.Value = 'Filtering object Tree...'; drawnow
            % Delete subject nodes
            subjNodeIDs = {app.currentTree.Children.Children.Text};
            subjSelecIDs = {app.ProtocolObj.Array.ObjList(unique(idxList(:,1))).ID};
            idx_del = ~ismember(subjNodeIDs, subjSelecIDs);
            delete(app.currentTree.Children.Children(idx_del));
            % Delete acquisition nodes
            for i = 1:numel(app.currentTree.Children.Children)
                Node = app.currentTree.Children.Children(i);
                app.expandNode(Node);
                indxS = app.ProtocolObj.Array.findElement('ID', Node.Text, 'strcmp');
                AcqList = idxList(idxList(:,1) == indxS,:);
                acqNodeIDs ={Node.Children.Text};
                acqSelecIDs = {app.ProtocolObj.Array.ObjList(indxS).Array.ObjList(AcqList(:,2)).ID};
                idx_del = ~ismember(acqNodeIDs, acqSelecIDs);
                delete(Node.Children(idx_del))
            end
            % Delete Modality nodes
            for i = 1:numel(app.currentTree.Children.Children)
                for j = 1:numel(app.currentTree.Children.Children(i).Children)
                    Node = app.currentTree.Children.Children(i).Children(j);
                    indxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    indxA = app.ProtocolObj.Array.ObjList(indxS).Array.findElement('ID', Node.Text, 'strcmp');
                    Modlist = idxList(idxList(:,1) == indxS & idxList(:,2) == indxA,:);
                    modNodeIDs = {Node.Children.Text};
                    modSelecIDs  = {app.ProtocolObj.Array.ObjList(indxS).Array.ObjList(indxA).Array.ObjList(Modlist(:,3)).ID};
                    idx_del = ~ismember(modNodeIDs, modSelecIDs);
                    delete(Node.Children(idx_del))
                end
            end
            app.currentTree.Enable ="on";
            expand(app.currentTree.Children, 'all');
            if isequal(app.currentTree, app.Tree2)
                app.TextArea_2.Value = 'Click on ''Select Items'' button to view the results of your query!';
            end
            app.TextArea_2.Value = 'Done!'; drawnow
            app.TextArea.Value = 'Done!'; drawnow
        end
        
        function displayPipeInfo(app, b_resetArea)
            % DISPLAYPIPEINFO displays information about the pipeline.
            % Input:
            % b_resetArea (bool) : If TRUE, erases the text area
            % TextArea_2 before displaying the information. If FALSE,
            % appends the new text to the current text.
            
            if b_resetArea
                app.TextArea_2.Value = '';
            end
            Pipe = app.PipelineObj.pipe;
            Text = app.TextArea_2.Value;
            txt = {['Number of steps: ' num2str(length(Pipe))]};
            txt = [txt {'------------------------------------------------------'}];
            for i = 1:length(Pipe)
                txt = [txt {['Step #' num2str(i) ': ']}];
                txt = [txt {['Class: ' Pipe(i).className]}];
                txt = [txt {['Function: ' Pipe(i).Name]}];
                if ~isempty(Pipe(i).opts)
                    fields = fieldnames(Pipe(i).opts);
                    txt = [txt {'Parameters: '}];
                    for j = 1:length(fields)
                        txt = [txt {[fields{j} ': ' num2str(Pipe(i).opts.(fields{j}))]}];
                    end
                end
                txt = [txt {'------------------------------------------------------'}];
            end
            Text = [Text txt];
            app.TextArea_2.Value = Text;
            app.TextArea_2.Editable = 'off';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            % Update UIFigure position:
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'center')
            root = getenv('Umitoolbox');
            addpath(genpath(root))
            if numel(varargin) == 1
                app.ProtocolFile = varargin{1};
                app.checkAndLoad_protocol;
            end
            app.currentTree = app.Tree;            
%             app.SelectButton.Enable = 'on';
%             app.AdvancedSearchButton.Enable = 'on';
%             app.RunPipelineButton.Enable = 'off';
%             app.LaunchPipelineConfigButton.Enable = 'off';
%             app.OpenButton.Enable = 'off';
%             app.SaveButton.Enable = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, path] = uigetfile('*mat', 'Open a Project File');
            if filename == 0
                return
            end
            app.ProtocolFile = fullfile(path, filename);
            % Lock figure:
            app.TabGroup.Visible = 'off';
            app.checkAndLoad_protocol;
            app.TabGroup.Visible = 'on';
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            protocol = app.ProtocolObj;
            if isempty(protocol)
                return
            end
            if isempty(app.ProtocolFile)
                app.ProtocolFile= uigetdir(app.ProtocolObj.SaveDir, 'Select Folder to Save Project File');
                app.ProtocolFile = fullfile(app.ProtocolFile, [app.ProtocolObj.Name '.mat']);
                save(app.ProtocolFile, "protocol");
                msgbox('Project Saved!');
            else
                save(app.ProtocolFile, 'protocol');
                uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            % Reset Protocol Object and FileName
            app.ProtocolObj = [];
            app.ProtocolFile = [];
            %%
            
            app.Label.Text = 'Creating New Project File...';
            app.Label_2.Text = '...';drawnow;
            Name = inputdlg('Type the Name of your project: ', 'Project Name', [1 60], {''}, 'on');
            if isempty(Name)
                disp('Operation Cancelled by User')
                return
            end
            MainDir = uigetdir(pwd, 'Select Directory with Raw Data.');
            SaveDir = uigetdir(pwd, 'Select Save Directory.');
            [ProtoFunc, FuncPath] = uigetfile('*.m', 'Select Protocol Function.');
            ProtoFunc = erase(ProtoFunc, '.m');
            %             addpath(genpath(MainDir));
            %             addpath(genpath(SaveDir));
            addpath(FuncPath);
            %             cd(SaveDir);
            eval(['FuncHandle = @' ProtoFunc ';']);
            app.ProtocolObj = Protocol(Name{:},MainDir, SaveDir,FuncHandle, []);
            app.Label.Text = 'New Project Created!'; drawnow
            app.Label_2.Text = 'Creating Object Arrays...'; drawnow
            app.ProtocolObj.generateList;
            app.Label_2.Text = 'Creating Save Directories...'; drawnow
            app.ProtocolObj.generateSaveFolders;
            % Make Trees:
            app.makeUItreeNodes(app.Tree);
            app.makeUItreeNodes(app.Tree2);
            app.Label_2.Text = 'All set! Don''t forget to save it.';drawnow
            
        end

        % Selection changed function: Tree, Tree3
        function TreeSelectionChanged(app, event)
            % TREESELECTIONCHANGED is the callback function for Tree, Tree2 and Tree3.
            % It runs the code below every time the user clicks on a node.
            
            % Lock tree:
            event.Source.Enable = 'off';drawnow
            % Un-select rows in List Of Files TextArea:
            app.ListoffilesListBox.Value = {};
            % This section is used by both Tree and Tree2
            Nodes = event.Source.SelectedNodes;
            % This for loop exists just to find a "Protocol" object
            for i = numel(Nodes)
                NodeObj = app.getNodeObj(Nodes(i));
                if isa(NodeObj, 'Protocol')
                    break
                end
            end
            % Automatically select all childrens from the selected node(s) (Not for Tree in Dashboard):
            if ~isequal(app.currentTree, app.Tree)
                nodeList = [];
                for i = 1:numel(Nodes)
                    node = Nodes(i);
                    app.expandNode(node);
                    try
                        nodeList = [nodeList;node;node.Children;[node.Children.Children]'];
                    catch ME
                        try
                            nodeList = [nodeList;node;node.Children];
                        catch ME
                            nodeList = [nodeList;node];
                        end
                    end
                    
                end
                event.Source.SelectedNodes = unique(nodeList);
            else
                app.expandNode(Nodes)
            end
            % This is for Tree only. Given that MultiSelection is "off", it
            % assumes that there is only one NodeObj.
            if isequal(app.currentTree, app.Tree) && ~isa(NodeObj, 'Protocol')
                app.displayNodeInfo(NodeObj);
                app.displayNodeFiles(app.currentTree,app.ListoffilesListBox);
            elseif isequal(app.currentTree, app.Tree3) && ~isa(NodeObj, 'Protocol')
                app.displayNodeFiles(app.currentTree,app.SelectafileListBox);
            else
                app.SelectafileListBox.Items = {''};
            end
            % Unlock tree:
            event.Source.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isempty(app.ProtocolObj)
                delete(app)
            elseif isempty(app.ProtocolFile)
                msg = 'You Created a new Project. Do you want to save it?';
                title = 'Save new Project';
                selection = uiconfirm(app.UIFigure, msg, title, 'Options',{'Save', 'No', 'Cancel'}, ...
                    "DefaultOption",1, "CancelOption",3);
                switch selection
                    case 'Save'
                        protocol = app.ProtocolObj;
                        app.ProtocolFile= uigetdir(protocol.SaveDir, 'Select Folder to Save Project File');
                        app.ProtocolFile = fullfile(app.ProtocolFile, [protocol.Name '.mat']);
                        save(app.ProtocolFile, "protocol");
                        msgbox('Project Saved!');
                        delete(app);
                    case 'No'
                        delete(app)
                    case 'Cancel'
                        return
                end
            else
                a = load(app.ProtocolFile);
                if ~isequal(a.protocol, app.ProtocolObj)
                    msg = 'It seems that you made changes to your Project. Do you want to save them?';
                    title = 'Confirm Save';
                    selection = uiconfirm(app.UIFigure,msg,title,...
                        'Options',{'Save','No', 'Cancel'},...
                        'DefaultOption',1,'CancelOption',3);
                    switch selection
                        case 'Save'
                            protocol = app.ProtocolObj;
                            save(app.ProtocolFile, "protocol");
                            uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
                            delete(app)
                        case 'No'
                            delete(app)
                        case 'Cancel'
                            return
                    end
                else
                    delete(app)
                end
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            protocol = app.ProtocolObj;
            if isempty(protocol)
                return
            end
            [file, Path] = uiputfile('*.mat','Save Protocol as...', fullfile(protocol.SaveDir,[protocol.Name '.mat']));
            if file == 0
                disp('Operation Cancelled by User')
                return
            end
            [~,protocol.Name,~] = fileparts(file);
            save(fullfile(Path,file), 'protocol');
            uiwait(msgbox('Project file saved!'));
            clear protocol
        end

        % Callback function
        function AdvancedSearchButtonPushed(app, event)
            app.AdvancedSearchButton.Enable = 'off';
            app.SearchBuilderApp = SearchBuilder_dlgBox(app, app.ProtocolObj.FilterStruct);
            waitfor(app.SearchBuilderApp)
            app.AdvancedSearchButton.Enable = 'on';
        end

        % Button pushed function: LaunchPipelineConfigButton
        function LaunchPipelineConfigButtonPushed(app, event)
            
            app.LaunchPipelineConfigButton.Enable = 'off';            
            % Ask User which object the Pipeline will run on:
            classNames = {'Subject', 'Acquisition'};
            selObjs = app.ProtocolObj.extractFilteredObjects(3);
            modNames = unique(cellfun(@(x) class(x), selObjs, 'UniformOutput', false));
            classNames = [classNames modNames];
            [indx,tf] = listdlg('ListString',classNames, 'PromptString', {'Select an object type to',....
                'run the analysis'}, "ListSize",[160 200],'SelectionMode', 'single');
            if ~tf
                disp('Operation Cancelled by User')
                app.LaunchPipelineConfigButton.Enable = 'on';
                return
            end
            % Create PipelineManager object:
            app.PipelineObj = PipelineManager(app.ProtocolObj, classNames{indx});
            % Launch Pipeline Config GUI:
            app.PipelineConfigApp = PipelineConfig_dlgBox(app.PipelineObj, false);
            waitfor(app.PipelineConfigApp)            
            app.RunPipelineButton.Enable = 'on';
            app.LaunchPipelineConfigButton.Enable = 'on';
        end

        % Callback function
        function SaveButtonPushed(app, event)
            Pipeline = app.PipelineObj;
            if isempty(Pipeline)
                uialert(app.UIFigure, 'Pipeline doesn''t exist!', 'Warning!', 'Modal',true);
                return
            end
            answer = inputdlg('Name of Pipeline:', 'Save Pipeline Config File', [1 40]); answer = answer{:};
            app.PipelineObj.savePipe(answer)
            msgbox(['Pipeline saved as ' answer ' in ' fullfile(app.ProtocolObj.SaveDir, 'PipelineConfigFiles')]);
        end

        % Callback function
        function OpenButtonPushed(app, event)
            if ~exist([app.ProtocolObj.SaveDir 'PipelineConfigFiles'], 'dir')
                uialert(app.UIFigure, 'There are no Pipelines saved yet. Create one!', 'Warning!', "Modal",true);
                return
            end
            cd([app.ProtocolObj.SaveDir 'PipelineConfigFiles']);
            [filename, path] = uigetfile('*.json','Open a Pipeline Configuration File');
            if filename == 0
                return
            end
            app.PipelineObj.loadPipe(fullfile(path,filename));
            msgbox(['Pipeline ' filename ' loaded!']);
            app.displayPipeInfo(true);
            app.SaveButton.Enable = 'on';
            app.RunPipelineButton.Enable = 'on';
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            txt = app.TextArea_2.Value;
            txt = [txt; {['(' char(datetime('now')) ') Running pipeline...']}];
            app.TextArea_2.Value = txt;
            app.PipelineObj.b_ignoreLoggedFiles = app.IgnoreLoggedTasksCheckBox.Value;
            app.PipelineObj.run_pipeline;
            txt = [txt; {['(' char(datetime('now')) ') Finished! Check the table below!']}];
            app.TextArea_2.Value = txt;
            % Show Pipeline Summary in UITable_2:
            tab = app.PipelineObj.pipelineSummary;
            tab.Messages = [];
            % Rearrange columns so "Error Message column" closer to the left
            tab = tab(:,[1 2 3 8:10 4:7]);
            % Rename Error Message column;
            tab.Properties.VariableNames{6} = 'Error_log';
            app.UITable_2.Data = tab;
            app.UITable_2.ColumnName = tab.Properties.VariableNames;
            app.UITable_2.ColumnEditable = false;
            app.UITable_2.ColumnSortable = true;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if isequal(app.currentTree, app.Tree2)
                app.TextArea_2.Value = 'Selecting items... Please wait.'; drawnow;
            end
            app.currentTree.Enable = "off"; drawnow
            if isempty(app.ProtocolObj.Idx_Filtered)
                app.ProtocolObj.clearFilterStruct;
                app.ProtocolObj.queryFilter;
            end
            AllIndx = app.ProtocolObj.Idx_Filtered;
            if isempty(app.currentTree.SelectedNodes)
                app.currentTree.SelectedNodes = app.currentTree.Children.Children;
            end
            indx_Sel = [];
            for i = 1:numel(app.currentTree.SelectedNodes)
                Node = app.currentTree.SelectedNodes(i);
                ID = Node.Text;
                b_top = false; a = 0;
                str = 'typeClass = class(Node.';
                str2 = 'Parent';
                while ~b_top
                    eval([str str2 ');']);
                    lvl = a;
                    if strcmp(typeClass,'matlab.ui.container.Tree')
                        b_top= true;
                    end
                    str = [str str2 '.'];
                    a = a+1;
                end
                switch lvl
                    % If Protocol Node is selected, reset filter and select
                    % everything.
                    case 0
                        break
                    case 1
                        idx = app.ProtocolObj.Array.findElement('ID', ID, 'strcmp');
                        indx = find(AllIndx(:,1)==idx);
                    case 2
                        idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                        idx = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', ID, 'strcmp');
                        indx = find(AllIndx(:,1)== idxS & AllIndx(:,2) == idx);
                    case 3
                        idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Parent.Text, 'strcmp');
                        idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Parent.Text, 'strcmp');
                        idx = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', ID, 'strcmp');
                        indx = find(AllIndx(:,1)== idxS & AllIndx(:,2) == idxA & AllIndx(:,3) == idx);
                end
                indx_Sel = [indx_Sel indx'];
            end
            if lvl ~= 0
                SelItems = AllIndx(indx_Sel, :);
                app.ProtocolObj.Idx_Filtered = unique(SelItems, 'rows');
            else
                SelItems = unique(app.ProtocolObj.Idx_Filtered, 'rows');
            end
            for i = 1:size(SelItems,1)
                idxS = strcmp({app.currentTree.Children.Children.Text}, app.ProtocolObj.Array.ObjList(SelItems(i,1)).ID);
                Node = app.currentTree.Children.Children(idxS);
                Node.Icon = 'icon_check.png';
                if isempty(Node.Children)
                    app.expandNode(Node);
                end
                indxA = find(strcmp({Node.Children.Text}, app.ProtocolObj.Array.ObjList(SelItems(i,1)).Array.ObjList(SelItems(i,2)).ID));
                for j = 1:numel(indxA)
                    Node.Children(indxA(j)).Icon = 'icon_check.png';
                    idxM = strcmp({Node.Children(indxA(j)).Children.Text}, app.ProtocolObj.Array.ObjList(SelItems(i,1)).Array.ObjList(SelItems(i,2)).Array.ObjList(SelItems(i,3)).ID);
                    set(Node.Children(indxA(j)).Children(idxM), 'Icon', 'icon_check.png');
                end
            end
            app.currentTree.Enable = "on";
            if isequal(app.currentTree, app.Tree2)
                app.LaunchPipelineConfigButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
                app.SelectButton.Enable = 'off';
                app.AdvancedSearchButton.Enable = 'off';
                app.TextArea_2.Value = {'Selection is done!', 'Now you are good to go with the pipeline!'}; drawnow
            elseif isequal(app.currentTree, app.Tree3)
                app.displayNodeFiles(app.currentTree,app.SelectafileListBox);
            end
        end

        % Button pushed function: ResetSearchButton_2, 
        % ResetSearchButton_3
        function ResetSearchButtonPushed(app, event)
            app.currentTree.Visible = 'off';
            if isequal(app.currentTree, app.Tree2)
                app.TextArea_2.Value = 'Resetting Search... please wait.'; drawnow
            end
            app.makeUItreeNodes(app.currentTree)
            app.FilterStructFromSearchBuilder = [];
            app.ProtocolObj.clearFilterStruct;
            app.ProtocolObj.queryFilter;
            if isequal(app.currentTree, app.Tree2)
                app.SelectButton.Enable = 'on';
                app.AdvancedSearchButton.Enable = 'on';
                app.RunPipelineButton.Enable = 'off';
                app.LaunchPipelineConfigButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.TextArea_2.Value = 'Query Reset completed!'; drawnow;
            end
            app.currentTree.Visible = 'on';
        end

        % Value changed function: SaveDatabeforeerrorCheckBox
        function SaveDatabeforeerrorCheckBoxValueChanged(app, event)
            value = app.SaveDatabeforeerrorCheckBox.Value;
            if value
                uialert(app.UIFigure, 'By deleting intermediate files, the pipeline may not be able to Ignore Logged Items next time!', 'Pipeline Warning!', 'Icon',"warning", "Modal",true);
            end
            app.PipelineObj.EraseIntermediate = value;
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            NodeObj = app.getNodeObj(app.Tree.SelectedNodes);
            eval(['mc = ?' class(NodeObj) ';'])
            b_isPublic = cellfun(@(x) any(strcmp(x, 'public')), {mc.PropertyList.SetAccess});
            propList = setdiff({mc.PropertyList(b_isPublic).Name}, {'ID', 'RawFiles'});
            b_isSettable = cellfun(@(x) (isnumeric(NodeObj.(x)) || ischar(NodeObj.(x)) || isdatetime(NodeObj.(x))), propList);
            if ~any(b_isSettable)
                msgbox('There are no editable properties for this object.', 'modal')
                return
            end
            propList = propList(b_isSettable);
            val = cellfun(@(x) num2str(NodeObj.(x)), propList, 'UniformOutput',false);
            prompt = propList;
            dlgTitle = ['Editor : ' app.Tree.SelectedNodes.Text];
            answer = inputdlg(prompt, dlgTitle, 1, val, "on");
            if ~isempty(answer)
                for i = 1:length(prompt)
                    try
                        if ~isnan(str2double(answer{i}))
                            disp(answer{i})
                            eval(['NodeObj.' prompt{i} ' = str2double(answer{' num2str(i) ...
                                '});'])
                        else
                            eval(['NodeObj.' prompt{i} ' = answer{' num2str(i) ...
                                '};'])
                        end
                    catch ME
                        errordlg(getReport(ME, 'basic'), 'Error!')
                    end
                end
                app.displayNodeInfo(NodeObj);
            else
                return
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            Node = app.Tree.SelectedNodes;
            NodeObj = app.getNodeObj(Node);
            if isa(NodeObj, 'Protocol') || isa(NodeObj, 'Modality')
                uialert(app.UIFigure, ['Cannot delete "' Node.Text '". One can only delete Subjects or Acquisitions.'], 'Delete failed.',...
                    'Icon', 'error', 'Modal', true);            
                return
            end
            onlyChild = false;
            if isempty(setdiff({Node.Parent.Children.Text}, Node.Text))
                onlyChild = true;
            end
            if onlyChild
                selection = uiconfirm(app.UIFigure,['You are about to delete ' Node.Text '. This is the only element inside ' ...
                    Node.Parent.Text '. This will also delete ' Node.Parent.Text '. Proceed?'], 'Delete Element?',...
                    'Options', {'Delete Item only', 'Delete Item and Files', 'Cancel'}, 'DefaultOption',1,...
                    'CancelOption',3,'Icon','warning');
            else
                selection = uiconfirm(app.UIFigure,['You are about to delete ' Node.Text '. Proceed?'], 'Delete Element?',...
                    'Options', {'Delete Item only', 'Delete Item and Files', 'Cancel'}, 'DefaultOption',1,...
                    'CancelOption',3,'Icon','warning');
            end
            if strcmp(selection, 'Cancel')
                disp('Operation Cancelled by User')
                return
            end
            
            % Proceed with item's deletion:
            
            if strcmp(selection, 'Delete Item only')
                b_delFolder = false;
            else
                b_delFolder = true;
            end
            
            if onlyChild                
                % Delete the parent:
                obj_to_del = app.getNodeObj(Node.Parent);
                idx_obj = obj_to_del.MyParent.Array.findElement('ID', obj_to_del.ID);
                switch class(obj_to_del)
                    case 'Subject'
                        app.ProtocolObj.manualRemoveObj(idx_obj,0, b_delFolder)
                    case 'Acquisition'
                        idx_subj = obj_to_del.MyParent.MyParent.Array.findElement('ID', obj_to_del.MyParent.ID);
                        app.ProtocolObj.manualRemoveObj(idx_subj,idx_obj, b_delFolder)
                    otherwise
                        uialert(app.UIFigure, 'Cannot delete Protocol!', 'Delete failed!', 'Icon', 'error', 'Modal',true);
                        return
                end
                % Delete Parent Node:
                idx = arrayfun(@(x) isequal(x,app.Tree.SelectedNodes), app.Tree.SelectedNodes.Parent.Children);
                delete(app.Tree.SelectedNodes.Parent.Children(idx))
                
            else
                % Delete the selected object:                
                idx_obj = NodeObj.MyParent.Array.findElement('ID', NodeObj.ID);
                switch class(NodeObj)
                    case 'Subject'                        
                        app.ProtocolObj.manualRemoveObj(idx_obj,0,b_delFolder);
                    case 'Acquisition'
                        idx_subj = NodeObj.MyParent.MyParent.Array.findElement('ID', NodeObj.MyParent.ID);
                        app.ProtocolObj.manualRemoveObj(idx_subj,idx_obj,b_delFolder);
                end              
                % Delete Node:
                delete(app.Tree.SelectedNodes)
            end
                                   
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if isempty(app.ProtocolObj)
                uiwait(warndlg('You have to load/create a Project File first!', 'Warning!', 'modal'));
                return
            end
            selectedTab = app.TabGroup.SelectedTab;                        
            arrayfun(@(x) set(x, 'Visible', 'off'), app.OptionsPanel.Children)
            switch selectedTab.Title
                case 'Dashboard'
                    app.currentTree = app.Tree;
                    app.SelectionLabel.Visible = 'on';
                    app.EditButton.Visible = 'on';
                    app.DeleteButton.Visible = 'on';                    
                case 'Pipeline control panel'
                    app.SelectButton.Visible = 'on';
                    app.UndoSelectionButton.Visible = 'on';
                case 'Visualization'                    
                    app.currentTree = app.Tree3;
                    app.SelectafileListBox.Items = {''};
                case 'Analysis'
                    app.currentTree = app.Tree4;
                    app.SelectFileListBox_analysis.Items = {''};
                    app.SelectObsListBox_analysis.Items = {''};
                    app.ToolsPanel.Visible = 'off';
            end
        end

        % Menu selected function: ResetLogBookMenu_2
        function ResetLogBookMenu_2Selected(app, event)
            app.ProtocolObj.clearLogBook;
            msgbox('Log Book reset!',"modal")
        end

        % Menu selected function: RestoregarbageListMenu
        function RestoregarbageListMenuSelected(app, event)
            app.ProtocolObj.purgeGarbageList;
            uialert(app.UIFigure, 'Garbage List was restored. You should see the deleted items back into the List if the raw data were not changed.',...
                'Garbage List restored!', "Icon","info", 'Modal',true, 'CloseFcn','uiresume(gcbf)'); uiwait(gcbf);           
            app.ProtocolObj.updateList;            
            app.makeUItreeNodes(app.Tree);
        end

        % Menu selected function: UpdateprojectMenu
        function UpdateprojectMenuSelected(app, event)
            if ~isempty(app.ProtocolObj)
                app.Label.Text = 'Updating Project Files...'; drawnow
                app.ProtocolObj.updateList;
                app.check4newData;
                % Make Trees:
                app.makeUItreeNodes(app.Tree);
                app.makeUItreeNodes(app.Tree2);
            else
                uialert(app.UIFigure, 'Open a Project file first!', 'Warning!', "Icon","warning", "Modal",true);
            end
        end

        % Menu selected function: LookfornewdataMenu
        function LookfornewdataMenuSelected(app, event)
            if isempty(app.ProtocolObj)
                uialert(app.UIFigure, 'Open a Project file first!', 'Warning!', "Icon","warning", "Modal",true);
                return
            end
            app.check4newData
        end

        % Button pushed function: UndoSelectionButton
        function UndoSelectionButtonPushed(app, event)
            %Restores the UITree2 icons and PROTOCOLOBJ.IDX_FILTERED.
            app.currentTree.Enable = "off"; drawnow
            % Subjects
            chkdS = strcmp({app.currentTree.Children.Children.Icon}, 'icon_check.png');
            set(app.currentTree.Children.Children(chkdS), 'Icon', 'icon_WinFolder.png');
            % Acquisitions
            indxS = find(chkdS);
            for i = 1:numel(indxS)
                chkdA = strcmp({app.currentTree.Children.Children(indxS(i)).Children.Icon}, 'icon_check.png');
                set(app.currentTree.Children.Children(indxS(i)).Children(chkdA), 'Icon', 'icon_WinFolder.png');
                indxA = find(chkdA);
                for j = 1:numel(indxA)
                    chkdM = strcmp({app.currentTree.Children.Children(indxS(i)).Children(indxA(j)).Children.Icon}, 'icon_check.png');
                    set(app.currentTree.Children.Children(indxS(i)).Children(indxA(j)).Children(chkdM), 'Icon', 'icon_WinFolder.png');
                end
            end
            % Reset app.ProtocolObj.Idx_Filtered
            if isempty(app.FilterStructFromSearchBuilder)
                app.ProtocolObj.clearFilterStruct;
            else
                app.ProtocolObj.FilterStruct = app.FilterStructFromSearchBuilder;
            end
            app.ProtocolObj.queryFilter;
            if isequal(app.currentTree, app.Tree2)
                app.SelectButton.Enable = 'on';
                app.AdvancedSearchButton.Enable = 'on';
                app.RunPipelineButton.Enable = 'off';
                app.LaunchPipelineConfigButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
            end
            app.currentTree.Enable = "on"; drawnow
        end

        % Menu selected function: ViewLogBookMenu
        function ViewLogBookMenuSelected(app, event)
            if isempty(app.ProtocolObj)
                return
            end
            fig = uifigure('Name', 'Project''s Log Book');
            tab = uitable(fig);
            tab.OuterPosition = [10 10 (fig.InnerPosition(3)-10) (fig.InnerPosition(4)-10)];
            a = load(app.ProtocolObj.LogBookFile);
            tab.Data = a.LogBook;
            tab.ColumnName = a.LogBook.Properties.VariableNames;
            tab.ColumnEditable = false;
        end

        % Value changed function: IgnoreLoggedTasksCheckBox
        function IgnoreLoggedTasksCheckBoxValueChanged(app, event)
            value = app.IgnoreLoggedTasksCheckBox.Value;
            app.PipelineObj.b_ignoreLoggedFiles = value;
        end

        % Button pushed function: RetinotopyButton
        function RetinotopyButtonPushed(app, event)
            retino_maps(app.selectedObjs, app.SelectafileListBox.Value);
        end

        % Button pushed function: SeedPixelCorrelationButton
        function SeedPixelCorrelationButtonPushed(app, event)
            if numel(app.selectedObjs) > 1
                uialert(app.UIFigure, 'This app accepts only one Object!','Warning', 'Icon',"warning", "Modal",true);
                return
            end
            SeedPixMap(app.selectedObjs, app.SelectafileListBox.Value);
        end

        % Button pushed function: ROImanagerButton
        function ROImanagerButtonPushed(app, event)
            if numel(app.selectedObjs) > 1
                uialert(app.UIFigure, 'This app accepts only one Object!','Warning', 'Icon',"warning", "Modal",true);
                return
            end
            obj = app.selectedObjs{:};
            ROImanager(fullfile(obj.SaveFolder, app.SelectafileListBox.Value));
        end

        % Button pushed function: ManualImageregistrationButton
        function ManualImageregistrationButtonPushed(app, event)
            if numel(app.selectedObjs) > 1
                uialert(app.UIFigure, 'This app accepts only one Object!','Warning', 'Icon',"warning", "Modal",true);
                return
            end
            myObj = app.selectedObjs{:};
            b_redo = true;
            while b_redo
                b_redo = manual_alignFrames(myObj, app.SelectafileListBox.Value);
            end
        end

        % Button pushed function: DataViewerButton
        function DataViewerButtonPushed(app, event)
            if numel(app.selectedObjs) ~=1
                uialert(app.UIFigure, 'This app only accepts one file.', 'Too many inputs',"Icon","warning")
                return
            end
            filename = fullfile(app.selectedObjs{1}.SaveFolder, app.SelectafileListBox.Value);
            funcImag_viz(filename);
        end

        % Value changed function: ListoffilesListBox
        function ListoffilesListBoxValueChanged(app, event)
            % Callback to show the data (or metaData) of selected file.
            % Single-selection only!
            % Here, the dataHistory will be displayed as a table while the
            % dimension of other properties will be displayed in the text
            % area.
            value = app.ListoffilesListBox.Value;
            
            if isempty(value)
                return
            elseif numel(value)>1
                app.TextArea.Value = 'Select ONE file to show info.';
                app.UITable.Data = table(0,0,0,0,0);
                return            
            else
                value = value{:};
            end
            
            NodeObj = app.getNodeObj(app.Tree.SelectedNodes);
            % Map data/metaData File
            if endsWith(value,'.dat')
                mapData = matfile(fullfile(NodeObj.SaveFolder, strrep(value, '.dat', '.mat')));
            else
                mapData = matfile(fullfile(NodeObj.SaveFolder, value));
            end
            
            % Extract variable dimensions and string values as text
            propList = setdiff(properties(mapData),{'Properties', 'dataHistory'});
            disp_txt = cell(1,length(propList));
            app.TextArea.Value = '';
            for i = 1:length(propList)
                data = mapData.(propList{i});
                if ischar(data(:)) || isstring(data(:))
                    disp_txt{i} = sprintf('%s:\n\tValue:"%s"', propList{i}, data);
                elseif isnumeric(data) && numel(data)<5
                    vals = arrayfun(@(x) num2str(x), data, 'UniformOutput', false);
                    vals = strjoin(vals, ', ');
                    disp_txt{i} = sprintf('%s:\n\tValue:"%s"', propList{i}, vals);
                elseif iscell(data) && ischar([data{:}])
                    data = mapData.(propList{i});
                    data = cellfun(@(x) num2str(x), data, 'UniformOutput',false);
                    data = strjoin(data,', ');
                    disp_txt{i} = sprintf('%s:\n\tValue:{%s}', propList{i}, data);
                else
                    dims = size(data);
                    dims = arrayfun(@(x) num2str(x), dims, 'UniformOutput', false);
                    dims = strjoin(dims, ' x ');
                    disp_txt{i} = sprintf('%s:\n\tDimemsion:%s, Type: %s"', propList{i}, dims, class(data));
                end
                
            end
            app.TextArea.Value = disp_txt;
            % Create Data History table:            
            
            % Transform "dataHistory" structure as table:
            info = struct2table(mapData.dataHistory, 'AsArray',true);
            str_params = {};
            str_outFiles = {};
            for i = 1:height(info)
                % Organize function's params as string:
                params = table2array(info(i,5)); 
                if iscell(params); params = params{:};end
                fNames = fieldnames(params);
                vals = cellfun(@(x) num2str(params.(x)), fNames, 'UniformOutput',false);
                C = [fNames'; vals'];
                str_params{i} = sprintf('%s : %s\n', C{:});
                % Do the same to output files:
                fList = table2array(info(i,7));
                if iscell([fList{:}])
                    fList = fList{:};
                end
                str_outFiles{i} = strjoin(fList, '\n');
            end
            info.Parameters = str_params';
            info.OutputFiles = str_outFiles';
            info.opts = [];
            info.outputFile_list = [];
            % Update table:
            app.UITable.Data = info;
            app.UITable.ColumnName = info.Properties.VariableNames;
            app.UITable.ColumnEditable = false;
            app.TableTitle.Text = ['History of File: ' value];                        
        end

        % Button pushed function: DeleteselectedfilesButton
        function DeleteselectedfilesButtonPushed(app, event)
            filelist = app.ListoffilesListBox.Value;
            if isempty(filelist)
                return
            end
            
            if ischar(filelist)
                filelist = {filelist};
            end
            
            msg = [{'Do you want to permanently delete the following file(s) :'}, filelist];
            answer = uiconfirm(app.UIFigure, msg, 'Warning!', 'Options', {'Yes, proceed', 'cancel'});
            if strcmp(answer,'Yes, proceed')
                NodeObj = app.getNodeObj(app.Tree.SelectedNodes);
                for i = 1:numel(filelist)
                    delete(fullfile(NodeObj.SaveFolder, filelist{i}));
                    [~,file,ext] = fileparts(filelist{i});
                    if strcmp(ext,'.dat')
                        % Delete metaData file:
                        delete(fullfile(NodeObj.SaveFolder,[file '.mat']))
                    end
                end
                app.displayNodeFiles(app.Tree,app.ListoffilesListBox);
                msgbox('File(s) erased!');
            end
        end

        % Button pushed function: AdvancedSearchButton_2
        function AdvancedSearchButton_2Pushed(app, event)
            app.AdvancedSearchButton_2.Enable = 'off';
            app.SearchBuilderApp = SearchBuilder_dlgBox(app, app.ProtocolObj.FilterStruct);
            waitfor(app.SearchBuilderApp)
            app.AdvancedSearchButton_2.Enable = 'on';
        end

        % Button pushed function: AdvancedSearchButton_3
        function AdvancedSearchButton_3Pushed(app, event)
            app.AdvancedSearchButton_3.Enable = 'off';
            app.SearchBuilderApp = SearchBuilder_dlgBox(app, app.ProtocolObj.FilterStruct);
            waitfor(app.SearchBuilderApp)
            app.AdvancedSearchButton_3.Enable = 'on';
        end

        % Value changed function: SelectafileListBox
        function SelectafileListBoxValueChanged(app, event)
            
            % Lock Tree3:
            app.Tree3.Enable = 'off';
            
            value = app.SelectafileListBox.Value;
            disp('Selection!')
            idxList = false(numel(app.Tree3.SelectedNodes),1);
            for i = 1:numel(app.Tree3.SelectedNodes)
                idxList(i) = ismember(value, app.getFileList(app.selectedObjs{i},'all'));
            end
            
            % Change Icons in tree nodes to show selection OR error:
            app.Tree3.SelectedNodes(~idxList) = [];
            app.selectedObjs = arrayfun(@(x) app.getNodeObj(x), app.Tree3.SelectedNodes, 'UniformOutput', false);
            arrayfun(@(x) expand(x.Parent, 'all'), app.Tree3.SelectedNodes)
            % Unlock Tree3:
            app.Tree3.Enable = 'on';
        end

        % Selection changed function: Tree4
        function Tree4SelectionChanged(app, event)
            % Callback made for controlling behaviour of Analysis tab
            % UITree.
            app.SelectButton_analysis.Enable = 'off';
            selectedNodes = app.Tree4.SelectedNodes;
            % erase List boxes:
            app.SelectFileListBox_analysis.Items = {''};
            app.SelectObsListBox_analysis.Items = {''};
            %
            
            if isempty(selectedNodes)
                return
            end
            disp('Building Node Selection...')
            lvl = arrayfun(@(x) app.findNodeLevel(x), selectedNodes);
            top_rank = min(lvl);
            nodelist = [];
            switch top_rank
                case 0
                    top_nodes = [app.Tree4.SelectedNodes(lvl == 0)];
                    for i = 1:numel(top_nodes)
                        top_node = top_nodes(i);
                        childs = [top_node.Children];
                        g_childs = vertcat(childs.Children);
                        nodelist = [nodelist; childs; g_childs];
                    end
                    app.Tree4.SelectedNodes = union(app.Tree4.SelectedNodes, nodelist);
                    tmp_lvl = arrayfun(@(x) app.findNodeLevel(x), app.Tree4.SelectedNodes);
                    tmp_idx = (tmp_lvl == 0);
                    app.Tree4.SelectedNodes = app.Tree4.SelectedNodes(~tmp_idx);
                case 1
                    idx = (lvl == 1);
                    nodes = selectedNodes(idx);
                    nodelist = nodes;
                    for i = 1:numel(nodes)
                        nodelist = [nodelist; nodes(i).Children; [nodes(i).Children.Children]'];
                        expand(nodes(i), 'all');
                    end
                    app.Tree4.SelectedNodes = union(app.Tree4.SelectedNodes, nodelist);
                case 2
                    idx = (lvl == 2);
                    nodes = selectedNodes(idx);
                    nodelist = nodes;
                    for i = 1:numel(nodes)
                        nodelist = [nodelist; nodes(i).Children];
                        expand(nodes(i), 'all');
                    end
                    app.Tree4.SelectedNodes = union(app.Tree4.SelectedNodes, nodelist);
            end
            
            [app.matFileList, app.nodeList, app.nodeObjList] = app.findStatsFiles;
            disp('Done Selecting!')
            % Display Node Files in Selection ListBox:
            [~, files, ~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput', false);
            if isempty(files)
                app.SelectFileListBox_analysis.Items = {'No .MAT files found!'};
            else
                app.SelectFileListBox_analysis.Items = unique(files);
                app.SelectFileListBox_analysis.Value = {};
                app.SelectFileListBox_analysisValueChanged;
            end
        end

        % Button pushed function: CreateEditButton
        function CreateEditButtonPushed(app, event)
            a = data_grouping_tool(app);
            waitfor(a);
            app.fillTree4;
        end

        % Value changed function: SelectFileListBox_analysis
        function SelectFileListBox_analysisValueChanged(app, event)
            value = app.SelectFileListBox_analysis.Value;
            if isempty(value)
                cellfun(@(x) set(x, 'Icon', 'icon_WinFolder.png'), app.nodeList);
                return
            end
            [~,all_files,~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput',false);
            idx = strcmp(all_files, value);
            cellfun(@(x) set(x, 'Icon', 'icon_WinFolder.png'), app.nodeList);
            cellfun(@(x) set(x, 'Icon', 'icon_check.png'), app.nodeList(idx));
            
            % Open .MAT files and show variable names:
            varnames = {};
            matFiles = app.matFileList(idx);
            for i = 1:numel(matFiles)
                mDt = matfile(matFiles{i});
                varnames = [varnames; mDt.obsID];
            end
            varnames = cellfun(@num2str, unique(varnames), 'UniformOutput', false);
            app.SelectObsListBox_analysis.Items = varnames;
            if ~isempty(varnames)
                app.SelectButton_analysis.Enable = 'on';
            else
                app.SelectButton.Enable = 'off';
            end
        end

        % Value changed function: SelectButton_analysis
        function SelectButton_analysisValueChanged(app, event)
            value = app.SelectButton_analysis.Value;
            if value
                app.SelectButton_analysis.Text = 'Please wait...';drawnow
                app.Tree4.Enable = 'off';
                app.SelectFileListBox_analysis.Enable = 'off';
                app.SelectObsListBox_analysis.Enable = 'off';
                obs_list = app.SelectObsListBox_analysis.Value;
                % Filter matFiles to get selected Nodes:
                [~,all_files,~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput',false);
                idx = strcmp(all_files, app.SelectFileListBox_analysis.Value);
                gList = app.getMyGroup(app.nodeList(idx));
                statsFile = [app.SelectFileListBox_analysis.Value '.mat'];
                app.StatsObj = StatsManager(app.nodeObjList(idx), obs_list, gList, statsFile);
                app.ToolsPanel.Visible = 'on';
                app.OpenBtn_analysis.Enable ='off';
                app.CreateEditButton.Enable = 'off';
                app.SavetofileButton.Enable = 'off';
                app.SelectButton_analysis.Text = 'Undo Selection';
            else
                app.SelectButton_analysis.Text = 'Select';
                app.Tree4.Enable = 'on';
                app.SelectFileListBox_analysis.Enable = 'on';
                app.SelectObsListBox_analysis.Enable = 'on';
                app.ToolsPanel.Visible = 'off';
                app.StatsObj = [];
                app.OpenBtn_analysis.Enable ='on';
                app.CreateEditButton.Enable = 'on';
                app.SavetofileButton.Enable = 'on';
            end
        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            % This callback calls StatsManager methods to create and save
            % all observation data into a .CSV file.
            [file, path] = uiputfile('*.csv', 'Export Data to .CSV file', 'obsData.csv');
            app.StatsObj.exportToCSV(fullfile(path, file));
        end

        % Button pushed function: TableViewButton
        function TableViewButtonPushed(app, event)
            % Callback to create a figure with a table per observation
            
            % Delete currently opened figure before creating a new one:
            h = findall(0, 'Tag', 'TableFig');
            delete(h)
            % Get Array of tables:
            [tabList, condNames] = app.StatsObj.createTable;
            % Create figure and display tables:
            uf = uifigure('Name', 'Observation Table', 'NumberTitle', 'off', 'Position', [1 1 800 500], 'ToolBar',...
                "none", "MenuBar","none", 'Tag', 'TableFig');
            movegui(uf,"center");
            tbg = uitabgroup(uf,'Position', [5, 5, 550, 490]);
            cnd_tab = uitable(uf, 'Position',[560, 5, 222, 490], 'Data',condNames);
            cnd_tab.ColumnName = 'Val ID';
            
            for i = 1:numel(tabList)
                t = uitab(tbg,'Title',app.StatsObj.obs_list{i});
                table = uitable('Parent', t, "Data",tabList{i}, "Position",[5 5 tbg.Position(3)-5 tbg.Position(4)-30]);
                table.ColumnSortable = true;
            end
        end

        % Button pushed function: OpenBtn_analysis
        function OpenBtn_analysisPushed(app, event)
            % This callback opens a UI to choose a StatsGroupInfo_XXXX.mat
            % file
            cd(app.ProtocolObj.SaveDir)
            [file, path] = uigetfile('StatsGroupInfo_*.mat', 'Select a Stats group file', 'StatsGroupInfo_data.mat');
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            try
                gInfo = matfile(fullfile(path, file));
                app.groupInfo = gInfo.groupInfo;
                app.fillTree4;
                app.SelectafileListBox.Items = {''};
                app.SelectObsListBox_analysis.Items = {''};
            catch
                uialert(app.UIFigure, 'Failed to load Stats group file! ')
            end
        
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            % This callback creates a UISAVE interface for the user to write the filename of the StatsGroup.mat file and
            % saves it to the protocol's SaveDir.
            
            % Create dialog box for input of new file:
            dlg_box = uifigure('Name', 'Type file name', 'Position', denormalize_UIfig_Position([0.1563 0.6481 0.1563 0.0926]),...
                'CloseRequestFcn', @Close_dlg_box);
            lab1 = uilabel('Parent',dlg_box, 'Text', 'StatsGroupInfo_','Position', [40 55 75 20], 'FontColor', [.5 .5 .5]);
            txt_box = uieditfield('Parent', dlg_box, "BackgroundColor", [1 1 1], "Position", [117 55 118 20]);
            lab2 = uilabel('Parent',dlg_box, 'Text', '.mat','Position', [232 55 50 20], 'FontColor', [.5 .5 .5]);
            ok_but = uibutton('Parent', dlg_box, "BackgroundColor",[0 .8 0], "Text", 'Save',...
                'Position', [40 10 60 25], 'ButtonPushedFcn',@(src,evnt) SaveROIas(src,evnt,app));
            
            % Local callbacks
            function SaveROIas(src,~,app)
                src.Enable = 'off';
                % Save StatsGroup structure to file:
                groupInfo = app.groupInfo; %#ok
                filename = fullfile(app.ProtocolObj.SaveDir, ['StatsGroupInfo_' src.Parent.Children(3).Value '.mat']);
                save(filename, 'groupInfo');
                msg = msgbox(['Stats Group info saved as ' filename],"modal");
                waitfor(msg)
                delete(src.Parent)
            end
            
            function Close_dlg_box(src,~)
                disp('Operation cancelled by User');
                delete(src)
            end
        end

        % Button pushed function: SavetoMatButton
        function SavetoMatButtonPushed(app, event)
            % Callback to save current selected data to a table in a .MAT
            % file.
            disp('Saving data to .MAT file...')
            % Select filename and path to save file:
            [file, path] = uiputfile('*.mat', 'Save data to MAT file', 'out.mat');
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            % Extract table from StatsObj:
            [table_arr, labels] = app.StatsObj.createTable;
            obs_list = app.StatsObj.obs_list;
            save(fullfile(path, file), "table_arr", 'labels', "obs_list");
            uialert(app.UIFigure, ['Data saved as: ' fullfile(path, file)], 'Data saved as .MAT', "Icon","info")
        end

        % Button pushed function: PlotCorrelationMatrixButton
        function PlotCorrelationMatrixButtonPushed(app, event)
            % Launches the PlotCorrMatrix app
            
            PlotCorrMatrix(app.StatsObj);
            
        end

        % Button pushed function: GenAverageRSimagesButton
        function GenAverageRSimagesButtonPushed(app, event)
            avgRSimage(app.StatsObj.stats_data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'umIToolbox - Main';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewMenu = uimenu(app.ProjectMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.ProjectMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as ...';
            app.UtilitiesMenu = uimenu(app.UIFigure);
            app.UtilitiesMenu.Text = 'Utilities';
            app.ProjectMenu_2 = uimenu(app.UtilitiesMenu);
            app.ProjectMenu_2.Text = 'Project';
            app.LookfornewdataMenu = uimenu(app.ProjectMenu_2);
            app.LookfornewdataMenu.MenuSelectedFcn = createCallbackFcn(app, @LookfornewdataMenuSelected, true);
            app.LookfornewdataMenu.Text = 'Look for new data';
            app.UpdateprojectMenu = uimenu(app.ProjectMenu_2);
            app.UpdateprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateprojectMenuSelected, true);
            app.UpdateprojectMenu.Text = 'Update project';
            app.ViewLogBookMenu = uimenu(app.ProjectMenu_2);
            app.ViewLogBookMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewLogBookMenuSelected, true);
            app.ViewLogBookMenu.Text = 'View LogBook';
            app.ResetLogBookMenu_2 = uimenu(app.ProjectMenu_2);
            app.ResetLogBookMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResetLogBookMenu_2Selected, true);
            app.ResetLogBookMenu_2.Text = 'Reset LogBook';
            app.RestoregarbageListMenu = uimenu(app.ProjectMenu_2);
            app.RestoregarbageListMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoregarbageListMenuSelected, true);
            app.RestoregarbageListMenu.Text = 'Restore garbage List';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.UITable = uitable(app.DashboardTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ProjectStatusPanel = uipanel(app.DashboardTab);
            app.ProjectStatusPanel.Title = 'Project Status';
            app.Label = uilabel(app.ProjectStatusPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ProjectStatusPanel);
            app.Label_2.Text = '';
            app.TableTitle = uilabel(app.DashboardTab);
            app.TableTitle.Text = 'Last operations run in selected item:';
            app.TextArea = uitextarea(app.DashboardTab);
            app.PropertiesofselecteditemLabel = uilabel(app.DashboardTab);
            app.PropertiesofselecteditemLabel.Text = 'Properties of selected item:';
            app.ListoffilesListBoxLabel = uilabel(app.DashboardTab);
            app.ListoffilesListBoxLabel.Text = 'List of files:';
            app.ListoffilesListBox = uilistbox(app.DashboardTab);
            app.ListoffilesListBox.Items = {};
            app.ListoffilesListBox.Multiselect = 'on';
            app.ListoffilesListBox.ValueChangedFcn = createCallbackFcn(app, @ListoffilesListBoxValueChanged, true);
            app.ListoffilesListBox.Value = {};
            app.DeleteselectedfilesButton = uibutton(app.DashboardTab, 'push');
            app.DeleteselectedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedfilesButtonPushed, true);
            app.DeleteselectedfilesButton.Text = 'Delete selected file(s)';
            app.PipelinecontrolpanelTab = uitab(app.TabGroup);
            app.PipelinecontrolpanelTab.Title = 'Pipeline control panel';
            app.UITable_2 = uitable(app.PipelinecontrolpanelTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.PipelineSummaryLabel = uilabel(app.PipelinecontrolpanelTab);
            app.PipelineSummaryLabel.Text = 'Pipeline Summary:';
            app.PipelinecontrolpanelPanel = uipanel(app.PipelinecontrolpanelTab);
            app.PipelinecontrolpanelPanel.Title = 'Pipeline control panel';
            app.LaunchPipelineConfigButton = uibutton(app.PipelinecontrolpanelPanel, 'push');
            app.LaunchPipelineConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchPipelineConfigButtonPushed, true);
            app.LaunchPipelineConfigButton.Tooltip = {'Launch pipeline configuration interface.'};
            app.LaunchPipelineConfigButton.Text = 'Launch Pipeline Config.';
            app.RunPipelineButton = uibutton(app.PipelinecontrolpanelPanel, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.IgnoreLoggedTasksCheckBox = uicheckbox(app.PipelinecontrolpanelPanel);
            app.IgnoreLoggedTasksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreLoggedTasksCheckBoxValueChanged, true);
            app.IgnoreLoggedTasksCheckBox.Tooltip = {'Check this box if you want to rerun tasks already performed in the data.'};
            app.IgnoreLoggedTasksCheckBox.Text = 'Ignore Logged Tasks';
            app.SaveDatabeforeerrorCheckBox = uicheckbox(app.PipelinecontrolpanelPanel);
            app.SaveDatabeforeerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveDatabeforeerrorCheckBoxValueChanged, true);
            app.SaveDatabeforeerrorCheckBox.Tooltip = {'Saves the last data created before the pipeline stops due to an error.'};
            app.SaveDatabeforeerrorCheckBox.Text = 'Save Data before error';
            app.PipelinestatusPanel = uipanel(app.PipelinecontrolpanelTab);
            app.PipelinestatusPanel.Title = 'Pipeline status';
            app.TextArea_2 = uitextarea(app.PipelinestatusPanel);
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.Tree3 = uitree(app.VisualizationTab);
            app.Tree3.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SearchtoolsPanel_2 = uipanel(app.VisualizationTab);
            app.SearchtoolsPanel_2.Title = 'Search tools';
            app.AdvancedSearchButton_2 = uibutton(app.SearchtoolsPanel_2, 'push');
            app.AdvancedSearchButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSearchButton_2Pushed, true);
            app.AdvancedSearchButton_2.Text = 'Advanced Search';
            app.ResetSearchButton_2 = uibutton(app.SearchtoolsPanel_2, 'push');
            app.ResetSearchButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetSearchButtonPushed, true);
            app.ResetSearchButton_2.Text = 'Reset Search';
            app.SelectanItemLabel = uilabel(app.VisualizationTab);
            app.SelectanItemLabel.Text = 'Select an Item:';
            app.AppsPanel = uipanel(app.VisualizationTab);
            app.AppsPanel.Title = 'Apps';
            app.RetinotopyButton = uibutton(app.AppsPanel, 'push');
            app.RetinotopyButton.ButtonPushedFcn = createCallbackFcn(app, @RetinotopyButtonPushed, true);
            app.RetinotopyButton.Text = 'Retinotopy';
            app.SeedPixelCorrelationButton = uibutton(app.AppsPanel, 'push');
            app.SeedPixelCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @SeedPixelCorrelationButtonPushed, true);
            app.SeedPixelCorrelationButton.Text = {'Seed Pixel '; 'Correlation '};
            app.FunctionalmapsvisualizationLabel = uilabel(app.AppsPanel);
            app.FunctionalmapsvisualizationLabel.Text = 'Functional maps visualization';
            app.ROImanagerButton = uibutton(app.AppsPanel, 'push');
            app.ROImanagerButton.ButtonPushedFcn = createCallbackFcn(app, @ROImanagerButtonPushed, true);
            app.ROImanagerButton.Text = 'ROI manager';
            app.ManualImageregistrationButton = uibutton(app.AppsPanel, 'push');
            app.ManualImageregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualImageregistrationButtonPushed, true);
            app.ManualImageregistrationButton.Text = {'Manual Image '; 'registration'};
            app.ImageRegistrationtoolsLabel = uilabel(app.AppsPanel);
            app.ImageRegistrationtoolsLabel.Text = 'Image Registration tools';
            app.DataViewerButton = uibutton(app.AppsPanel, 'push');
            app.DataViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewerButtonPushed, true);
            app.DataViewerButton.Text = 'DataViewer';
            app.CustomAppsVannisLabLabel = uilabel(app.AppsPanel);
            app.CustomAppsVannisLabLabel.Text = 'Custom Apps (Vanni''s Lab)';
            app.SelectafileLabel = uilabel(app.VisualizationTab);
            app.SelectafileLabel.Text = 'Select a file : ';
            app.SelectafileListBox = uilistbox(app.VisualizationTab);
            app.SelectafileListBox.Items = {};
            app.SelectafileListBox.ValueChangedFcn = createCallbackFcn(app, @SelectafileListBoxValueChanged, true);
            app.SelectafileListBox.Value = {};
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.Tree4 = uitree(app.AnalysisTab);
            app.Tree4.Multiselect = 'on';
            app.Tree4.SelectionChangedFcn = createCallbackFcn(app, @Tree4SelectionChanged, true);
            app.Tree4.Tooltip = {''};
            app.SelectItemsLabel = uilabel(app.AnalysisTab);
            app.SelectItemsLabel.Tooltip = {'Create/Load a group to show items!'};
            app.SelectItemsLabel.Text = 'Select Item(s):';
            app.SelectObsListBox_analysis = uilistbox(app.AnalysisTab);
            app.SelectObsListBox_analysis.Items = {};
            app.SelectObsListBox_analysis.Multiselect = 'on';
            app.SelectObsListBox_analysis.Value = {};
            app.SelectButton_analysis = uibutton(app.AnalysisTab, 'state');
            app.SelectButton_analysis.ValueChangedFcn = createCallbackFcn(app, @SelectButton_analysisValueChanged, true);
            app.SelectButton_analysis.Enable = 'off';
            app.SelectButton_analysis.Text = 'Select';
            app.SelectfileListBoxLabel = uilabel(app.AnalysisTab);
            app.SelectfileListBoxLabel.Text = 'Select file : ';
            app.SelectFileListBox_analysis = uilistbox(app.AnalysisTab);
            app.SelectFileListBox_analysis.Items = {};
            app.SelectFileListBox_analysis.ValueChangedFcn = createCallbackFcn(app, @SelectFileListBox_analysisValueChanged, true);
            app.SelectFileListBox_analysis.Value = {};
            app.GroupingtoolPanel = uipanel(app.AnalysisTab);
            app.GroupingtoolPanel.Title = 'Grouping tool';
            app.CreateEditButton = uibutton(app.GroupingtoolPanel, 'push');
            app.CreateEditButton.ButtonPushedFcn = createCallbackFcn(app, @CreateEditButtonPushed, true);
            app.CreateEditButton.Text = 'Create/Edit';
            app.SavetofileButton = uibutton(app.GroupingtoolPanel, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = {'Save '; 'to file'};
            app.OpenBtn_analysis = uibutton(app.GroupingtoolPanel, 'push');
            app.OpenBtn_analysis.ButtonPushedFcn = createCallbackFcn(app, @OpenBtn_analysisPushed, true);
            app.OpenBtn_analysis.Text = 'Open';
            app.SelectobservationsLabel = uilabel(app.AnalysisTab);
            app.SelectobservationsLabel.Text = 'Select observation(s) :';
            app.ToolsPanel = uipanel(app.AnalysisTab);
            app.ToolsPanel.Title = 'Tools';
            app.ToolsPanel.Visible = 'off';
            app.ExporttoCSVButton = uibutton(app.ToolsPanel, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Text = 'Export to .CSV';
            app.UtilitiesLabel = uilabel(app.ToolsPanel);
            app.UtilitiesLabel.Text = 'Utilities';
            app.TableViewButton = uibutton(app.ToolsPanel, 'push');
            app.TableViewButton.ButtonPushedFcn = createCallbackFcn(app, @TableViewButtonPushed, true);
            app.TableViewButton.Text = 'Table View';
            app.SavetoMatButton = uibutton(app.ToolsPanel, 'push');
            app.SavetoMatButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoMatButtonPushed, true);
            app.SavetoMatButton.Text = 'Save to .Mat';
            app.GenAverageRSimagesButton = uibutton(app.ToolsPanel, 'push');
            app.GenAverageRSimagesButton.ButtonPushedFcn = createCallbackFcn(app, @GenAverageRSimagesButtonPushed, true);
            app.GenAverageRSimagesButton.Text = {'Gen. Average'; ' RS images'};
            app.CustomFunctionsVannisLabLabel = uilabel(app.ToolsPanel);
            app.CustomFunctionsVannisLabLabel.Text = 'Custom Functions (Vanni''s Lab)';
            app.PlotCorrelationMatrixButton = uibutton(app.ToolsPanel, 'push');
            app.PlotCorrelationMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCorrelationMatrixButtonPushed, true);
            app.PlotCorrelationMatrixButton.Text = {'Plot Correlation'; 'Matrix'};
            app.PlottingLabel = uilabel(app.ToolsPanel);
            app.PlottingLabel.Text = 'Plotting';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SearchtoolsPanel_3 = uipanel(app.UIFigure);
            app.SearchtoolsPanel_3.Title = 'Search tools';
            app.AdvancedSearchButton_3 = uibutton(app.SearchtoolsPanel_3, 'push');
            app.AdvancedSearchButton_3.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSearchButton_3Pushed, true);
            app.AdvancedSearchButton_3.Text = 'Advanced Search';
            app.ResetSearchButton_3 = uibutton(app.SearchtoolsPanel_3, 'push');
            app.ResetSearchButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetSearchButtonPushed, true);
            app.ResetSearchButton_3.Text = 'Reset Search';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.SelectionLabel = uilabel(app.OptionsPanel);
            app.SelectionLabel.Text = 'Selection:';
            app.EditButton = uibutton(app.OptionsPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.OptionsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UndoSelectionButton = uibutton(app.OptionsPanel, 'push');
            app.UndoSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @UndoSelectionButtonPushed, true);
            app.UndoSelectionButton.Visible = 'off';
            app.UndoSelectionButton.Text = 'Undo Selection';
            app.SelectButton = uibutton(app.OptionsPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Visible = 'off';
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = umIToolbox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end